classdef Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BS_Panel             matlab.ui.container.Panel
        BS_FactorNode        matlab.ui.container.Panel
        BSFactorName         matlab.ui.control.EditField
        BSLevels             matlab.ui.control.Table
        AddBSLevelButton     matlab.ui.control.Button
        RemoveBSLevelButton  matlab.ui.control.Button
        DeleteBSFactor       matlab.ui.control.Button
        AddBSFactorButton    matlab.ui.control.Button
        WS_Panel             matlab.ui.container.Panel
        WS_FactorNode        matlab.ui.container.Panel
        WSFactorName         matlab.ui.control.EditField
        WSLevels             matlab.ui.control.Table
        AddWSLevelButton     matlab.ui.control.Button
        RemoveWSLevelButton  matlab.ui.control.Button
        DeleteWSFactor       matlab.ui.control.Button
        AddWSFactorButton    matlab.ui.control.Button
        Measures_Panel       matlab.ui.container.Panel
        MeasureNode          matlab.ui.container.Panel
        MeasureName          matlab.ui.control.EditField
        MeasureType          matlab.ui.container.ButtonGroup
        ScaleButton          matlab.ui.control.ToggleButton
        OrdinalButton        matlab.ui.control.ToggleButton
        NominalButton        matlab.ui.control.ToggleButton
        DeleteMeasure        matlab.ui.control.Button
        AddMeasureButton     matlab.ui.control.Button
        DoneButton           matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        BS_Factors % Between-Subjects Factors
        WS_Factors % Within-Subjects Factors
        Measures % Measurements to be taken
    end
    
    methods (Access = public)
        
        function refreshRows(app)
            shift = app.BS_FactorNode.Position(3) + 25;
            for n = 1:length(app.BS_Factors)-1
                app.BS_Factors(n).Node.Position(1) = app.BS_FactorNode.Position(1) + shift*(n-1);
            end
            app.AddBSFactorButton.Position(1) = app.BS_FactorNode.Position(1) + shift*(length(app.BS_Factors) - 1);
            
            shift = app.MeasureNode.Position(3) + 25;
            for n = 1:length(app.Measures)-1
                app.Measures(n).Node.Position(1) = app.MeasureNode.Position(1) + shift*(n-1);
            end
            app.AddMeasureButton.Position(1) = app.MeasureNode.Position(1) + shift*(length(app.Measures) - 1);
            
            shift = app.WS_FactorNode.Position(3) + 25;
            for n = 1:length(app.WS_Factors)-1
                app.WS_Factors(n).Node.Position(1) = app.WS_FactorNode.Position(1) + shift*(n-1);
            end
            app.AddWSFactorButton.Position(1) = app.WS_FactorNode.Position(1) + shift*(length(app.WS_Factors) - 1);
        end
    
        function saveobj(app)
            des = struct('BS_Factors', app.BS_Factors, ...
                'WS_Factors', app.WS_Factors, ...
                'Measures', app.Measures ...
                );
            save('Design\Design.mat', 'des')
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BS_Factors = struct("Name", [], "Levels", [], "Node", []);
            app.WS_Factors = struct("Name", [], "Levels", [], "Node", []);
            app.Measures   = struct("Name", [], "Type", [],   "Node", []);
        end

        % Button pushed function: AddBSFactorButton
        function AddBSFactorButtonPushed(app, event)
            n = length(app.BS_Factors);
            
            app.BS_Factors(n).Node = copyobj(app.BS_FactorNode,app.BS_Panel, 'legacy');
            app.BS_Factors(n).Node.Visible = 'on';
            app.BS_Factors(n+1).Node = [];
            
            refreshRows(app)
        end

        % Button pushed function: AddWSFactorButton
        function AddWSFactorButtonPushed(app, event)
            n = length(app.WS_Factors);
            
            app.WS_Factors(n).Node = copyobj(app.WS_FactorNode,app.WS_Panel, 'legacy');
            app.WS_Factors(n).Node.Visible = 'on';
            app.WS_Factors(n+1).Node = [];
            
            refreshRows(app)
        end

        % Button pushed function: AddMeasureButton
        function AddMeasureButtonPushed(app, event)
            n = length(app.Measures);
            
            app.Measures(n).Node = copyobj(app.MeasureNode,app.Measures_Panel, 'legacy');
            app.Measures(n).Node.Visible = 'on';
            app.Measures(n).Type = 'Scale';
            app.Measures(n+1).Node = [];
            
            refreshRows(app)
        end

        % Button pushed function: DeleteWSFactor
        function DeleteWSFactorButtonPushed(app, event)
            fac = [app.WS_Factors.Node] == [event.Source.Parent];
            delete(app.WS_Factors(fac).Node)
            app.WS_Factors(fac) = [];
            refreshRows(app)
        end

        % Button pushed function: DeleteBSFactor
        function DeleteBSFactorButtonPushed(app, event)
            fac = [app.BS_Factors.Node] == [event.Source.Parent];
            delete(app.BS_Factors(fac).Node)
            app.BS_Factors(fac) = [];
            refreshRows(app)
        end

        % Button pushed function: DeleteMeasure
        function DeleteMeasureButtonPushed(app, event)
            mes = [app.Measures.Node] == [event.Source.Parent];
            delete(app.Measures(mes).Node)
            app.Measures(mes) = [];
            refreshRows(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            saveobj(app)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Callback function: MeasureName, MeasureName
        function MeasureNameValueChanged(app, event)
            mes = [app.Measures.Node] == [event.Source.Parent];
            app.Measures(mes).Name = event.Value;
        end

        % Selection changed function: MeasureType
        function MeasureTypeSelectionChanged(app, event)
            mes = [app.Measures.Node] == [event.Source.Parent];
            app.Measures(mes).Type = event.NewValue.Text;
        end

        % Callback function: BSFactorName, BSFactorName
        function BSFactorNameValueChanged(app, event)
            fac = [app.BS_Factors.Node] == [event.Source.Parent];
            app.BS_Factors(fac).Name = event.Value;
        end

        % Callback function: WSFactorName, WSFactorName
        function WSFactorNameValueChanged(app, event)
            fac = [app.WS_Factors.Node] == [event.Source.Parent];
            app.WS_Factors(fac).Name = event.Value;
        end

        % Cell edit callback: WSLevels
        function WSLevelsCellEdit(app, event)
            fac = [app.WS_Factors.Node] == [event.Source.Parent];
            app.WS_Factors(fac).Levels = event.Source.Data;
        end

        % Button pushed function: AddBSLevelButton, AddWSLevelButton
        function AddLevelButtonPushed(app, event)
            event.Source.Parent.Children(4).Data = [event.Source.Parent.Children(4).Data; {''}];
        end

        % Button pushed function: RemoveBSLevelButton
        function RemoveBSLevelButtonPushed(app, event)
            event.Source.Parent.Children(4).Data = event.Source.Parent.Children(4).Data(1:end-1);
            fac = [app.BS_Factors.Node] == [event.Source.Parent];
            app.BS_Factors(fac).Levels = event.Source.Parent.Children(4).Data;
        end

        % Cell edit callback: BSLevels
        function BSLevelsCellEdit(app, event)
            fac = [app.BS_Factors.Node] == [event.Source.Parent];
            app.BS_Factors(fac).Levels = event.Source.Data;
        end

        % Button pushed function: RemoveWSLevelButton
        function RemoveWSLevelButtonPushed(app, event)
            event.Source.Parent.Children(4).Data = event.Source.Parent.Children(4).Data(1:end-1);
            fac = [app.WS_Factors.Node] == [event.Source.Parent];
            app.WS_Factors(fac).Levels = event.Source.Parent.Children(4).Data;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BS_Panel = uipanel(app.UIFigure);
            app.BS_Panel.Title = 'Between-Subjects Factors';
            app.BS_FactorNode = uipanel(app.BS_Panel);
            app.BS_FactorNode.Visible = 'off';
            app.BSFactorName = uieditfield(app.BS_FactorNode, 'text');
            app.BSFactorName.ValueChangedFcn = createCallbackFcn(app, @BSFactorNameValueChanged, true);
            app.BSFactorName.ValueChangingFcn = createCallbackFcn(app, @BSFactorNameValueChanged, true);
            app.BSFactorName.Value = 'Factor Name';
            app.BSLevels = uitable(app.BS_FactorNode);
            app.BSLevels.ColumnName = {'Levels'};
            app.BSLevels.RowName = {};
            app.BSLevels.ColumnEditable = true;
            app.BSLevels.CellEditCallback = createCallbackFcn(app, @BSLevelsCellEdit, true);
            app.AddBSLevelButton = uibutton(app.BS_FactorNode, 'push');
            app.AddBSLevelButton.ButtonPushedFcn = createCallbackFcn(app, @AddLevelButtonPushed, true);
            app.AddBSLevelButton.Text = 'Add Level';
            app.RemoveBSLevelButton = uibutton(app.BS_FactorNode, 'push');
            app.RemoveBSLevelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBSLevelButtonPushed, true);
            app.RemoveBSLevelButton.Text = 'Remove Level';
            app.DeleteBSFactor = uibutton(app.BS_FactorNode, 'push');
            app.DeleteBSFactor.ButtonPushedFcn = createCallbackFcn(app, @DeleteBSFactorButtonPushed, true);
            app.DeleteBSFactor.Text = 'x';
            app.AddBSFactorButton = uibutton(app.BS_Panel, 'push');
            app.AddBSFactorButton.ButtonPushedFcn = createCallbackFcn(app, @AddBSFactorButtonPushed, true);
            app.AddBSFactorButton.Text = {'Add '; 'Factor'};
            app.WS_Panel = uipanel(app.UIFigure);
            app.WS_Panel.Title = 'Within-Subjects Factors';
            app.WS_FactorNode = uipanel(app.WS_Panel);
            app.WS_FactorNode.Visible = 'off';
            app.WSFactorName = uieditfield(app.WS_FactorNode, 'text');
            app.WSFactorName.ValueChangedFcn = createCallbackFcn(app, @WSFactorNameValueChanged, true);
            app.WSFactorName.ValueChangingFcn = createCallbackFcn(app, @WSFactorNameValueChanged, true);
            app.WSFactorName.Value = 'Factor Name';
            app.WSLevels = uitable(app.WS_FactorNode);
            app.WSLevels.ColumnName = {'Levels'};
            app.WSLevels.RowName = {};
            app.WSLevels.ColumnEditable = true;
            app.WSLevels.CellEditCallback = createCallbackFcn(app, @WSLevelsCellEdit, true);
            app.AddWSLevelButton = uibutton(app.WS_FactorNode, 'push');
            app.AddWSLevelButton.ButtonPushedFcn = createCallbackFcn(app, @AddLevelButtonPushed, true);
            app.AddWSLevelButton.Text = 'Add Level';
            app.RemoveWSLevelButton = uibutton(app.WS_FactorNode, 'push');
            app.RemoveWSLevelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWSLevelButtonPushed, true);
            app.RemoveWSLevelButton.Text = 'Remove Level';
            app.DeleteWSFactor = uibutton(app.WS_FactorNode, 'push');
            app.DeleteWSFactor.ButtonPushedFcn = createCallbackFcn(app, @DeleteWSFactorButtonPushed, true);
            app.DeleteWSFactor.Text = 'x';
            app.AddWSFactorButton = uibutton(app.WS_Panel, 'push');
            app.AddWSFactorButton.ButtonPushedFcn = createCallbackFcn(app, @AddWSFactorButtonPushed, true);
            app.AddWSFactorButton.Text = {'Add '; 'Factor'};
            app.Measures_Panel = uipanel(app.UIFigure);
            app.Measures_Panel.Title = 'Measures';
            app.MeasureNode = uipanel(app.Measures_Panel);
            app.MeasureNode.Visible = 'off';
            app.MeasureName = uieditfield(app.MeasureNode, 'text');
            app.MeasureName.ValueChangedFcn = createCallbackFcn(app, @MeasureNameValueChanged, true);
            app.MeasureName.ValueChangingFcn = createCallbackFcn(app, @MeasureNameValueChanged, true);
            app.MeasureName.Value = 'Measure Name';
            app.MeasureType = uibuttongroup(app.MeasureNode);
            app.MeasureType.SelectionChangedFcn = createCallbackFcn(app, @MeasureTypeSelectionChanged, true);
            app.ScaleButton = uitogglebutton(app.MeasureType);
            app.ScaleButton.Text = 'Scale';
            app.ScaleButton.Value = true;
            app.OrdinalButton = uitogglebutton(app.MeasureType);
            app.OrdinalButton.Text = 'Ordinal';
            app.NominalButton = uitogglebutton(app.MeasureType);
            app.NominalButton.Text = 'Nominal';
            app.DeleteMeasure = uibutton(app.MeasureNode, 'push');
            app.DeleteMeasure.ButtonPushedFcn = createCallbackFcn(app, @DeleteMeasureButtonPushed, true);
            app.DeleteMeasure.Text = 'x';
            app.AddMeasureButton = uibutton(app.Measures_Panel, 'push');
            app.AddMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @AddMeasureButtonPushed, true);
            app.AddMeasureButton.Text = {'Add '; 'Measure'};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end