classdef El_Factors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Tree                           matlab.ui.container.Tree
        WithinSubjectsNode             matlab.ui.container.TreeNode
        BetweenSubjectsNode            matlab.ui.container.TreeNode
        WS_Factors                     matlab.ui.control.Table
        AssignButton                   matlab.ui.control.Button
        BS_Factors                     matlab.ui.control.Table
        WS_Clear                       matlab.ui.control.Button
        BS_Clear                       matlab.ui.control.Button
        AssigntofactorsandlevelsLabel  matlab.ui.control.Label
        WithinSubjectsFactorsLabel     matlab.ui.control.Label
        BetweenSubjectsFactorsLabel    matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Parent
    end
    
    methods (Access = public)
        function loadobj(app, fac)
            for n = 1:length(fac)*~isempty([fac{:}])
                i_w = strcmp(app.WS_Factors.Data(:,1), fac{n,1});
                app.WS_Factors.Data(i_w,2) = fac(n,2);
                i_b = strcmp(app.BS_Factors.Data(:,1), fac{n,1});
                app.BS_Factors.Data(i_b,2) = fac(n,2);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('Design\Design.mat')
            app.BS_Factors.Data = [...
                {des.BS_Factors.Name}', cell(length(des.BS_Factors),1)];
            app.WS_Factors.Data = [...
                {des.WS_Factors.Name}', cell(length(des.WS_Factors),1)];
            
            for n = 1:length(des.BS_Factors)
                fac = uitreenode(app.BetweenSubjectsNode,...
                    'Text', des.BS_Factors(n).Name, ...
                    'Tag', 'B');
                for l = 1:length(des.BS_Factors(n).Levels)
                    lv = uitreenode(fac, ...
                        'Text', des.BS_Factors(n).Levels(l).Text, ...
                        'Tag', 'L');
                end
            end
            
            for n = 1:length(des.WS_Factors)
                fac = uitreenode(app.WithinSubjectsNode,...
                    'Text', des.WS_Factors(n).Name, ...
                    'Tag', 'W');
                for l = 1:length(des.WS_Factors(n).Levels)
                    lv = uitreenode(fac, ...
                        'Text', des.WS_Factors(n).Levels(l).Text, ...
                        'Tag', 'L');
                end
            end
            
            expand(app.BetweenSubjectsNode)
            expand(app.WithinSubjectsNode)
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            switch app.Tree.SelectedNodes.Parent.Tag
                case 'B'
                    i = strcmp(app.BS_Factors.Data(:,1), app.Tree.SelectedNodes.Parent.Text);
                    app.BS_Factors.Data{i,2} = app.Tree.SelectedNodes.Text;
                case 'W'
                  i = strcmp(app.WS_Factors.Data(:,1), app.Tree.SelectedNodes.Parent.Text);
                    app.WS_Factors.Data{i,2} = app.Tree.SelectedNodes.Text;  
            end
            app.WS_Clear.Enable = false;
            app.BS_Clear.Enable = false;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.AssignButton.Enable = strcmp(event.SelectedNodes.Tag, 'L');
            
            app.WS_Clear.Enable = false;
            app.BS_Clear.Enable = false;
        end

        % Cell selection callback: WS_Factors
        function WS_FactorsCellSelection(app, event)
            app.WS_Factors.UserData = event.Indices(1);
            app.WS_Clear.Enable = ~isempty(app.WS_Factors.Data{event.Indices(1),2});
            
        end

        % Button pushed function: WS_Clear
        function WS_ClearButtonPushed(app, event)
            app.WS_Factors.Data{app.WS_Factors.UserData,2} = [];
            
            app.WS_Clear.Enable = false;
            app.BS_Clear.Enable = false;
        end

        % Button pushed function: BS_Clear
        function BS_ClearButtonPushed(app, event)
            app.BS_Factors.Data{app.BS_Factors.UserData,2} = [];
            
            app.WS_Clear.Enable = false;
            app.BS_Clear.Enable = false;
        end

        % Cell selection callback: BS_Factors
        function BS_FactorsCellSelection(app, event)
            app.BS_Factors.UserData = event.Indices(1);
            app.BS_Clear.Enable = ~isempty(app.BS_Factors.Data{event.Indices(1),2});
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            output = [app.BS_Factors.Data; app.WS_Factors.Data];
            i = ~cellfun(@isempty, output);
            output = output(i(:,2),:)';
            app.Parent.FactorTable.ColumnName = output(1,:);
            app.Parent.FactorTable.Data = output(2,:);
            app.Parent.FactorTable.Visible = ~isempty(output);
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.WithinSubjectsNode = uitreenode(app.Tree);
            app.WithinSubjectsNode.Text = 'Within-Subjects';
            app.BetweenSubjectsNode = uitreenode(app.Tree);
            app.BetweenSubjectsNode.Text = 'Between-Subjects';
            app.WS_Factors = uitable(app.UIFigure);
            app.WS_Factors.ColumnName = {'Factor'; 'Level'};
            app.WS_Factors.RowName = {};
            app.WS_Factors.CellSelectionCallback = createCallbackFcn(app, @WS_FactorsCellSelection, true);
            app.AssignButton = uibutton(app.UIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Enable = 'off';
            app.AssignButton.Text = 'Assign >>';
            app.BS_Factors = uitable(app.UIFigure);
            app.BS_Factors.ColumnName = {'Factor'; 'Level'};
            app.BS_Factors.RowName = {};
            app.BS_Factors.CellSelectionCallback = createCallbackFcn(app, @BS_FactorsCellSelection, true);
            app.WS_Clear = uibutton(app.UIFigure, 'push');
            app.WS_Clear.ButtonPushedFcn = createCallbackFcn(app, @WS_ClearButtonPushed, true);
            app.WS_Clear.Enable = 'off';
            app.WS_Clear.Text = 'Clear';
            app.BS_Clear = uibutton(app.UIFigure, 'push');
            app.BS_Clear.ButtonPushedFcn = createCallbackFcn(app, @BS_ClearButtonPushed, true);
            app.BS_Clear.Enable = 'off';
            app.BS_Clear.Text = 'Clear';
            app.AssigntofactorsandlevelsLabel = uilabel(app.UIFigure);
            app.AssigntofactorsandlevelsLabel.Text = 'Assign to factors and levels';
            app.WithinSubjectsFactorsLabel = uilabel(app.UIFigure);
            app.WithinSubjectsFactorsLabel.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsLabel = uilabel(app.UIFigure);
            app.BetweenSubjectsFactorsLabel.Text = 'Between-Subjects Factors';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = El_Factors

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end