classdef AdaptBlock < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdaptBlockSpec                matlab.ui.Figure
        AdaptStimulusPanel            matlab.ui.container.Panel
        StimulusTypeListBoxLabel      matlab.ui.control.Label
        AdaptStimType                 matlab.ui.control.ListBox
        RandomAdapt                   matlab.ui.control.CheckBox
        PresentationLabel             matlab.ui.control.Label
        AdaptPresentation             matlab.ui.control.DropDown
        AdaptFileSelection            matlab.ui.control.Button
        FilesLabelAdapt               matlab.ui.control.Label
        DurationmsEditFieldLabel      matlab.ui.control.Label
        AdaptDuration                 matlab.ui.control.NumericEditField
        PositionAdapt                 matlab.ui.control.Button
        TestStimulusPanel             matlab.ui.container.Panel
        StimulusTypeListBox_2Label    matlab.ui.control.Label
        TestStimType                  matlab.ui.control.ListBox
        RandomTest                    matlab.ui.control.CheckBox
        PresentationDropDown_2Label   matlab.ui.control.Label
        TestPresentation              matlab.ui.control.DropDown
        TestFileSelection             matlab.ui.control.Button
        FilesLabelTest                matlab.ui.control.Label
        DurationmsEditField_2Label    matlab.ui.control.Label
        TestDuration                  matlab.ui.control.NumericEditField
        PositionTest                  matlab.ui.control.Button
        InterStimulusIntervalPanel    matlab.ui.container.Panel
        DurationmsEditField_3Label    matlab.ui.control.Label
        ISIDuration                   matlab.ui.control.NumericEditField
        ResponsePanel                 matlab.ui.container.Panel
        NoResponseLimit               matlab.ui.control.CheckBox
        TimeLimitmsLabel              matlab.ui.control.Label
        ResponseWaitTime              matlab.ui.control.NumericEditField
        ButtonCoding                  matlab.ui.control.Table
        NewButtonMap                  matlab.ui.control.Button
        RemoveButtonMap               matlab.ui.control.Button
        TopUpStimulusPanel            matlab.ui.container.Panel
        DurationmsEditField_3Label_2  matlab.ui.control.Label
        TopupDuration                 matlab.ui.control.NumericEditField
        RepeatPanel                   matlab.ui.container.Panel
        NumberOfTrialsLabel           matlab.ui.control.Label
        TopupDuration_2               matlab.ui.control.NumericEditField
        OkayButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        BlockNamePanel                matlab.ui.control.EditField
        InterTestIntervalPanel        matlab.ui.container.Panel
        ITIDurationLabel_2            matlab.ui.control.Label
        ITIDuration_2                 matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        dlg % Current dialogue box

    end

    properties (Access = public)
        Parent % Data from parent object
        
        ScreenDim % Screen dimensions
        
        %Block Parameters
        BlockName % Block name
        
        % Adapt Stim Parameters
        AdaptPos % Adapt position
        AdaptType % Adapt stimulus type
        AdaptPres % Adapt presentation
        AdaptRand % Randomise adapt presentation?
        AdaptDir % Location of adapt stimulus / stimuli
        AdaptDim % Dimensions of adapt stimulus
        AdaptDur %Duration of adapt stimulus
        
        % Test Stim Parameters
        TestPos % Adapt position
        TestType % Adapt stimulus type
        TestPres % Adapt presentation
        TestRand % Randomise test presentation?
        TestDir % Location of adapt stimulus / stimuli
        TestDim % Dimensions of test stimulus
        TestDur %Duration of adapt stimulus
        
        % ISI Parameters
        ISI % ISI duration
        
        % Response Parameters
        TLimit % Is there a time limit to respond?
        ResDur % Time limit to respond
        ButtonCodes % Which buttons correspond to which response
        
        % ITI Parameters
        ITI % ITI duration
        
        %Top-UP Parameters
        TopUp % Top-up duration
        
        % Repeat Parameters
        Rep % How many repeats
        
        % Other
        Design % Associated factors and levels
    end

    methods (Access = public)
    
        function initialieStimposWindow(app)
            app.dlg.ScreenDim = [1000, 500];
            app.dlg.StimDim = [300, 200];
            
            %resize window
            app.dlg.StimPos.Position(3) = app.dlg.ScreenDim(1);
            app.dlg.StimPos.Position(4) = app.dlg.ScreenDim(2);
            
            %resize x slider
            app.dlg.XSlider.Limits = [0,app.dlg.ScreenDim(1)];
            app.dlg.XSlider.Value = round(app.dlg.ScreenDim(1)/2);
            app.dlg.XSlider.Position(3) = app.dlg.ScreenDim(1);
            app.dlg.XPos.Value = round(app.dlg.ScreenDim(1)/2);
            
            %resize y slider
            app.dlg.YSlider.Limits = [0,app.dlg.ScreenDim(2)];
            app.dlg.YSlider.Value = round(app.dlg.ScreenDim(2)/2);
            app.dlg.YSlider.Position(4) = app.dlg.ScreenDim(2);
            app.dlg.YPos.Value = round(app.dlg.ScreenDim(2)/2);
            
            %reposition stimulus
            app.dlg.Stimulus.Position(1) = app.dlg.XSlider.Value;
            app.dlg.Stimulus.Position(2) = app.dlg.YSlider.Value;
            
            %resize stimulus
            app.dlg.Stimulus.Position(3) = app.dlg.StimDim(1);
            app.dlg.Stimulus.Position(4) = app.dlg.StimDim(2);
        end
        
    end

    methods (Access = private)
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ScreenDim = [1000, 500];
            
            app.ButtonCoding.Data = {[], []};
            app.BlockName = "Untitled Block";
        end

        % Value changed function: AdaptDuration
        function AdaptDurationValueChanged(app, event)
            app.AdaptDur = app.AdaptDuration.Value;
            
        end

        % Button pushed function: AdaptFileSelection
        function AdaptFileSelectionPushed(app, event)
            if app.AdaptPres == 1
                app.AdaptDir = uigetfile('Select adapt stimulus...');
            else
                app.AdaptDir = uigetfile('Select folder containing adapt stimuli...');
            end
        end

        % Value changed function: AdaptPresentation
        function AdaptPresentationValueChanged(app, event)
            app.AdaptPres = app.AdaptPresentation.Value;
        end

        % Value changed function: AdaptStimType
        function AdaptStimTypeValueChanged(app, event)
            app.AdaptType = app.AdaptStimType.Value;
        end

        % Value changed function: BlockNamePanel
        function BlockNamePanelValueChanged(app, event)
            app.BlockName = app.BlockNamePanel.Value;
            
        end

        % Cell edit callback: ButtonCoding
        function ButtonCodingCellEdit(app, event)
            app.ButtonCodes = app.ButtonCoding.Data;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ISIDuration
        function ISIDurationValueChanged(app, event)
            app.ISI = app.ISIDuration.Value;
        end

        % Value changed function: ITIDuration_2
        function ITIDuration_2ValueChanged(app, event)
            app.ITI = app.ITIDuration_2.Value;
        end

        % Button pushed function: NewButtonMap
        function NewButtonMapPushed(app, event)
            app.ButtonCoding.Data = [app.ButtonCoding.Data; {[],[]}];
            app.ButtonCodes = app.ButtonCoding.Data;
        end

        % Value changed function: NoResponseLimit
        function NoResponseLimitValueChanged(app, event)
            app.TLimit = app.NoResponseLimit.Value;
            if app.NoResponseLimit.Value == 1
                app.ResponseWaitTime.Enable = 'off';
            else
                app.ResponseWaitTime.Enable = 'on';
            end
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            save([pwd, '\Blocks\', matlab.lang.makeValidName(app.BlockNamePanel.Value)])
            blockNames = dir([pwd, '\Blocks']);
            app.Parent.BlocksListBox.Items = {blockNames(3:end).name};
            delete(app)
        end

        % Button pushed function: PositionAdapt
        function PositionAdaptButtonPushed(app, event)
            app.dlg = PositionStim;
            app.dlg.Parent = app.AdaptPos; %NOT WORKING
            
            app.dlg.ScreenDim = [1000, 500];
            app.dlg.StimDim = [300, 200];
            
            initialieStimposWindow(app)
        end

        % Button pushed function: PositionTest
        function PositionTestButtonPushed(app, event)
            app.dlg = PositionStim;
            app.TestPos = app.dlg.output; %NOT WORKING
            
            app.dlg.ScreenDim = [1000, 500];
            app.dlg.StimDim = [300, 200];
            
            initialieStimposWindow(app)
        end

        % Value changed function: RandomAdapt
        function RandomAdaptValueChanged(app, event)
            app.AdaptRand = app.RandomAdapt.Value;
        end

        % Value changed function: RandomTest
        function RandomTestValueChanged(app, event)
            app.TestRand = app.RandomTest.Value;
            
        end

        % Button pushed function: RemoveButtonMap
        function RemoveButtonMapPushed(app, event)
            app.ButtonCoding.Data = app.ButtonCoding.Data(1:end-1, :);
            app.ButtonCodes = app.ButtonCoding.Data;
        end

        % Value changed function: ResponseWaitTime
        function ResponseWaitTimeValueChanged(app, event)
            app.ResDur = app.ResponseWaitTime.Value;
            
        end

        % Value changed function: TestDuration
        function TestDurationValueChanged(app, event)
            app.TestDur = app.TestDuration.Value;
            
        end

        % Button pushed function: TestFileSelection
        function TestFileSelectionButtonPushed(app, event)
            if app.TestPres == 1
                app.TestDir = uigetfile('Select adapt stimulus...');
            else
                app.TestDir = uigetfile('Select folder containing adapt stimuli...');
            end
        end

        % Value changed function: TestPresentation
        function TestPresentationValueChanged(app, event)
            app.TestPres = app.TestPresentation.Value;
            
        end

        % Value changed function: TestStimType
        function TestStimTypeValueChanged(app, event)
            app.TestType = app.TestStimType.Value;
        end

        % Value changed function: TopupDuration
        function TopupDurationValueChanged(app, event)
            app.TopUp = app.TopupDuration.Value;
            
        end

        % Value changed function: TopupDuration_2
        function TopupDuration_2ValueChanged(app, event)
            app.Rep = app.TopupDuration_2.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdaptBlockSpec = uifigure;
            app.AdaptBlockSpec.Name = 'UI Figure';
            app.AdaptStimulusPanel = uipanel(app.AdaptBlockSpec);
            app.AdaptStimulusPanel.Title = 'Adapt Stimulus';
            app.StimulusTypeListBoxLabel = uilabel(app.AdaptStimulusPanel);
            app.StimulusTypeListBoxLabel.Text = 'Stimulus Type';
            app.AdaptStimType = uilistbox(app.AdaptStimulusPanel);
            app.AdaptStimType.Items = {'Image', 'Video', 'Audio', 'Frame Animation', 'Image + Audio'};
            app.AdaptStimType.ValueChangedFcn = createCallbackFcn(app, @AdaptStimTypeValueChanged, true);
            app.AdaptStimType.Value = 'Image';
            app.RandomAdapt = uicheckbox(app.AdaptStimulusPanel);
            app.RandomAdapt.ValueChangedFcn = createCallbackFcn(app, @RandomAdaptValueChanged, true);
            app.RandomAdapt.Text = 'Randomise';
            app.PresentationLabel = uilabel(app.AdaptStimulusPanel);
            app.PresentationLabel.Text = 'Presentation:';
            app.AdaptPresentation = uidropdown(app.AdaptStimulusPanel);
            app.AdaptPresentation.Items = {'Same Every Time', 'Different Each Time'};
            app.AdaptPresentation.ValueChangedFcn = createCallbackFcn(app, @AdaptPresentationValueChanged, true);
            app.AdaptPresentation.Value = 'Same Every Time';
            app.AdaptFileSelection = uibutton(app.AdaptStimulusPanel, 'push');
            app.AdaptFileSelection.ButtonPushedFcn = createCallbackFcn(app, @AdaptFileSelectionPushed, true);
            app.AdaptFileSelection.Text = 'Browse';
            app.FilesLabelAdapt = uilabel(app.AdaptStimulusPanel);
            app.FilesLabelAdapt.Text = 'File(s):';
            app.DurationmsEditFieldLabel = uilabel(app.AdaptStimulusPanel);
            app.DurationmsEditFieldLabel.Text = 'Duration(ms):';
            app.AdaptDuration = uieditfield(app.AdaptStimulusPanel, 'numeric');
            app.AdaptDuration.ValueChangedFcn = createCallbackFcn(app, @AdaptDurationValueChanged, true);
            app.AdaptDuration.Limits = [0 Inf];
            app.AdaptDuration.RoundFractionalValues = 'on';
            app.AdaptDuration.ValueDisplayFormat = '%.0f';
            app.AdaptDuration.Value = 30000;
            app.PositionAdapt = uibutton(app.AdaptStimulusPanel, 'push');
            app.PositionAdapt.ButtonPushedFcn = createCallbackFcn(app, @PositionAdaptButtonPushed, true);
            app.PositionAdapt.Text = 'Position Stimulus...';
            app.TestStimulusPanel = uipanel(app.AdaptBlockSpec);
            app.TestStimulusPanel.Title = 'Test Stimulus';
            app.StimulusTypeListBox_2Label = uilabel(app.TestStimulusPanel);
            app.StimulusTypeListBox_2Label.Text = 'Stimulus Type';
            app.TestStimType = uilistbox(app.TestStimulusPanel);
            app.TestStimType.Items = {'Image', 'Video', 'Audio', 'Frame Animation', 'Image + Audio'};
            app.TestStimType.ValueChangedFcn = createCallbackFcn(app, @TestStimTypeValueChanged, true);
            app.TestStimType.Value = 'Image';
            app.RandomTest = uicheckbox(app.TestStimulusPanel);
            app.RandomTest.ValueChangedFcn = createCallbackFcn(app, @RandomTestValueChanged, true);
            app.RandomTest.Text = 'Randomise';
            app.PresentationDropDown_2Label = uilabel(app.TestStimulusPanel);
            app.PresentationDropDown_2Label.Text = 'Presentation:';
            app.TestPresentation = uidropdown(app.TestStimulusPanel);
            app.TestPresentation.Items = {'Same Every Time', 'Different Each Time'};
            app.TestPresentation.ValueChangedFcn = createCallbackFcn(app, @TestPresentationValueChanged, true);
            app.TestPresentation.Value = 'Same Every Time';
            app.TestFileSelection = uibutton(app.TestStimulusPanel, 'push');
            app.TestFileSelection.ButtonPushedFcn = createCallbackFcn(app, @TestFileSelectionButtonPushed, true);
            app.TestFileSelection.Text = 'Browse';
            app.FilesLabelTest = uilabel(app.TestStimulusPanel);
            app.FilesLabelTest.Text = 'File(s):';
            app.DurationmsEditField_2Label = uilabel(app.TestStimulusPanel);
            app.DurationmsEditField_2Label.Text = 'Duration(ms):';
            app.TestDuration = uieditfield(app.TestStimulusPanel, 'numeric');
            app.TestDuration.ValueChangedFcn = createCallbackFcn(app, @TestDurationValueChanged, true);
            app.TestDuration.Limits = [0 Inf];
            app.TestDuration.RoundFractionalValues = 'on';
            app.TestDuration.ValueDisplayFormat = '%.0f';
            app.TestDuration.Value = 30000;
            app.PositionTest = uibutton(app.TestStimulusPanel, 'push');
            app.PositionTest.ButtonPushedFcn = createCallbackFcn(app, @PositionTestButtonPushed, true);
            app.PositionTest.Text = 'Position Stimulus...';
            app.InterStimulusIntervalPanel = uipanel(app.AdaptBlockSpec);
            app.InterStimulusIntervalPanel.Title = 'Inter-Stimulus Interval';
            app.DurationmsEditField_3Label = uilabel(app.InterStimulusIntervalPanel);
            app.DurationmsEditField_3Label.Text = 'Duration(ms):';
            app.ISIDuration = uieditfield(app.InterStimulusIntervalPanel, 'numeric');
            app.ISIDuration.ValueChangedFcn = createCallbackFcn(app, @ISIDurationValueChanged, true);
            app.ISIDuration.Limits = [0 Inf];
            app.ISIDuration.RoundFractionalValues = 'on';
            app.ISIDuration.ValueDisplayFormat = '%.0f';
            app.ISIDuration.Value = 500;
            app.ResponsePanel = uipanel(app.AdaptBlockSpec);
            app.ResponsePanel.Title = 'Response';
            app.NoResponseLimit = uicheckbox(app.ResponsePanel);
            app.NoResponseLimit.ValueChangedFcn = createCallbackFcn(app, @NoResponseLimitValueChanged, true);
            app.NoResponseLimit.Text = 'No Limit';
            app.TimeLimitmsLabel = uilabel(app.ResponsePanel);
            app.TimeLimitmsLabel.Text = 'Time Limit (ms):';
            app.ResponseWaitTime = uieditfield(app.ResponsePanel, 'numeric');
            app.ResponseWaitTime.ValueChangedFcn = createCallbackFcn(app, @ResponseWaitTimeValueChanged, true);
            app.ResponseWaitTime.Limits = [0 Inf];
            app.ResponseWaitTime.RoundFractionalValues = 'on';
            app.ResponseWaitTime.ValueDisplayFormat = '%.0f';
            app.ResponseWaitTime.Value = 2000;
            app.ButtonCoding = uitable(app.ResponsePanel);
            app.ButtonCoding.ColumnName = {'If they press...'; 'Record it as...'};
            app.ButtonCoding.RowName = {};
            app.ButtonCoding.ColumnEditable = true;
            app.ButtonCoding.CellEditCallback = createCallbackFcn(app, @ButtonCodingCellEdit, true);
            app.NewButtonMap = uibutton(app.ResponsePanel, 'push');
            app.NewButtonMap.ButtonPushedFcn = createCallbackFcn(app, @NewButtonMapPushed, true);
            app.NewButtonMap.Text = '+';
            app.RemoveButtonMap = uibutton(app.ResponsePanel, 'push');
            app.RemoveButtonMap.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonMapPushed, true);
            app.RemoveButtonMap.Text = '-';
            app.TopUpStimulusPanel = uipanel(app.AdaptBlockSpec);
            app.TopUpStimulusPanel.Title = 'Top-Up Stimulus';
            app.DurationmsEditField_3Label_2 = uilabel(app.TopUpStimulusPanel);
            app.DurationmsEditField_3Label_2.Text = 'Duration(ms):';
            app.TopupDuration = uieditfield(app.TopUpStimulusPanel, 'numeric');
            app.TopupDuration.ValueChangedFcn = createCallbackFcn(app, @TopupDurationValueChanged, true);
            app.TopupDuration.Limits = [0 Inf];
            app.TopupDuration.RoundFractionalValues = 'on';
            app.TopupDuration.ValueDisplayFormat = '%.0f';
            app.TopupDuration.Value = 500;
            app.RepeatPanel = uipanel(app.AdaptBlockSpec);
            app.RepeatPanel.Title = 'Repeat...';
            app.NumberOfTrialsLabel = uilabel(app.RepeatPanel);
            app.NumberOfTrialsLabel.Text = 'Number Of Trials:';
            app.TopupDuration_2 = uieditfield(app.RepeatPanel, 'numeric');
            app.TopupDuration_2.ValueChangedFcn = createCallbackFcn(app, @TopupDuration_2ValueChanged, true);
            app.TopupDuration_2.Limits = [0 Inf];
            app.TopupDuration_2.RoundFractionalValues = 'on';
            app.TopupDuration_2.ValueDisplayFormat = '%.0f';
            app.TopupDuration_2.Value = 20;
            app.OkayButton = uibutton(app.AdaptBlockSpec, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.AdaptBlockSpec, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BlockNamePanel = uieditfield(app.AdaptBlockSpec, 'text');
            app.BlockNamePanel.ValueChangedFcn = createCallbackFcn(app, @BlockNamePanelValueChanged, true);
            app.BlockNamePanel.Value = 'Block Name';
            app.InterTestIntervalPanel = uipanel(app.AdaptBlockSpec);
            app.InterTestIntervalPanel.Title = 'Inter-Test Interval';
            app.ITIDurationLabel_2 = uilabel(app.InterTestIntervalPanel);
            app.ITIDurationLabel_2.Text = 'ITI Duration:';
            app.ITIDuration_2 = uieditfield(app.InterTestIntervalPanel, 'numeric');
            app.ITIDuration_2.ValueChangedFcn = createCallbackFcn(app, @ITIDuration_2ValueChanged, true);
            app.ITIDuration_2.Limits = [0 Inf];
            app.ITIDuration_2.RoundFractionalValues = 'on';
            app.ITIDuration_2.ValueDisplayFormat = '%.0f';
            app.ITIDuration_2.Value = 2000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = AdaptBlock

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdaptBlockSpec)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdaptBlockSpec)
        end
    end
end