classdef EditLevels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        LevelTable  matlab.ui.control.Table
        Remove      matlab.ui.control.Button
        Add         matlab.ui.control.Button
        SaveButton  matlab.ui.control.Button
    end


    properties (Access = private)
        
    end
    properties (Access = public)
        Parent % Data from the parent app
        StartLvls % Levels to start with
    end

    methods (Access = public)
   
       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            app.LevelTable.Data = [app.LevelTable.Data; {'New Level'}];
            if length(app.LevelTable.Data) == 1
                app.Remove.Enable = 'off';
            else
                app.Remove.Enable = 'on';
            end
        end

        % Button pushed function: Remove
        function RemoveButtonPushed(app, event)
            if length(app.LevelTable.Data) > 1
                app.LevelTable.Data = app.LevelTable.Data(1:end-1, :);
                if length(app.LevelTable.Data) == 1
                    app.Remove.Enable = 'off';
                else
                    app.Remove.Enable = 'on';
                end
            else
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Parent.Items = app.LevelTable.Data';
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LevelTable = uitable(app.UIFigure);
            app.LevelTable.ColumnName = {'Levels'};
            app.LevelTable.RowName = {};
            app.LevelTable.ColumnEditable = true;
            app.Remove = uibutton(app.UIFigure, 'push');
            app.Remove.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.Remove.Text = '-';
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EditLevels

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end