classdef Experiment_Old < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AddNodePanel                    matlab.ui.container.Panel
        AddRoutineButton                matlab.ui.control.StateButton
        AddRandomiserButton             matlab.ui.control.StateButton
        AddIfStatementButton            matlab.ui.control.StateButton
        AddForLoopButton                matlab.ui.control.StateButton
        AddWhileLoopButton              matlab.ui.control.StateButton
        RandEditPanel                   matlab.ui.container.Panel
        labelRandOrderRun               matlab.ui.control.Label
        labelOfFollowingRoutines        matlab.ui.control.Label
        RandOrderSwitch                 matlab.ui.control.Switch
        NoRands                         matlab.ui.control.Spinner
        WhileNode                       matlab.ui.container.Panel
        WhileButton                     matlab.ui.control.StateButton
        WhileAddIn                      matlab.ui.control.Button
        WhileAddNext                    matlab.ui.control.Button
        WhileDelete                     matlab.ui.control.Button
        WhileEditButton                 matlab.ui.control.StateButton
        ForNode                         matlab.ui.container.Panel
        ForButton                       matlab.ui.control.StateButton
        ForEditButton                   matlab.ui.control.StateButton
        ForAddNext                      matlab.ui.control.Button
        ForAddIn                        matlab.ui.control.Button
        ForDelete                       matlab.ui.control.Button
        RandNode                        matlab.ui.container.Panel
        RandButton                      matlab.ui.control.StateButton
        RandEditButton                  matlab.ui.control.StateButton
        RandAddNext                     matlab.ui.control.Button
        RandAddIn                       matlab.ui.control.Button
        RandDelete                      matlab.ui.control.Button
        IfNode                          matlab.ui.container.Panel
        IfButton                        matlab.ui.control.StateButton
        IfEditButton                    matlab.ui.control.StateButton
        IfAddNext                       matlab.ui.control.Button
        IfAddIn                         matlab.ui.control.Button
        IfDelete                        matlab.ui.control.Button
        rtNode                          matlab.ui.container.Panel
        rtAddNext                       matlab.ui.control.Button
        rtButton                        matlab.ui.control.StateButton
        rtDelete                        matlab.ui.control.Button
        FirstAdd                        matlab.ui.control.Button
        ElseNode                        matlab.ui.container.Panel
        ElseAddIn                       matlab.ui.control.Button
        ElseButton                      matlab.ui.control.StateButton
        ForEditPanel                    matlab.ui.container.Panel
        NumberofiterationsSpinnerLabel  matlab.ui.control.Label
        NumberofiterationsSpinner       matlab.ui.control.Spinner
        VariablestoiterateEditFieldLabel  matlab.ui.control.Label
        VariablestoiterateEditField     matlab.ui.control.EditField
        WhileEditPanel_3                matlab.ui.container.Panel
        IfEditPanel                     matlab.ui.container.Panel
        IfDropDownLabel                 matlab.ui.control.Label
        IfDropDown                      matlab.ui.control.DropDown
        IsDropDownLabel                 matlab.ui.control.Label
        IsDropDown                      matlab.ui.control.DropDown
        EditField                       matlab.ui.control.EditField
        ComparedtoEditFieldLabel        matlab.ui.control.Label
        ComparedtoEditField             matlab.ui.control.EditField
        OrButton                        matlab.ui.control.Button
        WhileEditPanel                  matlab.ui.container.Panel
        WhileEditNode                   matlab.ui.container.Panel
        WhileDropDownLabel              matlab.ui.control.Label
        WhileVar                        matlab.ui.control.DropDown
        IsDropDown_2Label               matlab.ui.control.Label
        WhileCond                       matlab.ui.control.DropDown
        WhileCustomVar                  matlab.ui.control.EditField
        ComparedtoEditField_2Label      matlab.ui.control.Label
        WhileComp                       matlab.ui.control.EditField
        WhileAddOr                      matlab.ui.control.Button
        WhileAddAnd                     matlab.ui.control.Button
        RoutineEditPanel                matlab.ui.container.Panel
        RoutineEdit                     matlab.ui.control.Button
        RoutineName                     matlab.ui.control.Label
        RoutineDuration                 matlab.ui.control.Label
        RoutineElementNo                matlab.ui.control.Label
    end

    
    properties (Access = public)
        Flow
        
        If
        For
        While
        Random
        Routine
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = floor(get(groot, 'Screensize').*0.9);
            
            app.Flow = {};
            
            app.If = struct("Class", ['If'], "Spec", [], "Node", [], "EditNode", [], "Contents", [], "Else", struct("Class", ['Else'], "Node", {}, "EditNode", {}, "Contents", {}));
            app.For = struct("Class", ['For'], "Spec", [], "Node", [], "EditNode", [], "Contents", []);
            app.While = struct("Class", ['While'], "Spec", [], "Node", [], "EditNode", [], "Contents", []);
            app.Random = struct("Class", ['Random'], "Spec", [], "Node", [], "EditNode", [], "Contents", []);
            app.Routine = struct("Class", ['Routine'], "Spec", [], "Node", [], "EditNode", []);
        end

        % Value changed function: WhileVar
        function WhileVarValueChanged(app, event)
            if strcmp(app.WhileVar.Value, "Custom") || strcmp(app.WhileVar.Value, "Time Since...")
                event.Source.Parent.Children(3).Enable = 'on';
            else
                event.Source.Parent.Children(3).Enable = 'off';
            end
        end

        % Button pushed function: WhileAddOr
        function WhileAddOrButtonPushed(app, event)
            node = event.Source.Parent.Children(3);
            or = event.Source.Parent.Children(2);
            and = event.Source.Parent.Children(1);
            event.Source.Parent.Position(4) = event.Source.Parent.Position(4) + node.Position(4);
            newnode = copyobj(node, event.Source.Parent, 'legacy');
            newnode.Position(2) = newnode.Position(2) - node.Position(4);
            or.Position(2) = or.Position(2) - node.Position(4);
            and.Position(2) = and.Position(2) - node.Position(4);
            newnode.Children(7).Text = 'Or...';
        end

        % Button pushed function: FirstAdd
        function AddAfter(app, event)
            if event.Source.Parent == app.UIFigure %if this is the first node to be added
                flowInd = 'app.Flow';
                localInd = 1;
            else
                flowInd = event.Source.Parent.Tag;
                localInd = 1;
            end
            
            app.AddNodePanel.Tag = pos;
            app.AddNodePanel.Position = event.Position;
            
        end

        % Callback function
        function AddRoutine(app, event)
            app.Flow{1} = app.Routine;
            app.Flow{1}.Node = copyobj(app.rtNode, app.UIFigure, 'legacy');
            app.Flow{1}.Node.Tag = event.Tag;
            
        end

        % Callback function
        function AddIfStatement(app, event)
            app.Flow{1} = app.If;
            app.Flow{1}.Node = copyobj(app.IfNode, app.UIFigure, 'legacy');
            app.Flow{1}.Node.Tag = event.Tag;
            
        end

        % Callback function
        function AddRandomiser(app, event)
            app.Flow{1} = app.Random;
            app.Flow{1}.Node = copyobj(app.RandNode, app.UIFigure, 'legacy');
            app.Flow{1}.Node.Tag = event.Tag;
            
        end

        % Callback function
        function AddForLoop(app, event)
            app.Flow{1} = app.For;
            app.Flow{1}.Node = copyobj(app.ForNode, app.UIFigure, 'legacy');
            app.Flow{1}.Node.Tag = event.Tag;
            
        end

        % Callback function
        function AddWhileLoop(app, event)
            app.Flow{1} = app.While;
            app.Flow{1}.Node = copyobj(app.WhileNode, app.UIFigure, 'legacy');
            app.Flow{1}.Node.Tag = event.Tag;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AddNodePanel = uipanel(app.UIFigure);
            app.AddRoutineButton = uibutton(app.AddNodePanel, 'state');
            app.AddRoutineButton.Tooltip = {'Run routine(s) or loop(s) only if a conditon is met'};
            app.AddRoutineButton.Text = 'Add Routine';
            app.AddRandomiserButton = uibutton(app.AddNodePanel, 'state');
            app.AddRandomiserButton.Tooltip = {'Run routine(s) n times in a random order'};
            app.AddRandomiserButton.Text = 'Add Randomiser';
            app.AddIfStatementButton = uibutton(app.AddNodePanel, 'state');
            app.AddIfStatementButton.Tooltip = {'Run routine(s) or loop(s) only if a conditon is met'};
            app.AddIfStatementButton.Text = 'Add If Statement';
            app.AddForLoopButton = uibutton(app.AddNodePanel, 'state');
            app.AddForLoopButton.Tooltip = {'Run routine(s) n times in order'};
            app.AddForLoopButton.Text = 'Add For Loop';
            app.AddWhileLoopButton = uibutton(app.AddNodePanel, 'state');
            app.AddWhileLoopButton.Tooltip = {'Run routine(s) until a condition is met'};
            app.AddWhileLoopButton.Text = 'Add While Loop';
            app.RandEditPanel = uipanel(app.UIFigure);
            app.labelRandOrderRun = uilabel(app.RandEditPanel);
            app.labelRandOrderRun.Text = {'In random order'; 'run...'};
            app.labelOfFollowingRoutines = uilabel(app.RandEditPanel);
            app.labelOfFollowingRoutines.Text = {'...of the following '; 'routines.'};
            app.RandOrderSwitch = uiswitch(app.RandEditPanel, 'slider');
            app.RandOrderSwitch.Items = {'Present Evenly', 'Fully Random'};
            app.RandOrderSwitch.Tooltip = {'Run routines in a purely random order or balance to avoid duplicates?'};
            app.RandOrderSwitch.Value = 'Present Evenly';
            app.NoRands = uispinner(app.RandEditPanel);
            app.NoRands.Limits = [1 Inf];
            app.NoRands.Value = 1;
            app.WhileNode = uipanel(app.UIFigure);
            app.WhileButton = uibutton(app.WhileNode, 'state');
            app.WhileButton.Text = 'While...';
            app.WhileAddIn = uibutton(app.WhileNode, 'push');
            app.WhileAddIn.Text = '+';
            app.WhileAddNext = uibutton(app.WhileNode, 'push');
            app.WhileAddNext.Text = '+';
            app.WhileDelete = uibutton(app.WhileNode, 'push');
            app.WhileDelete.Text = 'x';
            app.WhileEditButton = uibutton(app.WhileNode, 'state');
            app.WhileEditButton.Text = 'e';
            app.ForNode = uipanel(app.UIFigure);
            app.ForButton = uibutton(app.ForNode, 'state');
            app.ForButton.Text = 'For..';
            app.ForEditButton = uibutton(app.ForNode, 'state');
            app.ForEditButton.Text = 'e';
            app.ForAddNext = uibutton(app.ForNode, 'push');
            app.ForAddNext.Text = '+';
            app.ForAddIn = uibutton(app.ForNode, 'push');
            app.ForAddIn.Text = '+';
            app.ForDelete = uibutton(app.ForNode, 'push');
            app.ForDelete.Text = 'x';
            app.RandNode = uipanel(app.UIFigure);
            app.RandButton = uibutton(app.RandNode, 'state');
            app.RandButton.Text = 'Randomise...';
            app.RandEditButton = uibutton(app.RandNode, 'state');
            app.RandEditButton.Text = 'e';
            app.RandAddNext = uibutton(app.RandNode, 'push');
            app.RandAddNext.Text = '+';
            app.RandAddIn = uibutton(app.RandNode, 'push');
            app.RandAddIn.Text = '+';
            app.RandDelete = uibutton(app.RandNode, 'push');
            app.RandDelete.Text = 'x';
            app.IfNode = uipanel(app.UIFigure);
            app.IfButton = uibutton(app.IfNode, 'state');
            app.IfButton.Text = 'If...';
            app.IfEditButton = uibutton(app.IfNode, 'state');
            app.IfEditButton.Text = 'e';
            app.IfAddNext = uibutton(app.IfNode, 'push');
            app.IfAddNext.Text = '+';
            app.IfAddIn = uibutton(app.IfNode, 'push');
            app.IfAddIn.Text = '+';
            app.IfDelete = uibutton(app.IfNode, 'push');
            app.IfDelete.Text = 'x';
            app.rtNode = uipanel(app.UIFigure);
            app.rtAddNext = uibutton(app.rtNode, 'push');
            app.rtAddNext.Text = '+';
            app.rtButton = uibutton(app.rtNode, 'state');
            app.rtButton.Text = 'Routine';
            app.rtDelete = uibutton(app.rtNode, 'push');
            app.rtDelete.Text = 'x';
            app.FirstAdd = uibutton(app.UIFigure, 'push');
            app.FirstAdd.ButtonPushedFcn = createCallbackFcn(app, @AddAfter, true);
            app.FirstAdd.Text = '+';
            app.ElseNode = uipanel(app.UIFigure);
            app.ElseAddIn = uibutton(app.ElseNode, 'push');
            app.ElseAddIn.Text = '+';
            app.ElseButton = uibutton(app.ElseNode, 'state');
            app.ElseButton.Text = 'Else...';
            app.ForEditPanel = uipanel(app.UIFigure);
            app.NumberofiterationsSpinnerLabel = uilabel(app.ForEditPanel);
            app.NumberofiterationsSpinnerLabel.Text = 'Number of iterations:';
            app.NumberofiterationsSpinner = uispinner(app.ForEditPanel);
            app.NumberofiterationsSpinner.Limits = [1 Inf];
            app.NumberofiterationsSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.NumberofiterationsSpinner.Value = 1;
            app.VariablestoiterateEditFieldLabel = uilabel(app.ForEditPanel);
            app.VariablestoiterateEditFieldLabel.Text = 'Variable(s) to iterate:';
            app.VariablestoiterateEditField = uieditfield(app.ForEditPanel, 'text');
            app.VariablestoiterateEditField.Tooltip = {'Name a variable or variables (seperated by commas) to iterate each time this loop loops.'; 'You can refer back to this variable later if you want anything to change on each loop. "trial" will automatically change certain elements each time.'};
            app.VariablestoiterateEditField.Value = 'trial';
            app.WhileEditPanel_3 = uipanel(app.UIFigure);
            app.IfEditPanel = uipanel(app.WhileEditPanel_3);
            app.IfDropDownLabel = uilabel(app.IfEditPanel);
            app.IfDropDownLabel.Text = 'If...';
            app.IfDropDown = uidropdown(app.IfEditPanel);
            app.IfDropDown.Items = {'Trial', 'Time', 'Time Since...', 'Response to...', 'Custom'};
            app.IfDropDown.Value = 'Trial';
            app.IsDropDownLabel = uilabel(app.IfEditPanel);
            app.IsDropDownLabel.Text = 'Is...';
            app.IsDropDown = uidropdown(app.IfEditPanel);
            app.IsDropDown.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.IsDropDown.Value = 'Less Than';
            app.EditField = uieditfield(app.IfEditPanel, 'text');
            app.EditField.Enable = 'off';
            app.ComparedtoEditFieldLabel = uilabel(app.IfEditPanel);
            app.ComparedtoEditFieldLabel.Text = 'Compared to...';
            app.ComparedtoEditField = uieditfield(app.IfEditPanel, 'text');
            app.OrButton = uibutton(app.WhileEditPanel_3, 'push');
            app.OrButton.Text = 'Or...';
            app.WhileEditPanel = uipanel(app.UIFigure);
            app.WhileEditNode = uipanel(app.WhileEditPanel);
            app.WhileDropDownLabel = uilabel(app.WhileEditNode);
            app.WhileDropDownLabel.Text = 'While...';
            app.WhileVar = uidropdown(app.WhileEditNode);
            app.WhileVar.Items = {'Trial', 'Time', 'Time Since...', 'Custom'};
            app.WhileVar.ValueChangedFcn = createCallbackFcn(app, @WhileVarValueChanged, true);
            app.WhileVar.Value = 'Trial';
            app.IsDropDown_2Label = uilabel(app.WhileEditNode);
            app.IsDropDown_2Label.Text = 'Is...';
            app.WhileCond = uidropdown(app.WhileEditNode);
            app.WhileCond.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.WhileCond.Value = 'Less Than';
            app.WhileCustomVar = uieditfield(app.WhileEditNode, 'text');
            app.WhileCustomVar.Enable = 'off';
            app.ComparedtoEditField_2Label = uilabel(app.WhileEditNode);
            app.ComparedtoEditField_2Label.Text = 'Compared to...';
            app.WhileComp = uieditfield(app.WhileEditNode, 'text');
            app.WhileAddOr = uibutton(app.WhileEditPanel, 'push');
            app.WhileAddOr.ButtonPushedFcn = createCallbackFcn(app, @WhileAddOrButtonPushed, true);
            app.WhileAddOr.Text = 'Or...';
            app.WhileAddAnd = uibutton(app.WhileEditPanel, 'push');
            app.WhileAddAnd.Text = 'And...';
            app.RoutineEditPanel = uipanel(app.UIFigure);
            app.RoutineEdit = uibutton(app.RoutineEditPanel, 'push');
            app.RoutineEdit.Text = 'Edit';
            app.RoutineName = uilabel(app.RoutineEditPanel);
            app.RoutineName.Text = 'Routine Name';
            app.RoutineDuration = uilabel(app.RoutineEditPanel);
            app.RoutineDuration.Text = 'Duration';
            app.RoutineElementNo = uilabel(app.RoutineEditPanel);
            app.RoutineElementNo.Text = '# Elements';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Experiment_Old

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end