classdef PositionStim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimPos          matlab.ui.Figure
        XSlider          matlab.ui.control.Slider
        YSlider          matlab.ui.control.Slider
        Stimulus         matlab.ui.control.Button
        StimSize         matlab.ui.control.Spinner
        SizeLabel        matlab.ui.control.Label
        CenterX          matlab.ui.control.Button
        CenterY          matlab.ui.control.Button
        XEditFieldLabel  matlab.ui.control.Label
        XPos             matlab.ui.control.NumericEditField
        YLabel           matlab.ui.control.Label
        YPos             matlab.ui.control.NumericEditField
        Save             matlab.ui.control.Button
    end


    properties (Access = public)
        Parent %All data from app this was called from
        ScreenDim % Dimensions of screen
        StimDim %Dimensions of stimulus
        output % Output back to parent function
    end

    properties (Access = private)
    end

    methods (Access = public)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: CenterX
        function CenterXButtonPushed(app, event)
            app.Stimulus.Position(1) = round(app.ScreenDim(1)/2)-round(app.Stimulus.Position(3)/2);
            app.XPos.Value = round(app.ScreenDim(1)/2)-round(app.Stimulus.Position(3)/2);
            app.XSlider.Value = round(app.ScreenDim(1)/2)-round(app.Stimulus.Position(3)/2);
        end

        % Button pushed function: CenterY
        function CenterYButtonPushed(app, event)
            app.Stimulus.Position(2) = round(app.ScreenDim(2)/2)-round(app.Stimulus.Position(4)/2);
            app.YPos.Value = round(app.ScreenDim(2)/2)-round(app.Stimulus.Position(4)/2);
            app.YSlider.Value = round(app.ScreenDim(2)/2)-round(app.Stimulus.Position(4)/2);  
        end

        % Callback function
        function SaveButtonPushed(app, event)
            app.Parent = saveobj(app.StimPos);
        end

        % Close request function: StimPos
        function StimPosCloseRequest(app, event)
            delete(app);
        end

        % Value changed function: StimSize
        function StimSizeFcn(app, event)
          app.Stimulus.Position(3) = app.StimDim(1)*app.StimSize.Value;
          app.Stimulus.Position(4) = app.StimDim(2)*app.StimSize.Value;
        end

        % Value changing function: XSlider
        function XSliderFcn(app, event)
            app.Stimulus.Position(1) = app.XSlider.Value;
            app.XPos.Value = app.XSlider.Value;
        end

        % Value changed function: YSlider
        function YSliderFcn(app, event)
            app.Stimulus.Position(2) = app.YSlider.Value;
            app.YPos.Value = app.YSlider.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimPos = uifigure;
            app.StimPos.Name = 'Position Stimulus';
            app.StimPos.CloseRequestFcn = createCallbackFcn(app, @StimPosCloseRequest, true);
            app.XSlider = uislider(app.StimPos);
            app.XSlider.Limits = [0 1];
            app.XSlider.MajorTicks = [];
            app.XSlider.MajorTickLabels = {};
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderFcn, true);
            app.XSlider.MinorTicks = [];
            app.YSlider = uislider(app.StimPos);
            app.YSlider.Limits = [0 1];
            app.YSlider.MajorTicks = [];
            app.YSlider.MajorTickLabels = {};
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderFcn, true);
            app.YSlider.MinorTicks = [];
            app.YSlider.Value = 1;
            app.Stimulus = uibutton(app.StimPos, 'push');
            app.Stimulus.Text = 'Stimulus';
            app.StimSize = uispinner(app.StimPos);
            app.StimSize.Step = 0.1;
            app.StimSize.LowerLimitInclusive = 'off';
            app.StimSize.UpperLimitInclusive = 'off';
            app.StimSize.ValueChangedFcn = createCallbackFcn(app, @StimSizeFcn, true);
            app.StimSize.Limits = [0 Inf];
            app.StimSize.ValueDisplayFormat = '%.1f';
            app.StimSize.Editable = 'off';
            app.StimSize.Value = 1;
            app.SizeLabel = uilabel(app.StimPos);
            app.SizeLabel.Text = 'Size:';
            app.CenterX = uibutton(app.StimPos, 'push');
            app.CenterX.ButtonPushedFcn = createCallbackFcn(app, @CenterXButtonPushed, true);
            app.CenterX.Text = 'Center';
            app.CenterY = uibutton(app.StimPos, 'push');
            app.CenterY.ButtonPushedFcn = createCallbackFcn(app, @CenterYButtonPushed, true);
            app.CenterY.Text = 'Center';
            app.XEditFieldLabel = uilabel(app.StimPos);
            app.XEditFieldLabel.Text = 'X';
            app.XPos = uieditfield(app.StimPos, 'numeric');
            app.XPos.Editable = 'off';
            app.YLabel = uilabel(app.StimPos);
            app.YLabel.Text = 'Y';
            app.YPos = uieditfield(app.StimPos, 'numeric');
            app.YPos.Editable = 'off';
            app.Save = uibutton(app.StimPos, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Okay';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PositionStim

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StimPos)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimPos)
        end
    end
end