classdef Pedestal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        ComponentsMenu                  matlab.ui.container.Menu
        ElementsMenu                    matlab.ui.container.Menu
        NewElementMenu                  matlab.ui.container.Menu
        ImportElementMenu               matlab.ui.container.Menu
        ManageElementsMenu              matlab.ui.container.Menu
        RoutinesMenu                    matlab.ui.container.Menu
        NewRoutineMenu                  matlab.ui.container.Menu
        ImportRoutineMenu               matlab.ui.container.Menu
        ManageRoutinesMenu              matlab.ui.container.Menu
        EditDesignMenu                  matlab.ui.container.Menu
        ExperimentMenu                  matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        StopMenu                        matlab.ui.container.Menu
        CompileMenu                     matlab.ui.container.Menu
        Tree                            matlab.ui.container.Tree
        WithinSubjectsFactorsNode       matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode      matlab.ui.container.TreeNode
        MeasuresNode                    matlab.ui.container.TreeNode
        Node                            matlab.ui.container.TreeNode
        EditDesignButton                matlab.ui.control.Button
        ElementTree                     matlab.ui.container.Tree
        ManageRoutinesButton            matlab.ui.control.Button
        Tree_2                          matlab.ui.container.Tree
        Node_2                          matlab.ui.container.TreeNode
        ElementName                     matlab.ui.control.EditField
        ManageElementsButton_2          matlab.ui.control.Button
        ElementTree_2                   matlab.ui.container.Tree
        ManageDataButton                matlab.ui.control.Button
        DesignLabel                     matlab.ui.control.Label
        ElementsLabel                   matlab.ui.control.Label
        RoutinesLabel_3                 matlab.ui.control.Label
        DataLabel                       matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ProcedureTree                   matlab.ui.container.Tree
        PTBlank                         matlab.ui.container.TreeNode
        ProcedureLabel                  matlab.ui.control.Label
        EditProcedureButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ElementTab                      matlab.ui.container.Tab
        RoutineTab                      matlab.ui.container.Tab
        RoutineEdit                     matlab.ui.control.Button
        RoutineName                     matlab.ui.control.Label
        RoutineDuration                 matlab.ui.control.Label
        RoutineElementNo                matlab.ui.control.Label
        MoveButton                      matlab.ui.control.StateButton
        DeleteButton                    matlab.ui.control.Button
        VariableTab                     matlab.ui.container.Tab
        SetthevalueofEditFieldLabel     matlab.ui.control.Label
        VariableField                   matlab.ui.control.EditField
        TobeequaltoEditFieldLabel       matlab.ui.control.Label
        ValueField                      matlab.ui.control.EditField
        RandomField                     matlab.ui.control.CheckBox
        RandPanel                       matlab.ui.container.Panel
        DistSwitch                      matlab.ui.control.Switch
        RandLabel                       matlab.ui.control.Label
        distributionLabel               matlab.ui.control.Label
        RoundedtothenearestSpinnerLabel  matlab.ui.control.Label
        RoundSpinner                    matlab.ui.control.Spinner
        andSpinnerLabel                 matlab.ui.control.Label
        UpperSpinner                    matlab.ui.control.Spinner
        BetweenSpinnerLabel             matlab.ui.control.Label
        LowerSpinner                    matlab.ui.control.Spinner
        decimalplaceLabel               matlab.ui.control.Label
        MoveButton_2                    matlab.ui.control.StateButton
        DeleteButton_2                  matlab.ui.control.Button
        IfTab                           matlab.ui.container.Tab
        IfField                         matlab.ui.control.EditField
        IfDropDownLabel                 matlab.ui.control.Label
        IfDropDown                      matlab.ui.control.DropDown
        IsDropDownLabel                 matlab.ui.control.Label
        IfIsDropDown                    matlab.ui.control.DropDown
        ComparedtoEditFieldLabel        matlab.ui.control.Label
        IfComparedToField               matlab.ui.control.EditField
        MoveButton_3                    matlab.ui.control.StateButton
        DeleteButton_3                  matlab.ui.control.Button
        ForTab                          matlab.ui.container.Tab
        HowmanyrepeatsSpinnerLabel      matlab.ui.control.Label
        ForRepeatsSpinner               matlab.ui.control.Spinner
        tothevariableLabel              matlab.ui.control.Label
        ForIteratorField                matlab.ui.control.EditField
        EachrepeataddSpinnerLabel       matlab.ui.control.Label
        ForIntervalSpinner              matlab.ui.control.Spinner
        startingatSpinnerLabel          matlab.ui.control.Label
        ForStartSpinner                 matlab.ui.control.Spinner
        MoveButton_4                    matlab.ui.control.StateButton
        DeleteButton_4                  matlab.ui.control.Button
        WhileTab                        matlab.ui.container.Tab
        WhileField                      matlab.ui.control.EditField
        RepeatuntilDropDownLabel        matlab.ui.control.Label
        WhileDropDown                   matlab.ui.control.DropDown
        IsDropDown_2Label               matlab.ui.control.Label
        WhileIsDropDown                 matlab.ui.control.DropDown
        ComparedtoEditField_2Label      matlab.ui.control.Label
        WhileComparedToField            matlab.ui.control.EditField
        tothevariableLabel_2            matlab.ui.control.Label
        WhileIteratorField              matlab.ui.control.EditField
        EachrepeataddSpinnerLabel_2     matlab.ui.control.Label
        WhileIntervalSpinner            matlab.ui.control.Spinner
        startingatSpinnerLabel_2        matlab.ui.control.Label
        WhileStartSpinner               matlab.ui.control.Spinner
        MoveButton_5                    matlab.ui.control.StateButton
        DeleteButton_5                  matlab.ui.control.Button
        RandomTab                       matlab.ui.container.Tab
        RandLab1                        matlab.ui.control.Label
        RandLab2                        matlab.ui.control.Label
        RandSpinner                     matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RandFullButton                  matlab.ui.control.ToggleButton
        RandEvenButton                  matlab.ui.control.ToggleButton
        MoveButton_6                    matlab.ui.control.StateButton
        DeleteButton_6                  matlab.ui.control.Button
        CodeTab                         matlab.ui.container.Tab
        TyperawMATLABcodeTextAreaLabel  matlab.ui.control.Label
        CodeText                        matlab.ui.control.TextArea
        MoveButton_7                    matlab.ui.control.StateButton
        DeleteButton_7                  matlab.ui.control.Button
        NoneTab                         matlab.ui.container.Tab
    end

    
    methods (Access = public)
        function loadSpec(app, node)
            switch node.Tag
                case 'If'
                    app.IfDropDown.Value = node.NodeData.IfDropDown;
                    app.IfField.Value = node.NodeData.IfField;
                    app.IfIsDropDown.Value = node.NodeData.IfIsDropDown;
                    app.IfComparedToField.Value = node.NodeData.IfComparedToField;
                    
                case 'Else'
                    app.IfDropDown.Value = node.Parent.NodeData.IfDropDown;
                    app.IfField.Value = node.Parent.NodeData.IfField;
                    app.IfIsDropDown.Value = node.Parent.NodeData.IfIsDropDown;
                    app.IfComparedToField.Value = node.Parent.NodeData.IfComparedToField;
                    
                case 'While'
                    app.WhileDropDown.Value = node.NodeData.WhileDropDown;
                    app.WhileField.Value = node.NodeData.WhileField;
                    app.WhileIsDropDown.Value = node.NodeData.WhileIsDropDown;
                    app.WhileComparedToField.Value = node.NodeData.WhileComparedToField;
                    app.WhileIntervalSpinner.Value = node.NodeData.WhileIntervalSpinner;
                    app.WhileIteratorField.Value = node.NodeData.WhileIteratorField;
                    app.WhileStartSpinner.Value = node.NodeData.WhileStartSpinner;
                    
                case 'For'
                    app.ForRepeatsSpinner.Value = node.NodeData.ForRepeatsSpinner;
                    app.ForIntervalSpinner.Value = node.NodeData.ForIntervalSpinner;
                    app.ForIteratorField.Value = node.NodeData.ForIteratorField;
                    app.ForStartSpinner.Value = node.NodeData.ForStartSpinner;
                    
                case 'Random'
                    app.RandSpinner.Value = node.NodeData.RandSpinner;
                    app.RandSpinner.Limits(2) = length({node.Children});
                    app.RandFullButton.Value = node.NodeData.RandFullButton;
                    app.RandEvenButton.Value = node.NodeData.RandEvenButton;
                    
                case 'R'
                    app.RoutineName.Text = node.NodeData.Name;
                    app.RoutineDuration.Text = [num2str(node.NodeData.Dur), ' ms'];
                    app.RoutineElementNo.Text = [num2str(length(node.NodeData.Elements)), ' Elements'];
                    
                case 'Code'
                    app.CodeText.Value = node.NodeData.Code;
                    
                case 'Variable'
                    app.VariableField.Value = node.NodeData.Variable;
                    app.ValueField.Value = node.NodeData.Value;
                    app.RandomField.Value = contains(node.NodeData.Value, 'rand');
                    app.DistSwitch.Value = node.NodeData.Random.Distribution;
                    app.LowerSpinner.Value = node.NodeData.Random.Lower;
                    app.UpperSpinner.Value = node.NodeData.Random.Upper;
                    app.RoundSpinner.Value = node.NodeData.Random.Round;
                    
                case 'El'
                    
                    
            end
        end
        
        function loadproc(app)
            load('Design\Procedure.mat')
            i = app.ProcedureTree.Children ~= app.PTBlank;
            delete(app.ProcedureTree.Children(i));
            for n = 1:length(pr)
                parent = uitreenode(app.ProcedureTree, app.PTBlank, 'before', ...
                    'Text', pr(n).Text, ...
                    'Tag', pr(n).Tag, ...
                    'NodeData', pr(n).NodeData ...
                    );
                switch parent.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'}
                        procCycle(app, pr(n).Children, parent);
                    case 'R'
                        for e = 1:length(pr(n).NodeData.Elements)
                            el = pr(n).NodeData.Elements{e};
                            uitreenode(parent, ...
                                'Text', el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        function procCycle(app, children, parent)
            for n = 1:length(children)
                child = uitreenode(parent, ...
                    'Text', children(n).Text, ...
                    'Tag', children(n).Tag, ...
                    'NodeData', children(n).NodeData ...
                    );
                switch child.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'}
                        procCycle(app, children(n).Children, child);
                    case 'R'
                        for e = 1:length(children(n).NodeData.Elements)
                            el = children(n).NodeData.Elements{e};
                            uitreenode(child, ...
                                'Text', el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        
        function loaddes(app)
            load('Design\Design.mat');
            
            % load BS factors
            for n = 1:length(des.BS_Factors)
                parent = uitreenode(app.BetweenSubjectsFactorsNode,  ...
                    'Text', des.BS_Factors(n).Name, ...
                    'NodeData', des.BS_Factors(n).Levels, ...
                    'Tag', 'B' ...
                    );
                for c = 1:length(parent.NodeData)
                    uitreenode(parent,  ...
                    'Text', parent.NodeData(c).Text, ...
                    'Tag', 'L' ...
                    );
                end
            end
            expand(app.BetweenSubjectsFactorsNode)
            
            % load WS Factors
            for n = 1:length(des.WS_Factors)
                parent = uitreenode(app.WithinSubjectsFactorsNode,  ...
                    'Text', des.WS_Factors(n).Name, ...
                    'NodeData', des.WS_Factors(n).Levels, ...
                    'Tag', 'W' ...
                    );
                for c = 1:length(parent.NodeData)
                    uitreenode(parent,  ...
                    'Text', parent.NodeData(c).Text, ...
                    'Tag', 'L' ...
                    );
                end
            end
            expand(app.WithinSubjectsFactorsNode)
            
            % load Measures
            for n = 1:length(des.Measures)
                parent = uitreenode(app.MeasuresNode,  ...
                    'Text', des.Measures(n).Name, ...
                    'NodeData', des.Measures(n).Type, ...
                    'Tag', 'M' ...
                    );
            end
            expand(app.MeasuresNode)
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            addpath('Design', 'Elements', 'Routines', 'Icons');
            
            %% Splash
            im = imread('Splash.png'); %load splash image
            imsize = size(im); %get original image size
            imsize = imsize*(1000/max(imsize)); %transform so it fits within a 1000*1000 box
            
            splash = figure('Name', '');
            splash.MenuBar = 'none';
            splash.NumberTitle = 'off';
            splash.NumberTitle = 'off';
            splash.Position = [200,200,imsize(2),imsize(1)];
            
            splaxis = axes('Parent',splash);
            splaxis.Units = 'normalized';
            splaxis.Position = [0 0 1 1];
            image(splaxis, im);
            splaxis.TickDir = 'out';
            
            movegui(splash,'center'); %move to centre
            set(splash,'Visible','on')
            %%
            
            pause(1) %load content (placeholder)
            loadproc(app)
            loaddes(app)
            
            delete(splash)
            movegui(app.UIFigure,'center')
            set(0,'units','pixels');
            app.UIFigure.Visible = 'on';
            
        end

        % Button pushed function: EditDesignButton
        function EditDesignButtonPushed(app, event)
            load('Design\Design.mat')
            d = Design;
            d.loadobj(des)
        end

        % Button pushed function: EditProcedureButton
        function EditProcedureButtonPushed(app, event)
            load('Design\Procedure.mat')
            p = Procedure;
            p.loadobj(pr)
        end

        % Selection changed function: ProcedureTree
        function ProcedureTreeSelectionChanged(app, event)
            switch event.SelectedNodes.Tag
                case 'If'
                    app.TabGroup.SelectedTab = app.IfTab;
                case 'Else'
                    app.TabGroup.SelectedTab = app.IfTab;
                case 'While'
                    app.TabGroup.SelectedTab = app.WhileTab;
                case 'For'
                    app.TabGroup.SelectedTab = app.ForTab;
                case 'Random'
                    app.TabGroup.SelectedTab = app.RandomTab;
                case 'R'
                    app.TabGroup.SelectedTab = app.RoutineTab;
                case 'Code'
                    app.TabGroup.SelectedTab = app.CodeTab;
                case 'Variable'
                    app.TabGroup.SelectedTab = app.VariableTab;
                case 'El'
                    app.TabGroup.SelectedTab = app.ElementTab;
                otherwise
                    app.TabGroup.SelectedTab = app.NoneTab;
            end
            
            loadSpec(app, app.ProcedureTree.SelectedNodes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New...';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.ComponentsMenu = uimenu(app.UIFigure);
            app.ComponentsMenu.Text = 'Components';
            app.ElementsMenu = uimenu(app.ComponentsMenu);
            app.ElementsMenu.Text = 'Elements';
            app.NewElementMenu = uimenu(app.ElementsMenu);
            app.NewElementMenu.Text = 'New Element';
            app.ImportElementMenu = uimenu(app.ElementsMenu);
            app.ImportElementMenu.Text = 'Import Element';
            app.ManageElementsMenu = uimenu(app.ElementsMenu);
            app.ManageElementsMenu.Text = 'Manage Elements';
            app.RoutinesMenu = uimenu(app.ComponentsMenu);
            app.RoutinesMenu.Text = 'Routines';
            app.NewRoutineMenu = uimenu(app.RoutinesMenu);
            app.NewRoutineMenu.Text = 'New Routine';
            app.ImportRoutineMenu = uimenu(app.RoutinesMenu);
            app.ImportRoutineMenu.Text = 'Import Routine';
            app.ManageRoutinesMenu = uimenu(app.RoutinesMenu);
            app.ManageRoutinesMenu.Text = 'Manage Routines';
            app.EditDesignMenu = uimenu(app.ComponentsMenu);
            app.EditDesignMenu.Text = 'Edit Design...';
            app.ExperimentMenu = uimenu(app.UIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.RunMenu = uimenu(app.ExperimentMenu);
            app.RunMenu.Text = 'Run';
            app.StopMenu = uimenu(app.ExperimentMenu);
            app.StopMenu.Text = 'Stop';
            app.CompileMenu = uimenu(app.ExperimentMenu);
            app.CompileMenu.Text = 'Compile...';
            app.Tree = uitree(app.UIFigure);
            app.WithinSubjectsFactorsNode = uitreenode(app.Tree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.Tree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.Tree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.Node = uitreenode(app.Tree);
            app.Node.NodeData = 'N';
            app.Node.Text = '';
            app.EditDesignButton = uibutton(app.UIFigure, 'push');
            app.EditDesignButton.ButtonPushedFcn = createCallbackFcn(app, @EditDesignButtonPushed, true);
            app.EditDesignButton.Text = 'Edit Design';
            app.ElementTree = uitree(app.UIFigure);
            app.ManageRoutinesButton = uibutton(app.UIFigure, 'push');
            app.ManageRoutinesButton.Text = 'Manage Routines';
            app.Tree_2 = uitree(app.UIFigure);
            app.Node_2 = uitreenode(app.Tree_2);
            app.Node_2.NodeData = 'N';
            app.Node_2.Text = '';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.Value = 'Experiment Name';
            app.ManageElementsButton_2 = uibutton(app.UIFigure, 'push');
            app.ManageElementsButton_2.Text = 'Manage Elements';
            app.ElementTree_2 = uitree(app.UIFigure);
            app.ManageDataButton = uibutton(app.UIFigure, 'push');
            app.ManageDataButton.Text = 'Manage Data';
            app.DesignLabel = uilabel(app.UIFigure);
            app.DesignLabel.Text = 'Design';
            app.ElementsLabel = uilabel(app.UIFigure);
            app.ElementsLabel.Text = 'Elements';
            app.RoutinesLabel_3 = uilabel(app.UIFigure);
            app.RoutinesLabel_3.Text = 'Routines';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Text = 'Data';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.ProcedureTree = uitree(app.UIFigure);
            app.ProcedureTree.SelectionChangedFcn = createCallbackFcn(app, @ProcedureTreeSelectionChanged, true);
            app.PTBlank = uitreenode(app.ProcedureTree);
            app.PTBlank.NodeData = 'N';
            app.PTBlank.Text = '';
            app.ProcedureLabel = uilabel(app.UIFigure);
            app.ProcedureLabel.Text = 'Procedure';
            app.EditProcedureButton = uibutton(app.UIFigure, 'push');
            app.EditProcedureButton.ButtonPushedFcn = createCallbackFcn(app, @EditProcedureButtonPushed, true);
            app.EditProcedureButton.Text = 'Edit Procedure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ElementTab = uitab(app.TabGroup);
            app.ElementTab.Title = 'Element';
            app.RoutineTab = uitab(app.TabGroup);
            app.RoutineTab.Title = 'Routine';
            app.RoutineEdit = uibutton(app.RoutineTab, 'push');
            app.RoutineEdit.Text = 'Edit';
            app.RoutineName = uilabel(app.RoutineTab);
            app.RoutineName.Text = 'Routine Name';
            app.RoutineDuration = uilabel(app.RoutineTab);
            app.RoutineDuration.Text = 'Duration';
            app.RoutineElementNo = uilabel(app.RoutineTab);
            app.RoutineElementNo.Text = '# Elements';
            app.MoveButton = uibutton(app.RoutineTab, 'state');
            app.MoveButton.Text = 'Move';
            app.DeleteButton = uibutton(app.RoutineTab, 'push');
            app.DeleteButton.Text = 'Delete';
            app.VariableTab = uitab(app.TabGroup);
            app.VariableTab.Title = 'Variable';
            app.SetthevalueofEditFieldLabel = uilabel(app.VariableTab);
            app.SetthevalueofEditFieldLabel.Text = 'Set the value of...';
            app.VariableField = uieditfield(app.VariableTab, 'text');
            app.TobeequaltoEditFieldLabel = uilabel(app.VariableTab);
            app.TobeequaltoEditFieldLabel.Text = 'To be equal to...';
            app.ValueField = uieditfield(app.VariableTab, 'text');
            app.RandomField = uicheckbox(app.VariableTab);
            app.RandomField.Text = 'Random Value';
            app.RandPanel = uipanel(app.VariableTab);
            app.RandPanel.Visible = 'off';
            app.DistSwitch = uiswitch(app.RandPanel, 'slider');
            app.DistSwitch.Items = {'Uniform', 'Normal'};
            app.DistSwitch.ItemsData = {'rand', 'randn'};
            app.DistSwitch.Value = 'rand';
            app.RandLabel = uilabel(app.RandPanel);
            app.RandLabel.Text = {'Generate a random number '; 'from a...'};
            app.distributionLabel = uilabel(app.RandPanel);
            app.distributionLabel.Text = '...distribution.';
            app.RoundedtothenearestSpinnerLabel = uilabel(app.RandPanel);
            app.RoundedtothenearestSpinnerLabel.Text = 'Rounded to the nearest...';
            app.RoundSpinner = uispinner(app.RandPanel);
            app.RoundSpinner.Limits = [0 Inf];
            app.RoundSpinner.Value = 1;
            app.andSpinnerLabel = uilabel(app.RandPanel);
            app.andSpinnerLabel.Text = '...and...';
            app.UpperSpinner = uispinner(app.RandPanel);
            app.UpperSpinner.Value = 1;
            app.BetweenSpinnerLabel = uilabel(app.RandPanel);
            app.BetweenSpinnerLabel.Text = 'Between...';
            app.LowerSpinner = uispinner(app.RandPanel);
            app.decimalplaceLabel = uilabel(app.RandPanel);
            app.decimalplaceLabel.Text = '...decimal place';
            app.MoveButton_2 = uibutton(app.VariableTab, 'state');
            app.MoveButton_2.Text = 'Move';
            app.DeleteButton_2 = uibutton(app.VariableTab, 'push');
            app.DeleteButton_2.Text = 'Delete';
            app.IfTab = uitab(app.TabGroup);
            app.IfTab.Title = 'If';
            app.IfField = uieditfield(app.IfTab, 'text');
            app.IfField.Enable = 'off';
            app.IfDropDownLabel = uilabel(app.IfTab);
            app.IfDropDownLabel.Text = 'If...';
            app.IfDropDown = uidropdown(app.IfTab);
            app.IfDropDown.Items = {'Trial', 'Time', 'Time Since...', 'Response to...', 'Custom'};
            app.IfDropDown.Value = 'Trial';
            app.IsDropDownLabel = uilabel(app.IfTab);
            app.IsDropDownLabel.Text = 'Is...';
            app.IfIsDropDown = uidropdown(app.IfTab);
            app.IfIsDropDown.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.IfIsDropDown.Value = 'Less Than';
            app.ComparedtoEditFieldLabel = uilabel(app.IfTab);
            app.ComparedtoEditFieldLabel.Text = 'Compared to...';
            app.IfComparedToField = uieditfield(app.IfTab, 'text');
            app.MoveButton_3 = uibutton(app.IfTab, 'state');
            app.MoveButton_3.Text = 'Move';
            app.DeleteButton_3 = uibutton(app.IfTab, 'push');
            app.DeleteButton_3.Text = 'Delete';
            app.ForTab = uitab(app.TabGroup);
            app.ForTab.Title = 'For';
            app.HowmanyrepeatsSpinnerLabel = uilabel(app.ForTab);
            app.HowmanyrepeatsSpinnerLabel.Text = 'How many repeats?';
            app.ForRepeatsSpinner = uispinner(app.ForTab);
            app.ForRepeatsSpinner.Limits = [1 Inf];
            app.ForRepeatsSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForRepeatsSpinner.Value = 1;
            app.tothevariableLabel = uilabel(app.ForTab);
            app.tothevariableLabel.Text = '...to the variable...';
            app.ForIteratorField = uieditfield(app.ForTab, 'text');
            app.ForIteratorField.Tooltip = {'Name a variable or variables (seperated by commas) to iterate each time this loop loops.'; 'You can refer back to this variable later if you want anything to change on each loop. "trial" will automatically change certain elements each time.'};
            app.ForIteratorField.Value = 'trial';
            app.EachrepeataddSpinnerLabel = uilabel(app.ForTab);
            app.EachrepeataddSpinnerLabel.Text = 'Each repeat, add...';
            app.ForIntervalSpinner = uispinner(app.ForTab);
            app.ForIntervalSpinner.Limits = [1 Inf];
            app.ForIntervalSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForIntervalSpinner.Value = 1;
            app.startingatSpinnerLabel = uilabel(app.ForTab);
            app.startingatSpinnerLabel.Text = '...starting at...';
            app.ForStartSpinner = uispinner(app.ForTab);
            app.ForStartSpinner.Limits = [1 Inf];
            app.ForStartSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.ForStartSpinner.Value = 1;
            app.MoveButton_4 = uibutton(app.ForTab, 'state');
            app.MoveButton_4.Text = 'Move';
            app.DeleteButton_4 = uibutton(app.ForTab, 'push');
            app.DeleteButton_4.Text = 'Delete';
            app.WhileTab = uitab(app.TabGroup);
            app.WhileTab.Title = 'While';
            app.WhileField = uieditfield(app.WhileTab, 'text');
            app.WhileField.Enable = 'off';
            app.RepeatuntilDropDownLabel = uilabel(app.WhileTab);
            app.RepeatuntilDropDownLabel.Text = 'Repeat until...';
            app.WhileDropDown = uidropdown(app.WhileTab);
            app.WhileDropDown.Items = {'Trial', 'Time', 'Time Since...', 'Custom'};
            app.WhileDropDown.Value = 'Trial';
            app.IsDropDown_2Label = uilabel(app.WhileTab);
            app.IsDropDown_2Label.Text = 'Is...';
            app.WhileIsDropDown = uidropdown(app.WhileTab);
            app.WhileIsDropDown.Items = {'Less Than', 'Less Than or Equal To', 'Equal To', 'Greater Than or Equal To', 'Greater Than', 'Not Equal To'};
            app.WhileIsDropDown.Value = 'Less Than';
            app.ComparedtoEditField_2Label = uilabel(app.WhileTab);
            app.ComparedtoEditField_2Label.Text = 'Compared to...';
            app.WhileComparedToField = uieditfield(app.WhileTab, 'text');
            app.tothevariableLabel_2 = uilabel(app.WhileTab);
            app.tothevariableLabel_2.Text = '...to the variable...';
            app.WhileIteratorField = uieditfield(app.WhileTab, 'text');
            app.WhileIteratorField.Tooltip = {'Name a variable or variables (seperated by commas) to iterate each time this loop loops.'; 'You can refer back to this variable later if you want anything to change on each loop. "trial" will automatically change certain elements each time.'};
            app.WhileIteratorField.Value = 'trial';
            app.EachrepeataddSpinnerLabel_2 = uilabel(app.WhileTab);
            app.EachrepeataddSpinnerLabel_2.Text = 'Each repeat, add...';
            app.WhileIntervalSpinner = uispinner(app.WhileTab);
            app.WhileIntervalSpinner.Limits = [1 Inf];
            app.WhileIntervalSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.WhileIntervalSpinner.Value = 1;
            app.startingatSpinnerLabel_2 = uilabel(app.WhileTab);
            app.startingatSpinnerLabel_2.Text = '...starting at...';
            app.WhileStartSpinner = uispinner(app.WhileTab);
            app.WhileStartSpinner.Limits = [1 Inf];
            app.WhileStartSpinner.Tooltip = {'How many times would you like this loop to repeat?'};
            app.WhileStartSpinner.Value = 1;
            app.MoveButton_5 = uibutton(app.WhileTab, 'state');
            app.MoveButton_5.Text = 'Move';
            app.DeleteButton_5 = uibutton(app.WhileTab, 'push');
            app.DeleteButton_5.Text = 'Delete';
            app.RandomTab = uitab(app.TabGroup);
            app.RandomTab.Title = 'Random';
            app.RandLab1 = uilabel(app.RandomTab);
            app.RandLab1.Text = 'In random order run...';
            app.RandLab2 = uilabel(app.RandomTab);
            app.RandLab2.Text = '...of the nodes in this loop';
            app.RandSpinner = uispinner(app.RandomTab);
            app.RandSpinner.Limits = [0 Inf];
            app.ButtonGroup = uibuttongroup(app.RandomTab);
            app.RandFullButton = uitogglebutton(app.ButtonGroup);
            app.RandFullButton.Text = 'Fully Random';
            app.RandFullButton.Value = true;
            app.RandEvenButton = uitogglebutton(app.ButtonGroup);
            app.RandEvenButton.Text = 'Present Evenly';
            app.MoveButton_6 = uibutton(app.RandomTab, 'state');
            app.MoveButton_6.Text = 'Move';
            app.DeleteButton_6 = uibutton(app.RandomTab, 'push');
            app.DeleteButton_6.Text = 'Delete';
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.TyperawMATLABcodeTextAreaLabel = uilabel(app.CodeTab);
            app.TyperawMATLABcodeTextAreaLabel.Text = 'Type raw MATLAB code:';
            app.CodeText = uitextarea(app.CodeTab);
            app.MoveButton_7 = uibutton(app.CodeTab, 'state');
            app.MoveButton_7.Text = 'Move';
            app.DeleteButton_7 = uibutton(app.CodeTab, 'push');
            app.DeleteButton_7.Text = 'Delete';
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = ' ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pedestal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end