classdef Routine_Old < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RoutinePanel                   matlab.ui.container.Panel
        RoutineDurationmsSpinnerLabel  matlab.ui.control.Label
        RoutineDurationmsSpinner       matlab.ui.control.Spinner
        ControlPanel                   matlab.ui.container.Panel
        DurationLabel                  matlab.ui.control.Label
        StartSpinnerLabel              matlab.ui.control.Label
        StartSpinner                   matlab.ui.control.Spinner
        UIAxes                         matlab.ui.control.UIAxes
        AddRemove                      matlab.ui.control.Button
        ElementsPanel                  matlab.ui.container.Panel
        NewElementButton               matlab.ui.control.Button
        EditElementButton              matlab.ui.control.Button
        ElementsListBoxLabel           matlab.ui.control.Label
        ElementsListBox                matlab.ui.control.ListBox
        DoneButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        RoutineName                    matlab.ui.control.EditField
        ElementsTree                   matlab.ui.container.Tree
    end

    
    properties (Access = public)
        Parent % Description
        Elements % Array containing currently loaded elements
        Controls % Array with handles to the controls for each element
    end
    
    methods (Access = public)
        
        function refreshaxis(app, varargin)
            
            bottom = app.UIAxes.InnerPosition(2);
            rowheight = app.UIAxes.InnerPosition(4) / app.UIAxes.YLim(2);
            
            for l = 1:app.UIAxes.YLim(2)-1
                try
                    app.Elements.Obj{l} = load(app.Elements.Dir{l}); %re-load element from file
                    app.Elements.Dur{l} = app.Elements.Obj{l}.el.Dur(1); %get new duration
                    app.Elements.Start{l} = app.Controls.Start{l}.Children(1).Value; % get start point
                    app.Elements.Line{l}.XData = [app.Elements.Start{l}, app.Elements.Start{l} + app.Elements.Dur{l}]; %apply new duration
                    app.Elements.Line{l}.YData = [l,l];
                    app.Controls.Start{l}.Children(3).Text = ['Dur: ', num2str(app.Elements.Dur{l}), 'ms'];
                    
                end
                    app.Controls.Start{l}.Position(2) =     bottom + rowheight*l - app.Controls.Start{l}.Position(4)/2; %position start spinner
                    app.Controls.AddRemove{l}.Position(2) = bottom + rowheight*l - app.Controls.AddRemove{l}.Position(4)/2; %position add/remove button
            end
            
            app.Elements.TotalDur = app.RoutineDurationmsSpinner.Value;
            app.UIAxes.XLim = [0, app.Elements.TotalDur];
            hold(app.UIAxes, 'on');
            
            elementList = dir('Elements/*.mat');
            for e = 1:length(elementList)
                uitreenode(par,  ...
                    'Text', elementList(e).name, ...
                    'NodeData', load(['Elements\', elementList(2).name]), ...
                    'Tag', 'E' ...
                    );
            end
        end
        
        function addemt(app, varargin)
            layer = app.UIAxes.YLim(2)-1;
            
            % Get current element
            if ischar(varargin{1})
                app.Elements.Dir{layer} = varargin{1};
            else
                app.Elements.Dir{layer} = ['Elements/', app.ElementsListBox.Value]; %update element file location
            end
            app.Elements.Obj{layer} = load(app.Elements.Dir{layer}); %get element from file
            app.Elements.Dur{layer} = app.Elements.Obj{layer}.el.Dur(1); %get duration from loaded element
            app.Elements.Line{layer} = plot(app.UIAxes, ...
                [0,app.Elements.Dur{layer}(1)], [layer, layer], ...
                '-d', 'Color', [0,33/255,71/255], ...
                'LineWidth', 10); %draw element
            app.UIAxes.YTickLabel{layer} = [app.ElementsListBox.Value(1:6) '...'];
            
            % Auto-adjust duration
            app.RoutineDurationmsSpinner.Value = max(app.Elements.Dur{layer}, app.RoutineDurationmsSpinner.Value);
            
            % Add space for next element
            app.UIAxes.YTick = [app.UIAxes.YTick, app.UIAxes.YLim(2)];
            app.UIAxes.YLim(2) = app.UIAxes.YLim(2) + 1;
            app.UIAxes.YTickLabel = [app.UIAxes.YTickLabel; {'Vacant'}];
            
            % Add controls for the next element
            app.Controls.Start{layer+1} = copyobj(app.Controls.Start{layer}, app.RoutinePanel);
            app.Controls.Start{layer+1}.Children(1).ValueChangedFcn = @app.refreshaxis;
            app.Controls.Start{layer+1}.Children(1).ValueChangingFcn = @app.refreshaxis;
            
            app.Controls.AddRemove{layer+1} = copyobj(app.Controls.AddRemove{layer}, app.RoutinePanel);
            app.Controls.AddRemove{layer+1}.ButtonPushedFcn = @app.addemt;
            
            % Update controls for current element
            app.Controls.Start{layer}.Children(1).Enable = 'on'; %spinner
            app.Controls.Start{layer}.Children(2).Enable = 'on'; %spinner label
            app.Controls.AddRemove{layer}.Text = 'Remove'; %button
            app.Controls.AddRemove{layer}.ButtonPushedFcn = @app.subemt;
            
            % Re-calculate parameters
            refreshaxis(app);
        end
    
        function subemt(app, event, varargin)
            for i = 1:length(app.Controls.AddRemove)
                if app.Controls.AddRemove{i} == event
                    layer = i;
                end
            end
            
            delete(app.Controls.AddRemove{layer})
            delete(app.Controls.Start{layer})
            delete(app.Elements.Line{layer})
            
            app.Elements.Dir(layer)  = [];
            app.Elements.Obj(layer)  = [];
            app.Elements.Dur(layer)  = [];
            app.Elements.Start(layer) = [];
            app.Elements.Line(layer) = [];
            
            app.Controls.Start(layer) = [];
            app.Controls.AddRemove(layer) = [];
            
            app.UIAxes.YLim(2) = app.UIAxes.YLim(2) - 1;
            app.UIAxes.YTick = app.UIAxes.YTick(1:end-1);
            app.UIAxes.YTickLabel(layer) = [];
            
            refreshaxis(app);
        end
        
        function rt = saveobj(app)
            rt = app.Elements;
            save(['Routines/' app.RoutineName.Value], 'rt')
        end
    
        function loadobj(app, rt)
            for n = 1:length(rt.Line)
                dir = rt.Dir{n};
                addemt(app, dir);
            end
            app.RoutineDurationmsSpinner.Value = rt.TotalDur;
            app.refreshaxis(app);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
            %initialise Elements container
            app.Elements.Dir  = {};
            app.Elements.Obj  = {};
            app.Elements.Dur  = {0};
            app.Elements.Start = {0};
            app.Elements.Line = {};
            app.Elements.TotalDur = {app.RoutineDurationmsSpinner.Value};
            
            app.Controls.Start{1} = app.ControlPanel;
            app.Controls.AddRemove{1} = app.AddRemove;
            app.Controls.AddRemove{1}.ButtonPushedFcn = @app.addemt;
            app.Controls.Start{1}.Children(1).ValueChangedFcn = @app.refreshaxis;
            app.Controls.Start{1}.Children(1).ValueChangingFcn = @app.refreshaxis;
            
            elementList = dir('Elements/*.mat');
            elementList = [{elementList.name}];
            app.ElementsListBox.Items = elementList;
        end

        % Button pushed function: NewElementButton
        function NewElementButtonPushed(app, event)
            w = Element;
            waitfor(w);
            refreshaxis(app);
        end

        % Button pushed function: EditElementButton
        function EditElementButtonPushed(app, event)
            set(0,'units','pixels')
            disp = get(0,'screensize');
            wDim = disp(3:4);
            w = Element;
            el = load(['Elements/' app.ElementsListBox.Value]);
            el = el.el;
            w.loadobj(el);
            waitfor(w);
            refreshaxis(app);
        end

        % Callback function: RoutineDurationmsSpinner, 
        % RoutineDurationmsSpinner
        function RoutineDurationmsSpinnerValueChanged(app, event)
            app.UIAxes.XLim(2) = event.Value;
            refreshaxis(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            refreshaxis(app);
            saveobj(app);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RoutinePanel = uipanel(app.UIFigure);
            app.RoutineDurationmsSpinnerLabel = uilabel(app.RoutinePanel);
            app.RoutineDurationmsSpinnerLabel.Text = 'Routine Duration (ms)';
            app.RoutineDurationmsSpinner = uispinner(app.RoutinePanel);
            app.RoutineDurationmsSpinner.Step = 100;
            app.RoutineDurationmsSpinner.ValueChangingFcn = createCallbackFcn(app, @RoutineDurationmsSpinnerValueChanged, true);
            app.RoutineDurationmsSpinner.Limits = [100 Inf];
            app.RoutineDurationmsSpinner.ValueChangedFcn = createCallbackFcn(app, @RoutineDurationmsSpinnerValueChanged, true);
            app.RoutineDurationmsSpinner.Value = 100;
            app.ControlPanel = uipanel(app.RoutinePanel);
            app.DurationLabel = uilabel(app.ControlPanel);
            app.DurationLabel.Enable = 'off';
            app.DurationLabel.Text = 'Dur: 0ms';
            app.StartSpinnerLabel = uilabel(app.ControlPanel);
            app.StartSpinnerLabel.Enable = 'off';
            app.StartSpinnerLabel.Text = 'Start:';
            app.StartSpinner = uispinner(app.ControlPanel);
            app.StartSpinner.Step = 100;
            app.StartSpinner.Enable = 'off';
            app.UIAxes = uiaxes(app.RoutinePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, {'Time (ms)'; ''})
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 2];
            app.UIAxes.YTick = 1;
            app.UIAxes.YTickLabel = {'Vacant'};
            app.AddRemove = uibutton(app.RoutinePanel, 'push');
            app.AddRemove.Text = 'Add >>';
            app.ElementsPanel = uipanel(app.UIFigure);
            app.NewElementButton = uibutton(app.ElementsPanel, 'push');
            app.NewElementButton.ButtonPushedFcn = createCallbackFcn(app, @NewElementButtonPushed, true);
            app.NewElementButton.Text = {'New '; 'Element'};
            app.EditElementButton = uibutton(app.ElementsPanel, 'push');
            app.EditElementButton.ButtonPushedFcn = createCallbackFcn(app, @EditElementButtonPushed, true);
            app.EditElementButton.Text = {'Edit '; 'Element'};
            app.ElementsListBoxLabel = uilabel(app.ElementsPanel);
            app.ElementsListBoxLabel.Text = 'Elements';
            app.ElementsListBox = uilistbox(app.ElementsPanel);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.RoutineName = uieditfield(app.UIFigure, 'text');
            app.RoutineName.Value = 'Routine Name';
            app.ElementsTree = uitree(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Routine_Old

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end