classdef Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DesignTree                   matlab.ui.container.Tree
        WithinSubjectsFactorsNode    matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode   matlab.ui.container.TreeNode
        MeasuresNode                 matlab.ui.container.TreeNode
        NewNodePanel                 matlab.ui.container.Panel
        WithinSubjectsFactorButton   matlab.ui.control.Button
        BetweenSubjectsFactorButton  matlab.ui.control.Button
        MeasureButton                matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        DesignLabel                  matlab.ui.control.Label
        ToggleButton                 matlab.ui.control.StateButton
        ComponentPanel               matlab.ui.container.Panel
        FacMeasTabs                  matlab.ui.container.TabGroup
        FactorTab                    matlab.ui.container.Tab
        Tree2                        matlab.ui.container.Tree
        LevelsLabel                  matlab.ui.control.Label
        DeleteFactorButton           matlab.ui.control.Button
        AddLevelButton               matlab.ui.control.Button
        RemoveLevelButton            matlab.ui.control.Button
        FactorName                   matlab.ui.control.EditField
        MeasureTab                   matlab.ui.container.Tab
        ButtonGroup                  matlab.ui.container.ButtonGroup
        ScaleButton                  matlab.ui.control.ToggleButton
        OrdinalButton                matlab.ui.control.ToggleButton
        NominalButton                matlab.ui.control.ToggleButton
        MeasureTypeLabel             matlab.ui.control.Label
        DeleteMeasureButton          matlab.ui.control.Button
        MeasureName                  matlab.ui.control.EditField
        NoneTab                      matlab.ui.container.Tab
    end

    
    properties (Access = public)
        rootdir % Description
        Parent % Description
    end
    
    methods (Access = public)
        function loadobj(app, des)
            % load BS factors
            for fac = des.Between'
                uitreenode(app.BetweenSubjectsFactorsNode,  ...
                    'Text', fac.Name, ...
                    'NodeData', fac.Levels ...
                    );
            end
            
            % load WS Factors
            for fac = des.Within'
                uitreenode(app.WithinSubjectsFactorsNode,  ...
                    'Text', fac.Name, ...
                    'NodeData', fac.Levels ...
                    );
            end
            
            % load Measures
            for fac = des.Measures'
                uitreenode(app.MeasuresNode,  ...
                    'Text', fac.Name, ...
                    'NodeData', fac.Type ...
                    );
            end
        end
        
        function saveobj(app)
            des = struct(... % Create an output structure with...
                'Between', struct(...
                'Name', {app.BetweenSubjectsFactorsNode.Children.Text}', ... % The name of all BS factors
                'Levels', {app.BetweenSubjectsFactorsNode.Children.NodeData}' ... % The levels of all BS factors
                ), ...
                'Within', struct(...
                'Name', {app.WithinSubjectsFactorsNode.Children.Text}', ... % The name of all WS factors
                'Levels', {app.WithinSubjectsFactorsNode.Children.NodeData}' ... % The levels of all WS factors
                ), ...
                'Measures', struct(...
                'Name', {app.MeasuresNode.Children.Text}', ... % The name of all measures
                'Type', {app.MeasuresNode.Children.NodeData} ... % The type of all measures
                ) ...
                );
            save([app.rootdir, '\Design\Design.mat'], 'des') % Save design spec file
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FacMeasTabs.SelectedTab = app.NoneTab;
            
        end

        % Button pushed function: BetweenSubjectsFactorButton, 
        % MeasureButton, WithinSubjectsFactorButton
        function NewNode(app, event)
            switch event.Source % Check which button was pushed & create the appropriate node
                case app.WithinSubjectsFactorButton
                    node = uitreenode(app.WithinSubjectsFactorsNode, ... % Create blank WS factor node
                        'Text', 'Factor Name...', ...
                        'NodeData', struct('Text', {'Level 1 name...'; 'Level 2 name...'}, 'Tag', {'L'; 'L'}), ...
                        'Tag', 'W' ...
                        );
                case app.BetweenSubjectsFactorsNode
                    node = uitreenode(app.BetweenSubjectsFactorsNode, ... % Create blank BS factor node
                        'Text', 'Factor Name...', ...
                        'NodeData', struct('Text', {'Level 1 name...'; 'Level 2 name...'}, 'Tag', {'L'; 'L'}), ...
                        'Tag', 'B' ...
                        );
                case app.MeasureButton
                    node = uitreenode(app.MeasuresNode,  ... % Create blank Measure node
                        'Text', 'Measure Name...', ...
                        'NodeData', struct('Type', 'Scale'), ...
                        'Tag', 'M' ...
                        );
            end
            expand(node.Parent) % Expand node group
            app.NewNodePanel.Visible = false; % Hide "New..." panel
            app.ToggleButton.Value = false; % Reset "New..." button
        end

        % Value changed function: ToggleButton
        function ToggleNewNodePanel(app, event)
            app.NewNodePanel.Visible = event.Source.Value; % Toggle visibility of "New..." panel depending on button value
            
        end

        % Selection changed function: DesignTree
        function DesignTreeSelectionChanged(app, event)
            if ... % If current node is a parent node or is blank
                    isequal(event.SelectedNodes, app.WithinSubjectsFactorsNode) || ...
                    isequal(event.SelectedNodes, app.BetweenSubjectsFactorsNode) || ....
                    isequal(event.SelectedNodes, app.MeasuresNode) || ...
                    isempty(event.SelectedNodes)
                app.FacMeasTabs.SelectedTab = app.NoneTab; % Switch to blank tab
            else
                switch event.SelectedNodes.Parent
                    case {app.WithinSubjectsFactorsNode, app.BetweenSubjectsFactorsNode} % If selection is a factor node...
                        app.FacMeasTabs.SelectedTab = app.FactorTab; % Switch to factor tab
                        app.FactorName.Value = event.SelectedNodes.Text; % Update factor name field
                        
                        delete(app.Tree2.Children) % Clear previous levels
                        for lv = event.SelectedNodes.NodeData' % For each level
                            uitreenode(app.Tree2,  ... % Create a node in the level tree
                                'Text', lv.Text, ...
                                'Tag', lv.Tag ...
                                );
                        end
                        app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2; % Enable the "remove level" button only if there ar more than 2 levels
                        
                    case app.MeasuresNode % If selection is a measure node...
                        app.FacMeasTabs.SelectedTab = app.MeasureTab; % Switch to measure tab
                        app.MeasureName.Value = event.SelectedNodes.Text; %  Update measure name
                        app.ButtonGroup.SelectedObject = findobj(app.ButtonGroup.Children, 'Text', event.SelectedNodes.NodeData.Type); % Switch to button indicated by stored type

                end
            end


        end

        % Selection change function: FacMeasTabs
        function TabChange(app, event)
            app.FacMeasTabs.SelectedTab = event.OldValue; % Prevent changing tabs manually
            
        end

        % Button pushed function: DeleteFactorButton, 
        % DeleteMeasureButton
        function DeleteNode(app, event)
            delete(app.DesignTree.SelectedNodes)
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            saveobj(app)
            app.Parent.loaddes([app.rootdir, '\Design\Design.mat'])
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
            
        end

        % Callback function: FactorName, FactorName, MeasureName, 
        % MeasureName
        function ChangeNodeName(app, event)
            app.DesignTree.SelectedNodes.Text = event.Value;
            
        end

        % Callback function: AddLevelButton, RemoveLevelButton, Tree2
        function UpdateLevels(app, event)
            switch event.Source
                case app.AddLevelButton
                    uitreenode(app.Tree2,  ... % Add new node to level tree
                        'Text', 'Level Name...' ...
                        );
                case app.RemoveLevelButton
                    delete(app.Tree2.SelectedNodes)
            end
            
            app.DesignTree.SelectedNodes.NodeData = struct(... % Update stored spec for all levels
                'Text', {app.Tree2.Children.Text}' ...
                );
            app.RemoveLevelButton.Enable = length(app.Tree2.Children) > 2; % Enable remove level button if there are more than 2 levels
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            labels = {event.Source.Children.Text};
            app.DesignTree.SelectedNodes.NodeData.Type = labels{event.Source.Children == event.NewValue};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DesignTree = uitree(app.UIFigure);
            app.DesignTree.SelectionChangedFcn = createCallbackFcn(app, @DesignTreeSelectionChanged, true);
            app.WithinSubjectsFactorsNode = uitreenode(app.DesignTree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.DesignTree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.DesignTree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.NewNodePanel = uipanel(app.UIFigure);
            app.NewNodePanel.Visible = 'off';
            app.WithinSubjectsFactorButton = uibutton(app.NewNodePanel, 'push');
            app.WithinSubjectsFactorButton.ButtonPushedFcn = createCallbackFcn(app, @NewNode, true);
            app.WithinSubjectsFactorButton.Text = {'Within-Subjects'; 'Factor'};
            app.BetweenSubjectsFactorButton = uibutton(app.NewNodePanel, 'push');
            app.BetweenSubjectsFactorButton.ButtonPushedFcn = createCallbackFcn(app, @NewNode, true);
            app.BetweenSubjectsFactorButton.Text = {'Between-Subjects'; 'Factor'};
            app.MeasureButton = uibutton(app.NewNodePanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @NewNode, true);
            app.MeasureButton.Text = 'Measure';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DesignLabel = uilabel(app.UIFigure);
            app.DesignLabel.Text = 'Design';
            app.ToggleButton = uibutton(app.UIFigure, 'state');
            app.ToggleButton.ValueChangedFcn = createCallbackFcn(app, @ToggleNewNodePanel, true);
            app.ToggleButton.Text = 'New...';
            app.ComponentPanel = uipanel(app.UIFigure);
            app.FacMeasTabs = uitabgroup(app.ComponentPanel);
            app.FacMeasTabs.SelectionChangedFcn = createCallbackFcn(app, @TabChange, true);
            app.FactorTab = uitab(app.FacMeasTabs);
            app.FactorTab.Title = 'Factor';
            app.Tree2 = uitree(app.FactorTab);
            app.Tree2.NodeTextChangedFcn = createCallbackFcn(app, @UpdateLevels, true);
            app.Tree2.Editable = 'on';
            app.LevelsLabel = uilabel(app.FactorTab);
            app.LevelsLabel.Text = 'Levels';
            app.DeleteFactorButton = uibutton(app.FactorTab, 'push');
            app.DeleteFactorButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteFactorButton.Text = 'Delete Factor';
            app.AddLevelButton = uibutton(app.FactorTab, 'push');
            app.AddLevelButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateLevels, true);
            app.AddLevelButton.Text = 'Add Level';
            app.RemoveLevelButton = uibutton(app.FactorTab, 'push');
            app.RemoveLevelButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateLevels, true);
            app.RemoveLevelButton.Text = 'Remove Level';
            app.FactorName = uieditfield(app.FactorTab, 'text');
            app.FactorName.ValueChangedFcn = createCallbackFcn(app, @ChangeNodeName, true);
            app.FactorName.ValueChangingFcn = createCallbackFcn(app, @ChangeNodeName, true);
            app.FactorName.Value = 'Factor Name';
            app.MeasureTab = uitab(app.FacMeasTabs);
            app.MeasureTab.Title = 'Measure';
            app.ButtonGroup = uibuttongroup(app.MeasureTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ScaleButton = uitogglebutton(app.ButtonGroup);
            app.ScaleButton.Text = 'Scale';
            app.ScaleButton.Value = true;
            app.OrdinalButton = uitogglebutton(app.ButtonGroup);
            app.OrdinalButton.Text = 'Ordinal';
            app.NominalButton = uitogglebutton(app.ButtonGroup);
            app.NominalButton.Text = 'Nominal';
            app.MeasureTypeLabel = uilabel(app.MeasureTab);
            app.MeasureTypeLabel.Text = 'Measure Type';
            app.DeleteMeasureButton = uibutton(app.MeasureTab, 'push');
            app.DeleteMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteMeasureButton.Text = 'Delete Measure';
            app.MeasureName = uieditfield(app.MeasureTab, 'text');
            app.MeasureName.ValueChangedFcn = createCallbackFcn(app, @ChangeNodeName, true);
            app.MeasureName.ValueChangingFcn = createCallbackFcn(app, @ChangeNodeName, true);
            app.MeasureName.Value = 'Measure Name';
            app.NoneTab = uitab(app.FacMeasTabs);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end