classdef Element < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Tree                           matlab.ui.container.Tree
        StimuliNode                    matlab.ui.container.TreeNode
        ResponsesNode                  matlab.ui.container.TreeNode
        CodeNode                       matlab.ui.container.TreeNode
        ElementName                    matlab.ui.control.EditField
        TabGroup                       matlab.ui.container.TabGroup
        ImageTab                       matlab.ui.container.Tab
        PresentationOrderLabel         matlab.ui.control.Label
        StimOrder                      matlab.ui.control.DropDown
        DeleteStimButton               matlab.ui.control.Button
        ImgDimLab                      matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        X_SpinnerLabel                 matlab.ui.control.Label
        X_Spinner                      matlab.ui.control.Spinner
        X_CenterButton                 matlab.ui.control.Button
        Y_SpinnerLabel                 matlab.ui.control.Label
        Y_Spinner                      matlab.ui.control.Spinner
        Y_CenterButton                 matlab.ui.control.Button
        XSpinnerLabel_3                matlab.ui.control.Label
        Scale_Spinner                  matlab.ui.control.Spinner
        StimuliLabel                   matlab.ui.control.Label
        StimuliListBox                 matlab.ui.control.ListBox
        RespTab                        matlab.ui.container.Tab
        ButtonCoding                   matlab.ui.control.Table
        NewButtonMap                   matlab.ui.control.Button
        DeleteMeasure                  matlab.ui.control.Button
        EndroutineonresponseCheckBox   matlab.ui.control.CheckBox
        ResponsesMeasureDropDownLabel  matlab.ui.control.Label
        ResponsesMeasureDropDown       matlab.ui.control.DropDown
        ButtonMappingLabel             matlab.ui.control.Label
        CodeTab                        matlab.ui.container.Tab
        DeleteCode                     matlab.ui.control.Button
        CodeTextAreaLabel              matlab.ui.control.Label
        CodeTextArea                   matlab.ui.control.TextArea
        CodingName                     matlab.ui.control.EditField
        EdulogTab                      matlab.ui.container.Tab
        LoggersButtonGroup             matlab.ui.container.ButtonGroup
        TemperatureButton              matlab.ui.control.RadioButton
        LightButton                    matlab.ui.control.RadioButton
        PulseButton                    matlab.ui.control.RadioButton
        SoundButton                    matlab.ui.control.RadioButton
        HumidityButton                 matlab.ui.control.RadioButton
        MotionButton                   matlab.ui.control.RadioButton
        BloodPressureButton            matlab.ui.control.RadioButton
        HandDynamometerButton          matlab.ui.control.RadioButton
        GSRButton                      matlab.ui.control.RadioButton
        EKGButton                      matlab.ui.control.RadioButton
        PortEditFieldLabel             matlab.ui.control.Label
        PortEditField                  matlab.ui.control.EditField
        SamplesPerSecondSpinnerLabel   matlab.ui.control.Label
        SamplesPerSecondSpinner        matlab.ui.control.Spinner
        CodingName_2                   matlab.ui.control.EditField
        NoneTab                        matlab.ui.container.Tab
        AddNodePanel                   matlab.ui.container.Panel
        StimulusButton                 matlab.ui.control.Button
        CodeButton                     matlab.ui.control.Button
        MeasureButton                  matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        NewButton                      matlab.ui.control.StateButton
        Panel                          matlab.ui.container.Panel
        Window                         matlab.ui.control.UIAxes
        BlueSpinnerLabel               matlab.ui.control.Label
        BlueSpinner                    matlab.ui.control.Spinner
        GreenSpinnerLabel              matlab.ui.control.Label
        GreenSpinner                   matlab.ui.control.Spinner
        RedSpinnerLabel                matlab.ui.control.Label
        RedSpinner                     matlab.ui.control.Spinner
        X_Slider                       matlab.ui.control.Slider
        Y_Slider                       matlab.ui.control.Slider
        DurationmsLabel                matlab.ui.control.Label
        DurationSpinner                matlab.ui.control.Spinner
        NoResponseLimit                matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        rootdir % Description
        Factors % Description
        Parent % Description
    end
    
    methods (Access = public)            
        function ScreenSz(app, w, h, mw, mh)
            scale = min(mw / w, mh / h); % Divide max width and height by screen width and height to determine a scale to resize to
            
            app.Window.Position = [... % For the preview window...
                app.Window.Position(1) + (mw - w*scale)/2, ... x % Center horizontally
                app.Window.Position(2) + (mh - h*scale)/2, ... y % Center vertically
                floor([w*scale, h*scale]) ... w & h % Scale to fit
                ];
            app.Window.XLim(2) = w; % Match axis X limits to pixels on screen
            app.Window.YLim(2) = h; % Match axis Y limits to pixels on screen
            
            app.X_Slider.Position([1,2,3]) = floor(app.Window.Position([1,2,3])); % Position X slider
            app.X_Slider.Limits(2) = w; % Match slider X limits to pixels on screen
            app.X_Spinner.Limits(2) = w; % Match spinner X limits to pixels on screen
            
            app.Y_Slider.Position([1,2,4]) = floor(app.Window.Position([1,2,4])); % Position Y slider
            app.Y_Slider.Limits(2) = h; % Match slider Y limits to pixels on screen
            app.Y_Spinner.Limits(2) = h; % Match spinner Y limits to pixels on screen
           
        end
        
        function StimPos(app, stim, x, y, s)
            [h, w, ~] = size(stim.NodeData.Img.Px); % Get height and width of original stimulus
            
            stim.NodeData.Pos.x = x; % Store x position value
            app.X_Slider.Value = x; % Update x slider
            app.X_Spinner.Value = x; % Update x spinner
            stim.NodeData.Rect.XData = [x - w*s/2, x + w*s/2]; % Move & resize stimulus horizontally
            
            
            stim.NodeData.Pos.y = y; % Store y position value
            app.Y_Slider.Value = y; % Update y slider
            app.Y_Spinner.Value = y; % Update y spinner
            stim.NodeData.Rect.YData = [y - h*s/2, y + h*s/2]; % Move & resize stimulus vertically
            
            stim.NodeData.Pos.s = s; % Store scale
            app.Scale_Spinner.Value = s; % Update scale spinner
            
        end
        
        function el = saveobj(app)    
            
            nodes = {[app.StimuliNode], [app.ResponsesNode], [app.CodeNode]}; % Bind nodes into a single array
            
            empty = [isempty(app.StimuliNode.Children), isempty(app.ResponsesNode.Children), isempty(app.CodeNode.Children)]; % Identify childless nodes
            nodes(empty) = {struct('Children', struct('NodeData', []))}; % Fill empty nodes with placeholder arrays
            
            el = struct(... % Create output structure
                'ElementName', app.ElementName.Value, ...
                'Colour', [app.RedSpinner.Value, app.GreenSpinner.Value, app.BlueSpinner.Value], ...
                'Dur', app.DurationSpinner.Value, ...
                'Stimuli', [nodes{1}.Children.NodeData], ...
                'Measures', [nodes{2}.Children.NodeData], ...
                'Coding', [nodes{3}.Children.NodeData] ...
                );
            
            save([app.rootdir, 'Elements\' app.ElementName.Value '.mat'], 'el') % Save output structure
        end
        
        function loadobj(app, el)
            app.ElementName.Value = el.ElementName; % Load element name
            app.ElementName.Enable = false; % Disable element name changing
            app.DurationSpinner.Value = el.Dur; % Load element duration
            if ~isempty(el.Stimuli)
                for s = el.Stimuli % For each stimulus
                    try
                        cdata = ind2rgb(s.Img.Px, s.Img.Map); % Convert to RGB if possible
                    catch
                        cdata = s.Img.Px; % Leave as is if not
                    end
                    stim = uitreenode(app.StimuliNode, ... % Recreate node
                        'Text', s.Name, ...
                        'NodeData', s, ...
                        'Tag', 'Stim' ...
                        );
                    stim.NodeData.Rect = image(... % Re-draw stimulus
                        'CData', cdata, ...
                        'AlphaData', s.Img.A, ...
                        'Parent', app.Window...
                        );
                    StimPos(app, stim, s.Pos.x, s.Pos.y, s.Pos.s); % Re-position stimulus
                end
            end
            if ~isempty(el.Measures)
                for m = el.Measures % For each measure
                    uitreenode(app.ResponsesNode, ... % Recreate node
                        'Text', m.Measures, ...
                        'NodeData', m, ...
                        'Tag', 'Meas' ...
                        );
                end
            end
            if ~isempty(el.Coding)
                for c = el.Coding % For each measure
                    uitreenode(app.CodeNode, ... % Recreate node
                        'Text', c.Name, ...
                        'NodeData', c, ...
                        'Tag', c.Tag ...
                        );
                end
            end
            
            % Load screen colour
            app.RedSpinner.Value = el.Colour(1);
            app.GreenSpinner.Value = el.Colour(2);
            app.BlueSpinner.Value = el.Colour(3);
            app.Window.BackgroundColor = el.Colour;
            
            expand(app.Tree.Children); % Expand all parent nodes
            app.Tree.SelectedNodes = app.StimuliNode; % Change selection to Stimuli node
            TreeSelectionChanged(app, []) % Change tab accordingly
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off'; % Hide window while it sets up
            
            movegui(app.UIFigure,'center'); % Move to the center
            app.TabGroup.SelectedTab = app.NoneTab; % Switch to blank tab
            load([app.rootdir 'Design\Screen.mat'], 'sDim'); % Attempt to load spec from a file
            ScreenSz(app, sDim(1), sDim(2), app.Window.Position(3), app.Window.Position(4)); % Set screensize from spec file
            disableDefaultInteractivity(app.Window); % Disable axis interactions
            
            app.UIFigure.Visible = 'on'; % Show window once set up
            
        end

        % Button pushed function: StimulusButton
        function NewStimulusNode(app, event)
            stim = uitreenode(app.StimuliNode, ... % Create unassigned stimulus node with the following defaults...
                'Text', '..', ...
                'NodeData', struct( ...
                'Name', '..', ...
                'Order', {'Constant'}, ...
                'Pos', struct('x', [1], 'y', [1], 's', [1]), ...
                'Dir', '..', ...
                'Img', struct('Px', [], 'Map', [], 'A', []), ...
                'Rect', [] ...
                ), ...
                'Tag', 'Stim' ...
                );

            expand(app.StimuliNode) % Expend the parent stimulus node
            app.Tree.SelectedNodes = stim; % Change selection to the new node
            TreeSelectionChanged(app, app.Tree); % Follow up on selection change
            
            event.Source.Parent.Visible = 'off'; % Hide new component panel
            app.NewButton.Value = false; % Reset new component button
        end

        % Callback function: X_Slider, X_Spinner, X_Spinner
        function X_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ... % Reposition & Rescale with new values
                event.Value, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
            
        end

        % Callback function: Y_Slider, Y_Spinner, Y_Spinner
        function Y_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ... % Reposition & Rescale with new values
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                event.Value, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
            
        end

        % Callback function: Scale_Spinner, Scale_Spinner
        function S_Changed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ... % Reposition & Rescale with new values
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                event.Value);
            
        end

        % Button pushed function: X_CenterButton
        function X_CenterButtonPushed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ... % Reposition & Rescale with new values
                mean(app.Window.XLim), ...
                app.Tree.SelectedNodes.NodeData.Pos.y, ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
            
        end

        % Button pushed function: Y_CenterButton
        function Y_CenterButtonPushed(app, event)
            StimPos(app, app.Tree.SelectedNodes, ... % Reposition & Rescale with new values
                app.Tree.SelectedNodes.NodeData.Pos.x, ...
                mean(app.Window.YLim), ...
                app.Tree.SelectedNodes.NodeData.Pos.s);
            
        end

        % Callback function: BlueSpinner, BlueSpinner, GreenSpinner, 
        % GreenSpinner, RedSpinner, RedSpinner
        function Bg_Changed(app, event)
            spinners = [app.RedSpinner, app.GreenSpinner, app.BlueSpinner]; % Bind spinners into one array
            app.Window.BackgroundColor = [spinners.Value]; % Apply their values to the background colour
            
        end

        % Value changed function: NewButton
        function OpenNewInterface(app, event)
            app.AddNodePanel.Visible = event.Value;

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            set([app.X_Slider, app.Y_Slider], 'Visible', app.Tree.SelectedNodes.Parent == app.StimuliNode); % Show/hide sliders as appropriate
            
            switch app.Tree.SelectedNodes.Parent
                case app.StimuliNode % If selected node corresponds to a stimulus...
                    app.TabGroup.SelectedTab = app.ImageTab; % Switch to Image tab
                    
                    stim = dir([app.rootdir, 'Stimuli']); % Get names of stimulus files
                    stim = {stim(~strcmp({stim.name}, '.')).name}; % Remove first file (.)
                    app.StimuliListBox.Items = stim; % Display options in list box
                    
                    app.StimOrder.Value = app.Tree.SelectedNodes.NodeData.Order; % Update order drop-down value
                    StimPos(app, app.Tree.SelectedNodes, ... % Update stim position
                        app.Tree.SelectedNodes.NodeData.Pos.x, ...
                        app.Tree.SelectedNodes.NodeData.Pos.y, ...
                        app.Tree.SelectedNodes.NodeData.Pos.s...
                        );
                    try
                        app.StimuliListBox.Value = app.StimuliListBox.Items(strcmp(app.StimuliListBox.Items, app.Tree.SelectedNodes.NodeData.Name)); % Load selection
                    catch
                        app.StimuliListBox.Value = app.StimuliListBox.Items(1); % Default to first item (..)
                    end
                    
                case app.ResponsesNode % If selected node corresponds to a measure...
                    app.TabGroup.SelectedTab = app.RespTab; % Switch to measures tab
                    load([app.rootdir, 'Design\Design.mat'], 'des') % Load experiment design from file
                    
                    app.ButtonCoding.Data = app.Tree.SelectedNodes.NodeData.Mapping; % Load button mapping data
                    app.ResponsesMeasureDropDown.Items = [{des.Measures.Name}, {'..'}]; % Set measure choices to equal an array of measures from the design
                    app.ResponsesMeasureDropDown.Value = app.Tree.SelectedNodes.NodeData.Measures; % Load measure choice
                    app.EndroutineonresponseCheckBox.Value = app.Tree.SelectedNodes.NodeData.End; % Load box check
                    
                case app.CodeNode
                    app.TabGroup.SelectedTab = app.CodeTab; % Switch to coding tab
                    
                    app.CodingName.Value = app.Tree.SelectedNodes.NodeData.Name; % Load code name
                    app.CodeTextArea.Value = app.Tree.SelectedNodes.NodeData.Code; % Load code
                    
                otherwise
                    app.TabGroup.SelectedTab = app.NoneTab; % Switch to blank tab
                    
            end
        end

        % Value changed function: StimuliListBox
        function StimSelectionChanged(app, event)
            app.Tree.SelectedNodes.Text = event.Value; % Rename stimulus node
            app.Tree.SelectedNodes.NodeData.Name = event.Value; % Store new name
            
            try
                delete(app.Tree.SelectedNodes.NodeData.Rect) % Delete the preview of the previously chosen stimulus
            end
            
            switch event.Value
                case '..' % If blank was selected...
                    delete(app.Tree.SelectedNodes.NodeData.Rect);
                    app.Tree.SelectedNodes.NodeData.Img = struct('Px', [], 'Map', [], 'A', []); % Clear stimulus image data
                    app.Tree.SelectedNodes.NodeData.Dir = event.Value; % Clear stimulus directory data
                    set([app.Y_Slider, app.X_Slider], 'Visible', 'on'); % Hide sliders
                    
                otherwise % If a stimulus was selected...
                    delete(app.Tree.SelectedNodes.NodeData.Rect);
                    [img.Px, img.Map, img.A] = imread([app.rootdir 'Stimuli\' event.Value]); % Load selected image
                    img.Px = flipud(img.Px); img.A = flipud(img.A); % Flip image
                    app.Tree.SelectedNodes.NodeData.Img = img; % Store stimulus image data
                    app.Tree.SelectedNodes.NodeData.Dir = [app.rootdir 'Stimuli\' event.Value]; % Store stimulus directory data
                    try
                        cdata = ind2rgb(img.Px, img.Map); % Convert to RGB if possible
                    catch
                        cdata = img.Px; % Leave as is if not
                    end
                    
                    app.Tree.SelectedNodes.NodeData.Rect = image(... % Display image in preview window
                        'CData', cdata, ...
                        'AlphaData', img.A, ...
                        'Parent', app.Window);
                    StimPos(app, app.Tree.SelectedNodes, ... % Reposition image
                        mean(app.Window.XLim), ...
                        mean(app.Window.YLim), ...
                        1);
                    set([app.Y_Slider, app.X_Slider], 'Visible', 'on'); % Show sliders
            end

            dim = size(app.Tree.SelectedNodes.NodeData.Img.Px); % Get image size
            app.ImgDimLab.Text = [num2str(dim(1)) ' x ' num2str(dim(2))];  % Display image size
            
            
             hold(app.Window, 'on'); % Make sure hold is on
        end

        % Selection change function: TabGroup
        function KeepTab(app, event)
            app.TabGroup.SelectedTab = event.OldValue; %prevent changing tabs manually

        end

        % Value changed function: NoResponseLimit
        function InfiniteDuration(app, event)
            if event.Value == true % If ticked...
                app.DurationSpinner.Enable = false; % Disable duration spinner
                app.DurationSpinner.Value = Inf; % Set its value to infinity
            else
                app.DurationSpinner.Enable = true; % Enable duration spinner
            end
            
        end

        % Button pushed function: SaveButton
        function Save(app, event)
            saveobj(app); % Save
            app.Parent.loadcomp() % Reload component menu in parent
            delete(app);
            
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            delete(app);
            
        end

        % Button pushed function: NewButtonMap
        function NewButtonMapPushed(app, event)
            app.ButtonCoding.Data = [app.ButtonCoding.Data; {'Button'} {'Code'}]; % Append a row to the button mapping table
            app.Tree.SelectedNodes.NodeData.Mapping = app.ButtonCoding.Data; % Update stored mapping data
            
        end

        % Button pushed function: MeasureButton
        function NewMeasureNode(app, event)
            meas = uitreenode(app.ResponsesNode, ... % Create new measure node with the following defaults...
                'Text', '..', ...
                'NodeData', struct(...
                'Mapping', {{'Button', 'Code'}}, ...
                'Measures', {'..'}, ...
                'End', false ...
                ), ...
                'Tag', 'Meas' ...
                );
            app.Tree.SelectedNodes = meas; % Switch to new node
            expand(app.ResponsesNode) % Expand new node
            TreeSelectionChanged(app, app.Tree); % Update accordingly
            
            app.AddNodePanel.Visible = false; % Hide new node panel
            app.NewButton.Value = false; % Reset new button
        end

        % Button pushed function: DeleteCode, DeleteMeasure, 
        % DeleteStimButton
        function DeleteNode(app, event)
            try
                delete(app.Tree.SelectedNodes.NodeData.Rect) % Delete stimulus preview, if possible
            end
            delete(app.Tree.SelectedNodes); % Delete node
            app.TabGroup.SelectedTab = app.NoneTab; % Switch to blank tab
            
        end

        % Callback function: CodingName, CodingName
        function UpdateCodeNode(app, event)
            app.Tree.SelectedNodes.NodeData.Name = app.CodingName.Value; % Update code name
            app.Tree.SelectedNodes.Text = app.CodingName.Value; % Update node label
            app.Tree.SelectedNodes.NodeData.Code = app.CodeTextArea.Value; % Store code

        end

        % Button pushed function: CodeButton
        function NewCodeNode(app, event)
            co = uitreenode(app.CodeNode, ... % Create new node with the following defaults
                'Text', 'Code Component Name', ...
                'NodeData', struct(...
                'Name', 'Code Component Name', ...
                'Code', ''), ...
                'Tag', 'Code' ...
                );
            app.Tree.SelectedNodes = co; % Switch to new node
            TreeSelectionChanged(app, app.Tree); % Update accordingly
            expand(app.CodeNode) % Expand parent node
            
            app.AddNodePanel.Visible = false; % Hide new node panel
            app.NewButton.Value = false; % Reset new button
        end

        % Callback function: ButtonCoding, 
        % EndroutineonresponseCheckBox, ResponsesMeasureDropDown
        function UpdateResponseNode(app, event)
            i = sum(cellfun(@isempty, app.ButtonCoding.Data),2) == 2; % If both values in a row are empty...
            app.ButtonCoding.Data(i,:) = []; % Delete that row
            
            app.Tree.SelectedNodes.NodeData.Measures = app.ResponsesMeasureDropDown.Value; % Store measure
            app.Tree.SelectedNodes.NodeData.Mapping = app.ButtonCoding.Data; % Store button mapping
            app.Tree.SelectedNodes.NodeData.End = app.EndroutineonresponseCheckBox.Value; % Store whether to end routine upon response
            
            app.Tree.SelectedNodes.Text = app.ResponsesMeasureDropDown.Value; % Update node label
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.StimuliNode = uitreenode(app.Tree);
            app.StimuliNode.Text = 'Stimuli';
            app.ResponsesNode = uitreenode(app.Tree);
            app.ResponsesNode.Text = 'Responses';
            app.CodeNode = uitreenode(app.Tree);
            app.CodeNode.Text = 'Code';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.Value = 'Element Name';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @KeepTab, true);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.PresentationOrderLabel = uilabel(app.ImageTab);
            app.PresentationOrderLabel.Text = 'Presentation Order:';
            app.StimOrder = uidropdown(app.ImageTab);
            app.StimOrder.Items = {'Constant'};
            app.StimOrder.Value = 'Constant';
            app.DeleteStimButton = uibutton(app.ImageTab, 'push');
            app.DeleteStimButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteStimButton.Text = 'Delete';
            app.ImgDimLab = uilabel(app.ImageTab);
            app.ImgDimLab.Text = '';
            app.PositionLabel = uilabel(app.ImageTab);
            app.PositionLabel.Text = 'Position';
            app.X_SpinnerLabel = uilabel(app.ImageTab);
            app.X_SpinnerLabel.Text = 'X:';
            app.X_Spinner = uispinner(app.ImageTab);
            app.X_Spinner.ValueChangingFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Spinner.Limits = [1 750];
            app.X_Spinner.ValueChangedFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Spinner.Value = 1;
            app.X_CenterButton = uibutton(app.ImageTab, 'push');
            app.X_CenterButton.ButtonPushedFcn = createCallbackFcn(app, @X_CenterButtonPushed, true);
            app.X_CenterButton.Text = 'Center';
            app.Y_SpinnerLabel = uilabel(app.ImageTab);
            app.Y_SpinnerLabel.Text = 'Y:';
            app.Y_Spinner = uispinner(app.ImageTab);
            app.Y_Spinner.ValueChangingFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Spinner.Limits = [1 500];
            app.Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Spinner.Value = 1;
            app.Y_CenterButton = uibutton(app.ImageTab, 'push');
            app.Y_CenterButton.ButtonPushedFcn = createCallbackFcn(app, @Y_CenterButtonPushed, true);
            app.Y_CenterButton.Text = 'Center';
            app.XSpinnerLabel_3 = uilabel(app.ImageTab);
            app.XSpinnerLabel_3.Text = 'Scale:';
            app.Scale_Spinner = uispinner(app.ImageTab);
            app.Scale_Spinner.Step = 0.25;
            app.Scale_Spinner.ValueChangingFcn = createCallbackFcn(app, @S_Changed, true);
            app.Scale_Spinner.Limits = [0 Inf];
            app.Scale_Spinner.ValueChangedFcn = createCallbackFcn(app, @S_Changed, true);
            app.Scale_Spinner.Value = 1;
            app.StimuliLabel = uilabel(app.ImageTab);
            app.StimuliLabel.Text = 'Stimuli:';
            app.StimuliListBox = uilistbox(app.ImageTab);
            app.StimuliListBox.Items = {};
            app.StimuliListBox.ValueChangedFcn = createCallbackFcn(app, @StimSelectionChanged, true);
            app.StimuliListBox.Value = {};
            app.RespTab = uitab(app.TabGroup);
            app.RespTab.Title = 'Resp';
            app.ButtonCoding = uitable(app.RespTab);
            app.ButtonCoding.ColumnName = {'If they press...'; 'Record it as...'};
            app.ButtonCoding.RowName = {};
            app.ButtonCoding.ColumnEditable = true;
            app.ButtonCoding.CellEditCallback = createCallbackFcn(app, @UpdateResponseNode, true);
            app.NewButtonMap = uibutton(app.RespTab, 'push');
            app.NewButtonMap.ButtonPushedFcn = createCallbackFcn(app, @NewButtonMapPushed, true);
            app.NewButtonMap.Text = 'Add Map';
            app.DeleteMeasure = uibutton(app.RespTab, 'push');
            app.DeleteMeasure.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteMeasure.Text = 'Delete';
            app.EndroutineonresponseCheckBox = uicheckbox(app.RespTab);
            app.EndroutineonresponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateResponseNode, true);
            app.EndroutineonresponseCheckBox.Text = 'End routine on response';
            app.ResponsesMeasureDropDownLabel = uilabel(app.RespTab);
            app.ResponsesMeasureDropDownLabel.Text = 'Responses Measure...';
            app.ResponsesMeasureDropDown = uidropdown(app.RespTab);
            app.ResponsesMeasureDropDown.Items = {};
            app.ResponsesMeasureDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateResponseNode, true);
            app.ResponsesMeasureDropDown.Value = {};
            app.ButtonMappingLabel = uilabel(app.RespTab);
            app.ButtonMappingLabel.Text = 'Button Mapping:';
            app.CodeTab = uitab(app.TabGroup);
            app.CodeTab.Title = 'Code';
            app.DeleteCode = uibutton(app.CodeTab, 'push');
            app.DeleteCode.ButtonPushedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteCode.Text = 'Delete';
            app.CodeTextAreaLabel = uilabel(app.CodeTab);
            app.CodeTextAreaLabel.Text = 'Code:';
            app.CodeTextArea = uitextarea(app.CodeTab);
            app.CodingName = uieditfield(app.CodeTab, 'text');
            app.CodingName.ValueChangedFcn = createCallbackFcn(app, @UpdateCodeNode, true);
            app.CodingName.ValueChangingFcn = createCallbackFcn(app, @UpdateCodeNode, true);
            app.CodingName.Value = 'Code Component Name';
            app.EdulogTab = uitab(app.TabGroup);
            app.EdulogTab.Title = 'Edulog';
            app.LoggersButtonGroup = uibuttongroup(app.EdulogTab);
            app.LoggersButtonGroup.Title = 'Loggers';
            app.TemperatureButton = uiradiobutton(app.LoggersButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.TemperatureButton.Value = true;
            app.LightButton = uiradiobutton(app.LoggersButtonGroup);
            app.LightButton.Text = 'Light';
            app.PulseButton = uiradiobutton(app.LoggersButtonGroup);
            app.PulseButton.Text = 'Pulse';
            app.SoundButton = uiradiobutton(app.LoggersButtonGroup);
            app.SoundButton.Text = 'Sound';
            app.HumidityButton = uiradiobutton(app.LoggersButtonGroup);
            app.HumidityButton.Text = 'Humidity';
            app.MotionButton = uiradiobutton(app.LoggersButtonGroup);
            app.MotionButton.Text = 'Motion';
            app.BloodPressureButton = uiradiobutton(app.LoggersButtonGroup);
            app.BloodPressureButton.Text = 'BloodPressure';
            app.HandDynamometerButton = uiradiobutton(app.LoggersButtonGroup);
            app.HandDynamometerButton.Text = 'HandDynamometer';
            app.GSRButton = uiradiobutton(app.LoggersButtonGroup);
            app.GSRButton.Text = 'GSR';
            app.EKGButton = uiradiobutton(app.LoggersButtonGroup);
            app.EKGButton.Text = 'EKG';
            app.PortEditFieldLabel = uilabel(app.EdulogTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.EdulogTab, 'text');
            app.SamplesPerSecondSpinnerLabel = uilabel(app.EdulogTab);
            app.SamplesPerSecondSpinnerLabel.Text = 'Samples Per Second';
            app.SamplesPerSecondSpinner = uispinner(app.EdulogTab);
            app.SamplesPerSecondSpinner.Step = 0.5;
            app.SamplesPerSecondSpinner.Limits = [0.5 5];
            app.SamplesPerSecondSpinner.Value = 0.5;
            app.CodingName_2 = uieditfield(app.EdulogTab, 'text');
            app.CodingName_2.Value = 'Edulog Measure Name';
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = ' ';
            app.AddNodePanel = uipanel(app.UIFigure);
            app.AddNodePanel.Visible = 'off';
            app.StimulusButton = uibutton(app.AddNodePanel, 'push');
            app.StimulusButton.ButtonPushedFcn = createCallbackFcn(app, @NewStimulusNode, true);
            app.StimulusButton.Text = 'Stimulus';
            app.CodeButton = uibutton(app.AddNodePanel, 'push');
            app.CodeButton.ButtonPushedFcn = createCallbackFcn(app, @NewCodeNode, true);
            app.CodeButton.Text = 'Code';
            app.MeasureButton = uibutton(app.AddNodePanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @NewMeasureNode, true);
            app.MeasureButton.Text = 'Measure';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Save, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.NewButton = uibutton(app.UIFigure, 'state');
            app.NewButton.ValueChangedFcn = createCallbackFcn(app, @OpenNewInterface, true);
            app.NewButton.Text = 'New';
            app.Panel = uipanel(app.UIFigure);
            app.Window = uiaxes(app.Panel);
            app.Window.XLim = [1 750];
            app.Window.YLim = [1 500];
            app.Window.XTick = [];
            app.Window.YTick = [];
            app.Window.Visible = 'off';
            app.BlueSpinnerLabel = uilabel(app.Panel);
            app.BlueSpinnerLabel.Text = 'Blue';
            app.BlueSpinner = uispinner(app.Panel);
            app.BlueSpinner.Step = 0.01;
            app.BlueSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.BlueSpinner.Limits = [0 1];
            app.BlueSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.BlueSpinner.Value = 0.5;
            app.GreenSpinnerLabel = uilabel(app.Panel);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.Panel);
            app.GreenSpinner.Step = 0.01;
            app.GreenSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.GreenSpinner.Limits = [0 1];
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.GreenSpinner.Value = 0.5;
            app.RedSpinnerLabel = uilabel(app.Panel);
            app.RedSpinnerLabel.Text = 'Red';
            app.RedSpinner = uispinner(app.Panel);
            app.RedSpinner.Step = 0.01;
            app.RedSpinner.ValueChangingFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.RedSpinner.Limits = [0 1];
            app.RedSpinner.ValueChangedFcn = createCallbackFcn(app, @Bg_Changed, true);
            app.RedSpinner.Value = 0.5;
            app.X_Slider = uislider(app.Panel);
            app.X_Slider.Limits = [1 750];
            app.X_Slider.MajorTicks = [];
            app.X_Slider.ValueChangedFcn = createCallbackFcn(app, @X_Changed, true);
            app.X_Slider.MinorTicks = [];
            app.X_Slider.Visible = 'off';
            app.X_Slider.Value = 375;
            app.Y_Slider = uislider(app.Panel);
            app.Y_Slider.Limits = [1 500];
            app.Y_Slider.MajorTicks = [];
            app.Y_Slider.ValueChangedFcn = createCallbackFcn(app, @Y_Changed, true);
            app.Y_Slider.MinorTicks = [];
            app.Y_Slider.Visible = 'off';
            app.Y_Slider.Value = 250;
            app.DurationmsLabel = uilabel(app.Panel);
            app.DurationmsLabel.Text = 'Duration (ms):';
            app.DurationSpinner = uispinner(app.Panel);
            app.DurationSpinner.Step = 100;
            app.DurationSpinner.RoundFractionalValues = 'on';
            app.DurationSpinner.Value = 100;
            app.NoResponseLimit = uicheckbox(app.Panel);
            app.NoResponseLimit.ValueChangedFcn = createCallbackFcn(app, @InfiniteDuration, true);
            app.NoResponseLimit.Text = '∞';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Element

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end