classdef BlockDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BlocksPanel              matlab.ui.container.Panel
        BlocksListBox            matlab.ui.control.ListBox
        EditBlockButton          matlab.ui.control.Button
        RandomiseBlocksCheckBox  matlab.ui.control.CheckBox
        DeleteBlockButton        matlab.ui.control.Button
        NewBlockButton           matlab.ui.control.Button
        FactorsPanel             matlab.ui.container.Panel
        Factor1                  matlab.ui.container.Panel
        Factor1EditLevels        matlab.ui.control.Button
        ShowFactor1              matlab.ui.control.Switch
        Factor1Name              matlab.ui.control.EditField
        Factor1Levels            matlab.ui.control.ListBox
        Factor2                  matlab.ui.container.Panel
        Factor2EditLevels        matlab.ui.control.Button
        ShowFactor2              matlab.ui.control.Switch
        Factor2Name              matlab.ui.control.EditField
        Factor2Levels            matlab.ui.control.ListBox
        Factor3                  matlab.ui.container.Panel
        Factor3EditLevels        matlab.ui.control.Button
        ShowFactor3              matlab.ui.control.Switch
        Factor3Name              matlab.ui.control.EditField
        Factor3Levels            matlab.ui.control.ListBox
        Factor4                  matlab.ui.container.Panel
        Factor4EditLevels        matlab.ui.control.Button
        ShowFactor4              matlab.ui.control.Switch
        Factor4Name              matlab.ui.control.EditField
        Factor4Levels            matlab.ui.control.ListBox
        Factor5                  matlab.ui.container.Panel
        Factor5EditLevels        matlab.ui.control.Button
        ShowFactor5              matlab.ui.control.Switch
        Factor5Name              matlab.ui.control.EditField
        Factor5Levels            matlab.ui.control.ListBox
        Factor6                  matlab.ui.container.Panel
        Factor6EditLevels        matlab.ui.control.Button
        ShowFactor6              matlab.ui.control.Switch
        Factor6Name              matlab.ui.control.EditField
        Factor6Levels            matlab.ui.control.ListBox
        Factor7                  matlab.ui.container.Panel
        Factor7EditLevels        matlab.ui.control.Button
        ShowFactor7              matlab.ui.control.Switch
        Factor7Name              matlab.ui.control.EditField
        Factor7Levels            matlab.ui.control.ListBox
        Factor8                  matlab.ui.container.Panel
        Factor8EditLevels        matlab.ui.control.Button
        ShowFactor8              matlab.ui.control.Switch
        Factor8Name              matlab.ui.control.EditField
        Factor8Levels            matlab.ui.control.ListBox
        Factor9                  matlab.ui.container.Panel
        Factor9EditLevels        matlab.ui.control.Button
        ShowFactor9              matlab.ui.control.Switch
        Factor9Name              matlab.ui.control.EditField
        Factor9Levels            matlab.ui.control.ListBox
        SaveButton               matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
    end


    properties (Access = public)
        dlg % Current dialogue open
    end

    methods (Access = public)
    
        function updateDesign(app)
            m = matfile(app.BlocksListBox.Value,'Writable',true);
            m.Properties.Writable = true;
            m = m.app;
            m.Design = app.FactorsPanel;
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            blockNames = dir([pwd, '\Blocks']);
            app.BlocksListBox.Items = {blockNames(3:end).name};
        end

        % Value changed function: BlocksListBox
        function BlocksListBoxValueChanged(app, event)
            try
                Design = app.FactorsPanel.Children;
                
                save([pwd, '\Blocks\', event.PreviousValue], 'Design', '-append')
            catch
            end
            try
                CurrentBlock = load([pwd, '\Blocks\', event.Value], 'Design');
                set(app.FactorsPanel.Children, 'Children', CurrentBlock.Design);
            catch
            end
        end

        % Button pushed function: DeleteBlockButton
        function DeleteBlockButtonPushed(app, event)
            delete([pwd, '\Blocks\', app.BlocksListBox.Value]);
            blockNames = dir([pwd, '\Blocks']);
            app.BlocksListBox.Items = {blockNames(3:end).name};
        end

        % Button pushed function: Factor1EditLevels
        function Factor1EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor1Levels.Items';
            app.dlg.Parent = app.Factor1Levels;
        end

        % Value changed function: Factor1Levels, Factor1Name, 
        % Factor2Levels, Factor2Name, Factor3Levels, Factor3Name, 
        % Factor4Levels, Factor4Name, Factor5Levels, Factor5Name, 
        % Factor6Levels, Factor6Name, Factor7Levels, Factor7Name, 
        % Factor8Levels, Factor8Name, Factor9Levels, Factor9Name
        function Factor1LevelsValueChanged(app, event)
            updateDesign(app)
        end

        % Button pushed function: Factor2EditLevels
        function Factor2EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor2Levels.Items';
            app.dlg.Parent = app.Factor2Levels;
        end

        % Button pushed function: Factor3EditLevels
        function Factor3EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor3Levels.Items';
            app.dlg.Parent = app.Factor3Levels;
        end

        % Button pushed function: Factor4EditLevels
        function Factor4EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor4Levels.Items';
            app.dlg.Parent = app.Factor4Levels;
        end

        % Button pushed function: Factor5EditLevels
        function Factor5EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor5Levels.Items';
            app.dlg.Parent = app.Factor5Levels;
        end

        % Button pushed function: Factor6EditLevels
        function Factor6EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor6Levels.Items';
            app.dlg.Parent = app.Factor6Levels;
        end

        % Button pushed function: Factor7EditLevels
        function Factor7EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor7Levels.Items';
            app.dlg.Parent = app.Factor7Levels;
        end

        % Button pushed function: Factor8EditLevels
        function Factor8EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor8Levels.Items';
            app.dlg.Parent = app.Factor8Levels;
        end

        % Button pushed function: Factor9EditLevels
        function Factor9EditLevelsButtonPushed(app, event)
            app.dlg = EditLevels;
            app.dlg.LevelTable.Data = app.Factor9Levels.Items';
            app.dlg.Parent = app.Factor9Levels;
        end

        % Button pushed function: NewBlockButton
        function NewBlockButtonPushed(app, event)
            app.dlg = AdaptBlock;
            app.dlg.Parent = app;
        end

        % Value changed function: ShowFactor1
        function ShowFactor1ValueChanged(app, event)
            if strcmp(app.ShowFactor1.Value, 'Off')
                app.Factor1Levels.Enable = 'off';
                app.Factor1Name.Enable = 'off';
                app.Factor1EditLevels.Enable = 'off';
            else
                app.Factor1Levels.Enable = 'on';
                app.Factor1Name.Enable = 'on';
                app.Factor1EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor2
        function ShowFactor2ValueChanged(app, event)
            if strcmp(app.ShowFactor2.Value, 'Off')
                app.Factor2Levels.Enable = 'off';
                app.Factor2Name.Enable = 'off';
                app.Factor2EditLevels.Enable = 'off';
            else
                app.Factor2Levels.Enable = 'on';
                app.Factor2Name.Enable = 'on';
                app.Factor2EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor3
        function ShowFactor3ValueChanged(app, event)
            if strcmp(app.ShowFactor3.Value, 'Off')
                app.Factor3Levels.Enable = 'off';
                app.Factor3Name.Enable = 'off';
                app.Factor3EditLevels.Enable = 'off';
            else
                app.Factor3Levels.Enable = 'on';
                app.Factor3Name.Enable = 'on';
                app.Factor3EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor4
        function ShowFactor4ValueChanged(app, event)
            if strcmp(app.ShowFactor4.Value, 'Off')
                app.Factor4Levels.Enable = 'off';
                app.Factor4Name.Enable = 'off';
                app.Factor4EditLevels.Enable = 'off';
            else
                app.Factor4Levels.Enable = 'on';
                app.Factor4Name.Enable = 'on';
                app.Factor4EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor5
        function ShowFactor5ValueChanged(app, event)
            if strcmp(app.ShowFactor5.Value, 'Off')
                app.Factor5Levels.Enable = 'off';
                app.Factor5Name.Enable = 'off';
                app.Factor5EditLevels.Enable = 'off';
            else
                app.Factor5Levels.Enable = 'on';
                app.Factor5Name.Enable = 'on';
                app.Factor5EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor6
        function ShowFactor6ValueChanged(app, event)
            if strcmp(app.ShowFactor6.Value, 'Off')
                app.Factor6Levels.Enable = 'off';
                app.Factor6Name.Enable = 'off';
                app.Factor6EditLevels.Enable = 'off';
            else
                app.Factor6Levels.Enable = 'on';
                app.Factor6Name.Enable = 'on';
                app.Factor6EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor7
        function ShowFactor7ValueChanged(app, event)
            if strcmp(app.ShowFactor7.Value, 'Off')
                app.Factor7Levels.Enable = 'off';
                app.Factor7Name.Enable = 'off';
                app.Factor7EditLevels.Enable = 'off';
            else
                app.Factor7Levels.Enable = 'on';
                app.Factor7Name.Enable = 'on';
                app.Factor7EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor8
        function ShowFactor8ValueChanged(app, event)
            if strcmp(app.ShowFactor8.Value, 'Off')
                app.Factor8Levels.Enable = 'off';
                app.Factor8Name.Enable = 'off';
                app.Factor8EditLevels.Enable = 'off';
            else
                app.Factor8Levels.Enable = 'on';
                app.Factor8Name.Enable = 'on';
                app.Factor8EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Value changed function: ShowFactor9
        function ShowFactor9ValueChanged(app, event)
            if strcmp(app.ShowFactor9.Value, 'Off')
                app.Factor9Levels.Enable = 'off';
                app.Factor9Name.Enable = 'off';
                app.Factor9EditLevels.Enable = 'off';
            else
                app.Factor9Levels.Enable = 'on';
                app.Factor9Name.Enable = 'on';
                app.Factor9EditLevels.Enable = 'on';
            end
            
            updateDesign(app)
        end

        % Button pushed function: EditBlockButton
        function EditBlockButtonPushed(app, event)
            currentBlock = load([pwd,'\Blocks\',app.BlocksListBox.Value]);
            app.dlg = AdaptBlock;
            app.dlg.set(app.dlg,'AdaptBlockSpec',currentBlock)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BlocksPanel = uipanel(app.UIFigure);
            app.BlocksPanel.Title = 'Blocks';
            app.BlocksListBox = uilistbox(app.BlocksPanel);
            app.BlocksListBox.Items = {'Block 1', 'Block 2', 'Block 3', 'Block 4'};
            app.BlocksListBox.ValueChangedFcn = createCallbackFcn(app, @BlocksListBoxValueChanged, true);
            app.BlocksListBox.Value = {};
            app.EditBlockButton = uibutton(app.BlocksPanel, 'push');
            app.EditBlockButton.ButtonPushedFcn = createCallbackFcn(app, @EditBlockButtonPushed, true);
            app.EditBlockButton.Text = 'Edit Block';
            app.RandomiseBlocksCheckBox = uicheckbox(app.BlocksPanel);
            app.RandomiseBlocksCheckBox.Text = 'Randomise Blocks';
            app.DeleteBlockButton = uibutton(app.BlocksPanel, 'push');
            app.DeleteBlockButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBlockButtonPushed, true);
            app.DeleteBlockButton.Text = 'Delete Block';
            app.NewBlockButton = uibutton(app.BlocksPanel, 'push');
            app.NewBlockButton.ButtonPushedFcn = createCallbackFcn(app, @NewBlockButtonPushed, true);
            app.NewBlockButton.Text = 'New Block';
            app.FactorsPanel = uipanel(app.UIFigure);
            app.FactorsPanel.Title = 'Factors';
            app.Factor1 = uipanel(app.FactorsPanel);
            app.Factor1EditLevels = uibutton(app.Factor1, 'push');
            app.Factor1EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor1EditLevelsButtonPushed, true);
            app.Factor1EditLevels.Text = 'Edit Levels';
            app.ShowFactor1 = uiswitch(app.Factor1, 'slider');
            app.ShowFactor1.ValueChangedFcn = createCallbackFcn(app, @ShowFactor1ValueChanged, true);
            app.ShowFactor1.Value = 'On';
            app.Factor1Name = uieditfield(app.Factor1, 'text');
            app.Factor1Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor1Name.Value = 'Factor 1 Name';
            app.Factor1Levels = uilistbox(app.Factor1);
            app.Factor1Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor1Levels.Multiselect = 'on';
            app.Factor1Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor1Levels.Value = {'Not Applicable'};
            app.Factor2 = uipanel(app.FactorsPanel);
            app.Factor2EditLevels = uibutton(app.Factor2, 'push');
            app.Factor2EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor2EditLevelsButtonPushed, true);
            app.Factor2EditLevels.Enable = 'off';
            app.Factor2EditLevels.Text = 'Edit Levels';
            app.ShowFactor2 = uiswitch(app.Factor2, 'slider');
            app.ShowFactor2.ValueChangedFcn = createCallbackFcn(app, @ShowFactor2ValueChanged, true);
            app.Factor2Name = uieditfield(app.Factor2, 'text');
            app.Factor2Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor2Name.Enable = 'off';
            app.Factor2Name.Value = 'Factor 2 Name';
            app.Factor2Levels = uilistbox(app.Factor2);
            app.Factor2Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor2Levels.Multiselect = 'on';
            app.Factor2Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor2Levels.Enable = 'off';
            app.Factor2Levels.Value = {'Not Applicable'};
            app.Factor3 = uipanel(app.FactorsPanel);
            app.Factor3EditLevels = uibutton(app.Factor3, 'push');
            app.Factor3EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor3EditLevelsButtonPushed, true);
            app.Factor3EditLevels.Enable = 'off';
            app.Factor3EditLevels.Text = 'Edit Levels';
            app.ShowFactor3 = uiswitch(app.Factor3, 'slider');
            app.ShowFactor3.ValueChangedFcn = createCallbackFcn(app, @ShowFactor3ValueChanged, true);
            app.Factor3Name = uieditfield(app.Factor3, 'text');
            app.Factor3Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor3Name.Enable = 'off';
            app.Factor3Name.Value = 'Factor 3 Name';
            app.Factor3Levels = uilistbox(app.Factor3);
            app.Factor3Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor3Levels.Multiselect = 'on';
            app.Factor3Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor3Levels.Enable = 'off';
            app.Factor3Levels.Value = {'Not Applicable'};
            app.Factor4 = uipanel(app.FactorsPanel);
            app.Factor4EditLevels = uibutton(app.Factor4, 'push');
            app.Factor4EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor4EditLevelsButtonPushed, true);
            app.Factor4EditLevels.Enable = 'off';
            app.Factor4EditLevels.Text = 'Edit Levels';
            app.ShowFactor4 = uiswitch(app.Factor4, 'slider');
            app.ShowFactor4.ValueChangedFcn = createCallbackFcn(app, @ShowFactor4ValueChanged, true);
            app.Factor4Name = uieditfield(app.Factor4, 'text');
            app.Factor4Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor4Name.Enable = 'off';
            app.Factor4Name.Value = 'Factor 1 Name';
            app.Factor4Levels = uilistbox(app.Factor4);
            app.Factor4Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor4Levels.Multiselect = 'on';
            app.Factor4Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor4Levels.Enable = 'off';
            app.Factor4Levels.Value = {'Not Applicable'};
            app.Factor5 = uipanel(app.FactorsPanel);
            app.Factor5EditLevels = uibutton(app.Factor5, 'push');
            app.Factor5EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor5EditLevelsButtonPushed, true);
            app.Factor5EditLevels.Enable = 'off';
            app.Factor5EditLevels.Text = 'Edit Levels';
            app.ShowFactor5 = uiswitch(app.Factor5, 'slider');
            app.ShowFactor5.ValueChangedFcn = createCallbackFcn(app, @ShowFactor5ValueChanged, true);
            app.Factor5Name = uieditfield(app.Factor5, 'text');
            app.Factor5Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor5Name.Enable = 'off';
            app.Factor5Name.Value = 'Factor 1 Name';
            app.Factor5Levels = uilistbox(app.Factor5);
            app.Factor5Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor5Levels.Multiselect = 'on';
            app.Factor5Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor5Levels.Enable = 'off';
            app.Factor5Levels.Value = {'Not Applicable'};
            app.Factor6 = uipanel(app.FactorsPanel);
            app.Factor6EditLevels = uibutton(app.Factor6, 'push');
            app.Factor6EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor6EditLevelsButtonPushed, true);
            app.Factor6EditLevels.Enable = 'off';
            app.Factor6EditLevels.Text = 'Edit Levels';
            app.ShowFactor6 = uiswitch(app.Factor6, 'slider');
            app.ShowFactor6.ValueChangedFcn = createCallbackFcn(app, @ShowFactor6ValueChanged, true);
            app.Factor6Name = uieditfield(app.Factor6, 'text');
            app.Factor6Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor6Name.Enable = 'off';
            app.Factor6Name.Value = 'Factor 1 Name';
            app.Factor6Levels = uilistbox(app.Factor6);
            app.Factor6Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor6Levels.Multiselect = 'on';
            app.Factor6Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor6Levels.Enable = 'off';
            app.Factor6Levels.Value = {'Not Applicable'};
            app.Factor7 = uipanel(app.FactorsPanel);
            app.Factor7EditLevels = uibutton(app.Factor7, 'push');
            app.Factor7EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor7EditLevelsButtonPushed, true);
            app.Factor7EditLevels.Enable = 'off';
            app.Factor7EditLevels.Text = 'Edit Levels';
            app.ShowFactor7 = uiswitch(app.Factor7, 'slider');
            app.ShowFactor7.ValueChangedFcn = createCallbackFcn(app, @ShowFactor7ValueChanged, true);
            app.Factor7Name = uieditfield(app.Factor7, 'text');
            app.Factor7Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor7Name.Enable = 'off';
            app.Factor7Name.Value = 'Factor 1 Name';
            app.Factor7Levels = uilistbox(app.Factor7);
            app.Factor7Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor7Levels.Multiselect = 'on';
            app.Factor7Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor7Levels.Enable = 'off';
            app.Factor7Levels.Value = {'Not Applicable'};
            app.Factor8 = uipanel(app.FactorsPanel);
            app.Factor8EditLevels = uibutton(app.Factor8, 'push');
            app.Factor8EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor8EditLevelsButtonPushed, true);
            app.Factor8EditLevels.Enable = 'off';
            app.Factor8EditLevels.Text = 'Edit Levels';
            app.ShowFactor8 = uiswitch(app.Factor8, 'slider');
            app.ShowFactor8.ValueChangedFcn = createCallbackFcn(app, @ShowFactor8ValueChanged, true);
            app.Factor8Name = uieditfield(app.Factor8, 'text');
            app.Factor8Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor8Name.Enable = 'off';
            app.Factor8Name.Value = 'Factor 1 Name';
            app.Factor8Levels = uilistbox(app.Factor8);
            app.Factor8Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor8Levels.Multiselect = 'on';
            app.Factor8Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor8Levels.Enable = 'off';
            app.Factor8Levels.Value = {'Not Applicable'};
            app.Factor9 = uipanel(app.FactorsPanel);
            app.Factor9EditLevels = uibutton(app.Factor9, 'push');
            app.Factor9EditLevels.ButtonPushedFcn = createCallbackFcn(app, @Factor9EditLevelsButtonPushed, true);
            app.Factor9EditLevels.Enable = 'off';
            app.Factor9EditLevels.Text = 'Edit Levels';
            app.ShowFactor9 = uiswitch(app.Factor9, 'slider');
            app.ShowFactor9.ValueChangedFcn = createCallbackFcn(app, @ShowFactor9ValueChanged, true);
            app.Factor9Name = uieditfield(app.Factor9, 'text');
            app.Factor9Name.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor9Name.Enable = 'off';
            app.Factor9Name.Value = 'Factor 1 Name';
            app.Factor9Levels = uilistbox(app.Factor9);
            app.Factor9Levels.Items = {'Not Applicable', 'Level 1', 'Level 2'};
            app.Factor9Levels.Multiselect = 'on';
            app.Factor9Levels.ValueChangedFcn = createCallbackFcn(app, @Factor1LevelsValueChanged, true);
            app.Factor9Levels.Enable = 'off';
            app.Factor9Levels.Value = {'Not Applicable'};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BlockDesign

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end