classdef Pedestal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewExperimentMenu           matlab.ui.container.Menu
        OpenExperimentMenu          matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        CloseMenu                   matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        ComponentsMenu              matlab.ui.container.Menu
        StimuliMenu                 matlab.ui.container.Menu
        NewStimulusMenu             matlab.ui.container.Menu
        ElementsMenu                matlab.ui.container.Menu
        NewElementMenu              matlab.ui.container.Menu
        ImportElementMenu           matlab.ui.container.Menu
        RoutinesMenu                matlab.ui.container.Menu
        NewRoutineMenu              matlab.ui.container.Menu
        ImportRoutineMenu           matlab.ui.container.Menu
        EditDesignMenu              matlab.ui.container.Menu
        EditProcedureMenu           matlab.ui.container.Menu
        ExperimentMenu              matlab.ui.container.Menu
        RunMenu                     matlab.ui.container.Menu
        StopMenu                    matlab.ui.container.Menu
        CompileMenu                 matlab.ui.container.Menu
        DisplayMenu                 matlab.ui.container.Menu
        ResolutionMenu              matlab.ui.container.Menu
        DesignTree                  matlab.ui.container.Tree
        WithinSubjectsFactorsNode   matlab.ui.container.TreeNode
        BetweenSubjectsFactorsNode  matlab.ui.container.TreeNode
        MeasuresNode                matlab.ui.container.TreeNode
        Node                        matlab.ui.container.TreeNode
        EditDesignButton            matlab.ui.control.Button
        ElementName                 matlab.ui.control.EditField
        ElementTree_2               matlab.ui.container.Tree
        ManageDataButton            matlab.ui.control.Button
        DesignLabel                 matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        ProcedureTree               matlab.ui.container.Tree
        PTBlank                     matlab.ui.container.TreeNode
        ProcedureLabel              matlab.ui.control.Label
        EditProcedureButton         matlab.ui.control.Button
        ComponentPanel              matlab.ui.container.Panel
        ComponentManagerLabel       matlab.ui.control.Label
        ComponentViewer             matlab.ui.container.TabGroup
        StimulusTab                 matlab.ui.container.Tab
        StimPreview                 matlab.ui.control.UIAxes
        DeleteButton_2              matlab.ui.control.Button
        ElementTab                  matlab.ui.container.Tab
        DeleteButton                matlab.ui.control.Button
        EditButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        BgPreview                   matlab.ui.control.UIAxes
        BackgroundColourLabel       matlab.ui.control.Label
        ContentsLabel               matlab.ui.control.Label
        ElementNameLabel            matlab.ui.control.Label
        DurationLabel               matlab.ui.control.Label
        RGBLabel                    matlab.ui.control.Label
        RoutineTab                  matlab.ui.container.Tab
        RoutineNameLabel            matlab.ui.control.Label
        RoutineDuration             matlab.ui.control.Label
        ElementsLabel               matlab.ui.control.Label
        RoutineElements             matlab.ui.container.Tree
        ElDurLabel                  matlab.ui.control.Label
        FactorsLabel                matlab.ui.control.Label
        RoutineFactors              matlab.ui.control.Table
        DeleteButton_3              matlab.ui.control.Button
        EditButton_2                matlab.ui.control.Button
        NoneTab                     matlab.ui.container.Tab
        ComponentTree               matlab.ui.container.Tree
        StimuliNode                 matlab.ui.container.TreeNode
        ElementsNode                matlab.ui.container.TreeNode
        RoutinesNode                matlab.ui.container.TreeNode
        cmpBlank                    matlab.ui.container.TreeNode
        AddComponentPanel           matlab.ui.container.Panel
        StimulusButton              matlab.ui.control.Button
        ElementButton               matlab.ui.control.Button
        RoutineButton               matlab.ui.control.Button
        NewComponentButton          matlab.ui.control.StateButton
        RefreshButton               matlab.ui.control.Button
        DataLabel                   matlab.ui.control.Label
        ActionDetails               matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        rootdir % Location of root experiment folder
    end
    
    methods (Access = public)        
        function loadproc(app, file)
            % Function to load saved procedure
            % file = location of saved procedure file
            
            load(file, '-mat', 'pr') % Load procedure structure
            delete(app.ProcedureTree.Children(app.ProcedureTree.Children ~= app.PTBlank)); % Delete all nodes apart from the blank placeholder
            for node = pr' % For each node in pr...
                parent = uitreenode(app.ProcedureTree, app.PTBlank, 'before', ... % Recreate it as a new node in the procedure tree
                    'Text', node.Text, ...
                    'Tag', node.Tag, ...
                    'NodeData', node.NodeData ...
                    );
                switch parent.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'} % If parent is a container node..
                        procCycle(app, node.Children, parent); % Run recursive function to generate children
                    case 'R' % If parent is a routine node...
                        for e = 1:length(node.NodeData.Elements) % For each element in the routine...
                            el = node.NodeData.Elements(e); % Extract element
                            uitreenode(parent, ... % Recreate element as a new node in the procedure tree
                                'Text', el.el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        function procCycle(app, children, parent)
            % Recursive function to tunnel into a container node and recreate child nodes within it
            % Children = structure of child nodes from saved experiment
            % Parent = newly generated parent node to recreate child nodes in
            
            for ch = children' % For each child node...
                child = uitreenode(parent, ... % Recreate it as a new node in the procedure tree
                    'Text', ch.Text, ...
                    'Tag', ch.Tag, ...
                    'NodeData', ch.NodeData ...
                    );
                switch child.Tag
                    case {'For', 'If', 'Else', 'While', 'Random'} % If it's a container node...
                        procCycle(app, ch.Children, child); % Recurse
                    case 'R' % If it's a routine node...
                        for el = ch.NodeData.Elements' % For each of its elements...
                            uitreenode(child, ... % Reacreate it as a new node under the routine node
                                'Text', el.el.ElementName, ...
                                'Tag', 'El', ...
                                'NodeData', el ...
                                );
                        end
                end
            end
        end
        
        function loaddes(app, file)
            load(file, '-mat', 'des');
            
            %% load BS factors
            delete(app.BetweenSubjectsFactorsNode.Children) % Delete previous between subjects factor nodes
            for bsf = des.Between' % For each between subjects factor...
                factor = uitreenode(app.BetweenSubjectsFactorsNode,  ... % Replicate it as a new node under the between subjects node
                    'Text', bsf.Name, ...
                    'NodeData', bsf.Levels ...
                    );
                for ch = factor.NodeData' % For each level...
                    uitreenode(factor,  ... % Recreate it as a new node under its factor node
                    'Text', ch.Text ...
                    );
                end
            end
            expand(app.BetweenSubjectsFactorsNode); % Expand the between subjects node
            
            %% load WS factors
            delete(app.WithinSubjectsFactorsNode.Children) % Delete previous within subjects factor nodes
            for qsf = des.Within' % For each within subjects factor...
                factor = uitreenode(app.WithinSubjectsFactorsNode,  ... % Replicate it as a new node under the within subjects node
                    'Text', qsf.Name, ...
                    'NodeData', qsf.Levels ...
                    );
                for ch = factor.NodeData' % For each level...
                    uitreenode(factor,  ... % Recreate it as a new node under its factor node
                    'Text', ch.Text ...
                    );
                end
            end
            expand(app.WithinSubjectsFactorsNode); % Expand the within subjects node
            
            %% load Measures
            delete(app.MeasuresNode.Children) % Delete previous measure nodes
            for ms = des.Measures' % For each measure...
                uitreenode(app.MeasuresNode,  ... % Recreate it as a new node under the measures node
                    'Text', ms.Name, ...
                    'NodeData', ms.Type ...
                    );
            end
            expand(app.MeasuresNode) % Expand the measures node
        end
    
        function loadcomp(app)
            cmpTypes = app.ComponentTree.Children(app.ComponentTree.Children~=app.cmpBlank); % Store container node handles for the different component types
            
            for parent = cmpTypes' % For each component type node...
                delete(parent.Children) % Delete existing component nodes
                
                files = dir([app.rootdir, parent.NodeData]); % Get filenames of all files in stimuli folder
                files = files(~strcmp({files.name}, '..') & ~strcmp({files.name}, '.')); % Remove filenames which are just . or ..
                for cmp = files' % For each file...
                    uitreenode(parent,  ... % Create a node under the parent node
                        'Text', cmp.name, ...
                        'NodeData', [cmp.folder, '\', cmp.name], ...
                        'Tag', parent.Tag ...
                        );
                end
                expand(parent); % Expand parent node
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off'; % Hide window
            addpath(genpath(pwd), genpath('Experiments')); % Add all subfolders to path
            format long % Set format to long to avoid scientific notation
            
            %% Splash
            im = imread('System\Icons\Splash.png'); % Load splash image
            imsize = size(im); % Get original image size
            imsize = imsize*(1000/max(imsize)); % Transform so it fits within a 1000*1000 box
            
            splash = figure(... % Creature figure
                'Name', '', ... 
                'MenuBar', 'none', ... 
                'NumberTitle', 'off', ... 
                'Position', [200,200,imsize(2),imsize(1)] ... 
                );
            movegui(splash,'center'); % Move figure to centre
            splaxis = axes(splash, ... % Create axis within figure
                'Units', 'normalized', ...
                'Position', [0 0 1 1] ...
                );
            image(splaxis, im); % Plot splash image on axis
            splaxis.TickLength = [0 0]; % Remove axis ticks
            %%
            
            pause(2)
            
            delete(splash) % Close splash
            set(0,'units','pixels'); % Set units to pixels
            app.UIFigure.Position = get(groot, 'Screensize') + [99, 99, -200, -200]; % Make window occupy most of the screen
            movegui(app.UIFigure,'center') % Center window
            app.ComponentViewer.SelectedTab = app.NoneTab; % Switch to blank tab in component viewer
            
            app.UIFigure.Visible = 'on'; % Reveal window
            
        end

        % Callback function: EditProcedureButton, EditProcedureMenu
        function EditProcedure(app, event)
            load([app.rootdir, '\Design\Procedure.mat']) % Load procedure file from save
            p = Procedure; % Open Procedure interface
            p.loadobj(pr) % Load procedure to interface
            p.rootdir = app.rootdir; % Pass root directory
            p.Parent = app; % Pass parent handle
            
        end

        % Selection changed function: ProcedureTree
        function ProcedureTreeSelectionChanged(app, event)
            node = event.SelectedNodes;
            
            switch node.Tag
                case {'If', 'Else'} % If selection is an If or Else node
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'If ' node.NodeData.IfDropDown ...
                        ' is ' node.NodeData.IfIsDropDown ...
                        ' ' node.NodeData.IfComparedToField '...'];
                    
                case 'While'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Reapeat until ' node.NodeData.WhileSubject ...
                        ' is ' node.NodeData.WhileIsDropDown ...
                        ' ' node.NodeData.WhileComparedToField ...
                        ', each time adding ' num2str(node.NodeData.WhileIntervalSpinner) ...
                        ' to the variable ' node.NodeData.WhileIteratorField ...
                        ' starting at ' num2str(node.NodeData.WhileStartSpinner)];
                    
                case 'For'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Repeat ' num2str(node.NodeData.ForRepeatsSpinner) ' times,' ...
                        ' each time adding ' num2str(node.NodeData.ForIntervalSpinner) ...
                        ' to the variable "' node.NodeData.ForIteratorField '",' ...
                        ' starting at ' num2str(node.NodeData.ForStartSpinner)...
                        ];
                    
                case 'Random'
                    if node.NodeData.RandFullButton
                        randType = ' fully';
                    elseif node.NodeData.RandEvenButton
                        randType = 'n evenly';
                    end
                    
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Run ' num2str(node.NodeData.RandSpinner) 'of the contained components'...
                        ', in a' randType ' random order' ...
                        ];
                    
                case 'R'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        node.NodeData.Name ': ' ...
                        num2str(node.NodeData.Dur) ' ms, ' ...
                        num2str(length({node.NodeData.Elements})) ' Elements' ...
                        ];
                    
                case 'Code'
                    app.ActionDetails.Value = [ ... % Present following text to action details panel:
                        num2str(length(node.NodeData.Code)), ' lines of code' ...
                        ];
                    
                case 'Variable'
                    app.ActionDetails.Value = [... % Present following text to action details panel:
                        'Set ' node.NodeData.Variable ...
                        ' to equal ' node.NodeData.Value ...
                        ];
                    
                otherwise
                    app.ActionDetails.Value = ''; % Make the action details panel blank
                    
            end
        end

        % Menu selected function: NewExperimentMenu
        function NewExperiment(app, event)
            [file,path] = uiputfile('.mat', 'Choose save location'); % Ask participant where to save experiment
            name = strsplit(file, '.mat'); name = name{1}; % Remove file extension
            app.ElementName.Value = name; % Update experiment name field
            app.rootdir = [path, name, '\']; % Store new root directory
            
            % Create folders to store experiment in
            mkdir(app.rootdir);
            mkdir(app.rootdir, 'Design');
            mkdir(app.rootdir, 'Elements');
            mkdir(app.rootdir, 'Routines');
            mkdir(app.rootdir, 'Stimuli');
            mkdir(app.rootdir, 'Data');
            
            rtdir = app.rootdir;
            save([app.rootdir file], 'rtdir'); % Create file pointing to root directory
            
            de = Design; % Open design interface
            de.rootdir = app.rootdir; % Pass root directory
            de.Parent = app; % Pass parent handle
            
            % Try to load parameters, in case participant has added files manually, otherwise create blank placeholders
            try
                loaddes(app, [app.rootdir, 'Design\Design.mat'])
            catch
                des = struct();
                save([app.rootdir, 'Design\Design.mat'], 'des');
            end
            try
                loadproc(app, [app.rootdir, 'Design\Procedure.mat'])
            catch
                pr = struct();
                save([app.rootdir, 'Design\Procedure.mat'], 'pr');
            end
            try
                loadcomp(app)
            catch
            end
            
            set([ ... % Enable the following objects...
                app.ElementName, ...
                app.RunButton, ...
                app.DesignLabel, ...
                app.ProcedureLabel, ...
                app.DesignTree, ...
                app.ProcedureTree, ...
                app.EditProcedureButton, ...
                app.EditDesignButton, ...
                app.ComponentTree, ...
                app.NewComponentButton, ...
                app.ComponentManagerLabel ...
                ], 'Enable', 'on');
            
        end

        % Menu selected function: OpenExperimentMenu
        function OpenExperiment(app, event)
            [name, app.rootdir] = uigetfile('.mat'); % Get root directory from location of load file

            try
                loaddes(app, [app.rootdir, 'Design\Design.mat'])
            end
            try
                loadproc(app, [app.rootdir, 'Design\Procedure.mat'])
            end
            try
                loadcomp(app)
            end
            set([ ... % Enable the following objects...
                app.ElementName, ...
                app.RunButton, ...
                app.DesignLabel, ...
                app.ProcedureLabel, ...
                app.DesignTree, ...
                app.ProcedureTree, ...
                app.EditProcedureButton, ...
                app.EditDesignButton, ...
                app.ComponentTree, ...
                app.NewComponentButton, ...
                app.ComponentManagerLabel ...
                ], 'Enable', 'on');
            
            app.ElementName.Value = erase(name, '.mat'); % Update experiment name
            app.ComponentViewer.SelectedTab = app.NoneTab; % Switch to none tab
        end

        % Callback function: ElementName, SaveMenu
        function RenameExperiment(app, event)
            spl = strsplit(app.rootdir, '\'); % Split up root directory into folders
            newrt = [strjoin(spl(1:end-2), '\') '\' app.ElementName.Value]; % Recombine with the experiment folder removed
            movefile(app.rootdir, newrt) % Rename experiment folder to ElementName value
            app.rootdir = [newrt, '\']; % Store new root directory
            rtdir = app.rootdir;
            save([app.rootdir app.ElementName.Value '.mat'], 'rtdir') % Save new directory file
            
        end

        % Menu selected function: SaveAsMenu
        function SaveAs(app, event)
            [file,path] = uiputfile('.mat', 'Choose save location'); % Ask participant where to save experiment
            name = strsplit(file, '.mat'); name = name{1}; % Remove file extension
            app.rootdir = [path, name]; % Store new root directory
            
            newdir = [uigetdir(), '\'];
            name = inputdlg('Enter a new name for this copy of your experiment in the box below',"New Experiment Name",1,"NewExperiment");
            copyfile(app.rootdir, [newdir, name{:}])
        end

        % Menu selected function: CloseMenu
        function CloseExperiment(app, event)
            set([ ... % Diable the following objects...
                app.ElementName, ...
                app.RunButton, ...
                app.DesignLabel, ...
                app.ProcedureLabel, ...
                app.DesignTree, ...
                app.ProcedureTree, ...
                app.EditProcedureButton, ...
                app.EditDesignButton, ...
                app.ComponentTree, ...
                app.NewComponentButton, ...
                app.ComponentManagerLabel ...
                ], 'Enable', 'off');

        end

        % Menu selected function: QuitMenu
        function QuitPedestal(app, event)
            delete(app)
        end

        % Callback function: ElementButton, NewElementMenu
        function NewElement(app, event)
            app.NewComponentButton.Value = false; % Reset new component button
            app.AddComponentPanel.Visible = false; % Hide new component panel
            
            if exist([app.rootdir 'Design\Screen.mat'], 'file')
                e = Element; % Open element interface
                e.rootdir = app.rootdir; % Pass root directory
                e.Parent = app; % Pass parent handle
                load([app.rootdir 'Design\Screen.mat'], 'sDim'); % Attempt to load spec from a file
                e.ScreenSz(sDim(1), sDim(2), e.Window.Position(3), e.Window.Position(4)); % Set screensize from spec file
                loadcomp(app) % Reload component browser
            else
                msg = errordlg('Before you create your first element, you need to specify the resolution of the screen you will run your experiment on.');
                waitfor(msg);
                sc = Screen;
                sc.rootdir = app.rootdir;
                sc.parent = app;
            end

        end

        % Callback function: NewRoutineMenu, RoutineButton
        function NewRoutine(app, event)
            app.NewComponentButton.Value = false; % Reset new component button
            app.AddComponentPanel.Visible = false; % Hide new component panel
            r = Routine; % Open routine interface
            r.rootdir = app.rootdir; % Pass root directory
            r.Parent = app; % Pass parent handle
            loadcomp(app) % Reload component browser
            
        end

        % Callback function: EditDesignButton, EditDesignMenu
        function EditDesign(app, event)
            load([app.rootdir, '\Design\Design.mat'], 'des') % Load saved design
            d = Design; % Open design interface
            d.rootdir = app.rootdir; % Pass root directory
            d.Parent = app; % Pass parent handle
            d.loadobj(des) % pass design spec
            loaddes(app, [app.rootdir, '\Design\Design.mat'])
            
        end

        % Menu selected function: ImportElementMenu
        function ImportElement(app, event)
            [srcnm, srcdir] = uigetfile(); % Find element
            copyfile([srcdir, srcnm], [app.rootdir, 'Elements\', srcnm]); % Copy element to experiment folder
            loadcomp(app) % Reload component browser
            
        end

        % Menu selected function: ImportRoutineMenu
        function ImportRoutine(app, event)
            [srcnm, srcdir] = uigetfile(); % Find routine
            copyfile([srcdir, srcnm], [app.rootdir, 'Routines\', srcnm]); % Copy routine to experiment folder
            loadcomp(app) % Reload component browser
            
        end

        % Selection changed function: ComponentTree
        function ComponentTreeSelectionChanged(app, event)
            switch event.SelectedNodes.Parent
                case app.StimuliNode % If selected node corresponds to a stimulus...
                    app.ComponentViewer.SelectedTab = app.StimulusTab; % Switch to stimulus tab
                    pedclear(app.StimPreview) % Clear preview pane
                    
                    % Determine stimulus type
                    imFormats = imformats; % Get list of valid image formats
                    [~ , ~, ext] = fileparts(event.SelectedNodes.NodeData); % Get file extension of stimulus directory
                    switch erase(ext, '.')
                        case '' % If stim is a folder (meaning it is a frame animation)
                            l = text(app.StimPreview, 'String', "Loading..."); % Draw a loading string
                            drawnow
                            stim = pedanimload(app.StimPreview, event.SelectedNodes.NodeData); % Load in animation frames
                            delete(l) % Clear loading
                            stim = pedimageplay(app.StimPreview, stim(1)); % Display first frame of animation
                        case [imFormats.ext] % If stim is an image...
                            stim = pedimageload(app.StimPreview, event.SelectedNodes.NodeData); % Load image
                            stim = pedimageplay(app.StimPreview, stim); % Display image
                        case 'txt' % If stim is text...
                            stim = pedtextload(app.StimPreview, event.SelectedNodes.NodeData); % Read text
                            stim = pedtextplay(app.StimPreview, stim); % Display text
                    end
                    
                    switch stim.Type
                        case 'img' % If stimulus was an image OR frame animation
                            % Resize preview
                            scale = min([diff(app.StimPreview.XLim) / size(stim.Img, 2) - 0.1, diff(app.StimPreview.YLim) / size(stim.Img, 1) - 0.1]); % Calculate scale to make image fit in preview axis
                            set(app.StimPreview, ...
                                'XLim', [-0.5 0.5].*app.StimPreview.Position(3), ... % Set X limits to equal real size of axis (for proportion)
                                'YLim', [-0.5 0.5].*app.StimPreview.Position(4) ... % Set X limits to equal real size of axis (for proportion)
                                );
                            set(stim.Obj, ...
                                'XData', [-0.5 0.5].*size(stim.Img, 2)*scale, ... % Scale image width
                                'YData', [-0.5 0.5].*size(stim.Img, 1)*scale ... % Scale image height
                                );
                            drawnow
                    end
                    
                case app.ElementsNode
                    app.ComponentViewer.SelectedTab = app.ElementTab; % Switch to element tab
                    
                    load(event.SelectedNodes.NodeData, 'el'); % Load element
                    
                    app.ElementNameLabel.Text = el.ElementName; % Load element name
                    app.DurationLabel.Text = [num2str(el.Dur) ' ms']; % Load element duration
                    
                    app.UITable.Data = {}; % Reset table data
                    for s = 1:length(el.Stimuli)
                        app.UITable.Data{s,1} = el.Stimuli(s).Name; % Load stimulus names
                    end
                    for m = 1:length(el.Measures)
                        app.UITable.Data{m,2} = el.Measures(m).Measures; % Load measure names
                    end
                    for c = 1:length(el.Coding)
                        app.UITable.Data{c,3} = el.Coding(c).Name; % Load code names
                    end
                   
                    app.RGBLabel.Text = ['[' num2str(el.Colour(1)) ', ' num2str(el.Colour(2)) ', ' num2str(el.Colour(1)) ']']; % Load RGB values
                    app.BgPreview.BackgroundColor = el.Colour; % Load colour preview
                    
                case app.RoutinesNode
                    app.ComponentViewer.SelectedTab = app.RoutineTab;
                    
                    app.RoutineNameLabel.Text = app.ComponentTree.SelectedNodes.Text;
                    app.RoutineDuration.Text = [num2str(app.ComponentTree.SelectedNodes.NodeData.Dur), 'ms'];
                    for el = app.ComponentTree.SelectedNodes.NodeData.Elements
                        try
                            delete(app.RoutineElements.Children)
                        end
                        if el.el.Dur == inf
                            durtag = [num2str(round(el.Start)), 'ms -> End'];
                        else
                            durtag = [num2str(round(el.Start)), 'ms -> ' num2str(el.Start + el.el.Dur), 'ms'];
                        end
                        uitreenode(app.RoutineElements, ...
                            'Text', el.el.ElementName, ...
                            'Tag', 'E', ...
                            'NodeData',  durtag ...
                            );
                    end
                    app.RoutineFactors.Data = app.ComponentTree.SelectedNodes.NodeData.Factors;
                    app.ElDurLabel.Text = '';
                    
                case app.ComponentTree
                    app.ComponentViewer.SelectedTab = app.NoneTab;
            end
        end

        % Value changed function: NewComponentButton
        function NewComponent(app, event)
            app.AddComponentPanel.Visible = event.Value; % Bring up new component panel
            
        end

        % Button pushed function: StimulusButton
        function NewStimulus(app, event)
            app.AddComponentPanel.Visible = false; % Hide new component panel
            app.NewComponentButton.Value = false; % Reset new component button
            
            [name, path] = uigetfile([app.rootdir, '\*.*']); % Get stimulus file
            try
                copyfile([path, name], [app.rootdir, 'Stimuli\', name]); % Copy stimulus file to experiment folder
            catch
                error('Could not move file'); % Throw up an error if it fails for whatever reason
            end
            
            loadcomp(app) % Reload component browser
            
        end

        % Selection changed function: RoutineElements
        function RoutineElementsSelectionChanged(app, event)
            app.ElDurLabel.Text = app.RoutineElements.SelectedNodes.NodeData; 
            
        end

        % Button pushed function: EditButton, EditButton_2
        function EditComponent(app, event)
            switch app.ComponentTree.SelectedNodes.Parent % Which node branch is the selected node under?
                case app.ElementsNode % If it is an element node...
                    load([app.rootdir, 'Elements\', app.ComponentTree.SelectedNodes.Text], 'el');
                    e = Element; % open element interface
                    e.Parent = app; % Pass parent handle
                    load([app.rootdir 'Design\Screen.mat'], 'sDim'); % Attempt to load spec from a file
                    e.ScreenSz(sDim(1), sDim(2), e.Window.Position(3), e.Window.Position(4)); % Set screensize from spec file
                    e.loadobj(el) % Load from element save file
                    e.rootdir = app.rootdir; % Pass root directory
                    
                case app.RoutinesNode % If it is a routine node...
                    load([app.rootdir, 'Routines\', app.ComponentTree.SelectedNodes.Text], 'rt');
                    r = Routine; % Open routine interface
                    r.Parent = app; % Pass parent handle
                    r.loadobj(rt) % Load from routine save file
                    r.rootdir = app.rootdir; % Pass root directory
                    
            end
            
        end

        % Button pushed function: DeleteButton, DeleteButton_2, 
        % DeleteButton_3
        function DeleteComponent(app, event)
            switch questdlg('Are you sure you wish to delete this component? This cannot be undone.','Warning','Yes','No','No') % Deliver confirmation message
                case 'Yes'
                    switch app.ComponentTree.SelectedNodes.Parent % Which node branch is the selected node under?
                        case app.ElementsNode % If it is an element node...
                            delete([app.rootdir, 'Elements\', app.ComponentTree.SelectedNodes.Text]); % Delete the corresponding file
                        case app.RoutinesNode % If it is a routine node...
                            delete([app.rootdir, 'Routines\', app.ComponentTree.SelectedNodes.Text]); % Delete the corresponding file
                        case app.StimuliNode % If it is a stimulus node...
                            delete([app.rootdir, 'Stimuli\', app.ComponentTree.SelectedNodes.Text]); % Delete the corresponding file
                    end
                    delete(app.ComponentTree.SelectedNodes); % Delete the node
                case 'No'
                    
            end
            
            loadcomp(app) % Reload component browser
            
        end

        % Selection change function: ComponentViewer
        function ComponentViewerSelectionChanged(app, event)
            app.ComponentViewer.SelectedTab = event.OldValue; % Prevent changing tabs manually
            
        end

        % Menu selected function: ResolutionMenu
        function ResolutionMenuSelected(app, event)
            sc = Screen; % Open screen spec interface
            sc.rootdir = app.rootdir; % Pass root directory
            sc.parent = app; % Pass parent handle
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewExperimentMenu = uimenu(app.FileMenu);
            app.NewExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @NewExperiment, true);
            app.NewExperimentMenu.Text = 'New Experiment...';
            app.OpenExperimentMenu = uimenu(app.FileMenu);
            app.OpenExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenExperiment, true);
            app.OpenExperimentMenu.Text = 'Open Experiment...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameExperiment, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAs, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseExperiment, true);
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitPedestal, true);
            app.QuitMenu.Text = 'Quit';
            app.ComponentsMenu = uimenu(app.UIFigure);
            app.ComponentsMenu.Text = 'Components';
            app.StimuliMenu = uimenu(app.ComponentsMenu);
            app.StimuliMenu.Text = 'Stimuli';
            app.NewStimulusMenu = uimenu(app.StimuliMenu);
            app.NewStimulusMenu.Text = 'New Stimulus';
            app.ElementsMenu = uimenu(app.ComponentsMenu);
            app.ElementsMenu.Text = 'Elements';
            app.NewElementMenu = uimenu(app.ElementsMenu);
            app.NewElementMenu.MenuSelectedFcn = createCallbackFcn(app, @NewElement, true);
            app.NewElementMenu.Text = 'New Element';
            app.ImportElementMenu = uimenu(app.ElementsMenu);
            app.ImportElementMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportElement, true);
            app.ImportElementMenu.Text = 'Import Element';
            app.RoutinesMenu = uimenu(app.ComponentsMenu);
            app.RoutinesMenu.Text = 'Routines';
            app.NewRoutineMenu = uimenu(app.RoutinesMenu);
            app.NewRoutineMenu.MenuSelectedFcn = createCallbackFcn(app, @NewRoutine, true);
            app.NewRoutineMenu.Text = 'New Routine';
            app.ImportRoutineMenu = uimenu(app.RoutinesMenu);
            app.ImportRoutineMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportRoutine, true);
            app.ImportRoutineMenu.Text = 'Import Routine';
            app.EditDesignMenu = uimenu(app.ComponentsMenu);
            app.EditDesignMenu.MenuSelectedFcn = createCallbackFcn(app, @EditDesign, true);
            app.EditDesignMenu.Text = 'Edit Design...';
            app.EditProcedureMenu = uimenu(app.ComponentsMenu);
            app.EditProcedureMenu.MenuSelectedFcn = createCallbackFcn(app, @EditProcedure, true);
            app.EditProcedureMenu.Text = 'Edit Procedure...';
            app.ExperimentMenu = uimenu(app.UIFigure);
            app.ExperimentMenu.Text = 'Experiment';
            app.RunMenu = uimenu(app.ExperimentMenu);
            app.RunMenu.Text = 'Run';
            app.StopMenu = uimenu(app.ExperimentMenu);
            app.StopMenu.Text = 'Stop';
            app.CompileMenu = uimenu(app.ExperimentMenu);
            app.CompileMenu.Text = 'Compile...';
            app.DisplayMenu = uimenu(app.UIFigure);
            app.DisplayMenu.Text = 'Display';
            app.ResolutionMenu = uimenu(app.DisplayMenu);
            app.ResolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ResolutionMenuSelected, true);
            app.ResolutionMenu.Text = 'Resolution...';
            app.DesignTree = uitree(app.UIFigure);
            app.DesignTree.Enable = 'off';
            app.WithinSubjectsFactorsNode = uitreenode(app.DesignTree);
            app.WithinSubjectsFactorsNode.NodeData = 'W';
            app.WithinSubjectsFactorsNode.Text = 'Within-Subjects Factors';
            app.BetweenSubjectsFactorsNode = uitreenode(app.DesignTree);
            app.BetweenSubjectsFactorsNode.NodeData = 'B';
            app.BetweenSubjectsFactorsNode.Text = 'Between-Subjects Factors';
            app.MeasuresNode = uitreenode(app.DesignTree);
            app.MeasuresNode.NodeData = 'M';
            app.MeasuresNode.Text = 'Measures';
            app.Node = uitreenode(app.DesignTree);
            app.Node.NodeData = 'N';
            app.Node.Text = '';
            app.EditDesignButton = uibutton(app.UIFigure, 'push');
            app.EditDesignButton.ButtonPushedFcn = createCallbackFcn(app, @EditDesign, true);
            app.EditDesignButton.Enable = 'off';
            app.EditDesignButton.Text = 'Edit Design';
            app.ElementName = uieditfield(app.UIFigure, 'text');
            app.ElementName.ValueChangedFcn = createCallbackFcn(app, @RenameExperiment, true);
            app.ElementName.Enable = 'off';
            app.ElementName.Value = 'Experiment Name';
            app.ElementTree_2 = uitree(app.UIFigure);
            app.ManageDataButton = uibutton(app.UIFigure, 'push');
            app.ManageDataButton.Text = 'Manage Data';
            app.DesignLabel = uilabel(app.UIFigure);
            app.DesignLabel.Enable = 'off';
            app.DesignLabel.Text = 'Design';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ProcedureTree = uitree(app.UIFigure);
            app.ProcedureTree.SelectionChangedFcn = createCallbackFcn(app, @ProcedureTreeSelectionChanged, true);
            app.ProcedureTree.Enable = 'off';
            app.PTBlank = uitreenode(app.ProcedureTree);
            app.PTBlank.NodeData = 'N';
            app.PTBlank.Text = '';
            app.ProcedureLabel = uilabel(app.UIFigure);
            app.ProcedureLabel.Enable = 'off';
            app.ProcedureLabel.Text = 'Procedure';
            app.EditProcedureButton = uibutton(app.UIFigure, 'push');
            app.EditProcedureButton.ButtonPushedFcn = createCallbackFcn(app, @EditProcedure, true);
            app.EditProcedureButton.Enable = 'off';
            app.EditProcedureButton.Text = 'Edit Procedure';
            app.ComponentPanel = uipanel(app.UIFigure);
            app.ComponentManagerLabel = uilabel(app.ComponentPanel);
            app.ComponentManagerLabel.Enable = 'off';
            app.ComponentManagerLabel.Text = 'Component Manager';
            app.ComponentViewer = uitabgroup(app.ComponentPanel);
            app.ComponentViewer.SelectionChangedFcn = createCallbackFcn(app, @ComponentViewerSelectionChanged, true);
            app.StimulusTab = uitab(app.ComponentViewer);
            app.StimulusTab.Title = 'Stimulus';
            app.StimPreview = uiaxes(app.StimulusTab);
            app.StimPreview.XTick = [];
            app.StimPreview.YTick = [];
            app.StimPreview.Visible = 'off';
            app.DeleteButton_2 = uibutton(app.StimulusTab, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton_2.Text = 'Delete';
            app.ElementTab = uitab(app.ComponentViewer);
            app.ElementTab.Title = 'Element';
            app.DeleteButton = uibutton(app.ElementTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton.Text = 'Delete';
            app.EditButton = uibutton(app.ElementTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditComponent, true);
            app.EditButton.Text = 'Edit';
            app.UITable = uitable(app.ElementTab);
            app.UITable.ColumnName = {'Stimuli'; 'Measures'; 'Coding'};
            app.UITable.RowName = {};
            app.BgPreview = uiaxes(app.ElementTab);
            app.BgPreview.XLim = [1 750];
            app.BgPreview.YLim = [1 500];
            app.BgPreview.XTick = [];
            app.BgPreview.YTick = [];
            app.BgPreview.Visible = 'off';
            app.BackgroundColourLabel = uilabel(app.ElementTab);
            app.BackgroundColourLabel.Text = 'Background Colour';
            app.ContentsLabel = uilabel(app.ElementTab);
            app.ContentsLabel.Text = 'Contents';
            app.ElementNameLabel = uilabel(app.ElementTab);
            app.ElementNameLabel.Text = 'Name';
            app.DurationLabel = uilabel(app.ElementTab);
            app.DurationLabel.Text = 'Duration';
            app.RGBLabel = uilabel(app.ElementTab);
            app.RGBLabel.Text = '[R, G, B]';
            app.RoutineTab = uitab(app.ComponentViewer);
            app.RoutineTab.Title = 'Routine';
            app.RoutineNameLabel = uilabel(app.RoutineTab);
            app.RoutineNameLabel.Text = 'Name';
            app.RoutineDuration = uilabel(app.RoutineTab);
            app.RoutineDuration.Text = 'Duration';
            app.ElementsLabel = uilabel(app.RoutineTab);
            app.ElementsLabel.Text = 'Elements';
            app.RoutineElements = uitree(app.RoutineTab);
            app.RoutineElements.SelectionChangedFcn = createCallbackFcn(app, @RoutineElementsSelectionChanged, true);
            app.ElDurLabel = uilabel(app.RoutineTab);
            app.ElDurLabel.Text = '';
            app.FactorsLabel = uilabel(app.RoutineTab);
            app.FactorsLabel.Text = 'Factors';
            app.RoutineFactors = uitable(app.RoutineTab);
            app.RoutineFactors.ColumnName = {'Factor'; 'Level'};
            app.RoutineFactors.RowName = {};
            app.DeleteButton_3 = uibutton(app.RoutineTab, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteComponent, true);
            app.DeleteButton_3.Text = 'Delete';
            app.EditButton_2 = uibutton(app.RoutineTab, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditComponent, true);
            app.EditButton_2.Text = 'Edit';
            app.NoneTab = uitab(app.ComponentViewer);
            app.NoneTab.Title = ' ';
            app.ComponentTree = uitree(app.ComponentPanel);
            app.ComponentTree.SelectionChangedFcn = createCallbackFcn(app, @ComponentTreeSelectionChanged, true);
            app.ComponentTree.Enable = 'off';
            app.StimuliNode = uitreenode(app.ComponentTree);
            app.StimuliNode.NodeData = 'Stimuli';
            app.StimuliNode.Text = 'Stimuli';
            app.ElementsNode = uitreenode(app.ComponentTree);
            app.ElementsNode.NodeData = 'Elements';
            app.ElementsNode.Text = 'Elements';
            app.RoutinesNode = uitreenode(app.ComponentTree);
            app.RoutinesNode.NodeData = 'Routines';
            app.RoutinesNode.Text = 'Routines';
            app.cmpBlank = uitreenode(app.ComponentTree);
            app.cmpBlank.Text = '';
            app.AddComponentPanel = uipanel(app.ComponentPanel);
            app.AddComponentPanel.Visible = 'off';
            app.StimulusButton = uibutton(app.AddComponentPanel, 'push');
            app.StimulusButton.ButtonPushedFcn = createCallbackFcn(app, @NewStimulus, true);
            app.StimulusButton.Text = 'Stimulus';
            app.ElementButton = uibutton(app.AddComponentPanel, 'push');
            app.ElementButton.ButtonPushedFcn = createCallbackFcn(app, @NewElement, true);
            app.ElementButton.Text = 'Element';
            app.RoutineButton = uibutton(app.AddComponentPanel, 'push');
            app.RoutineButton.ButtonPushedFcn = createCallbackFcn(app, @NewRoutine, true);
            app.RoutineButton.Text = 'Routine';
            app.NewComponentButton = uibutton(app.ComponentPanel, 'state');
            app.NewComponentButton.ValueChangedFcn = createCallbackFcn(app, @NewComponent, true);
            app.NewComponentButton.Enable = 'off';
            app.NewComponentButton.Text = 'New...';
            app.RefreshButton = uibutton(app.ComponentPanel, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Enable = 'off';
            app.DataLabel.Text = 'Data';
            app.ActionDetails = uitextarea(app.UIFigure);
            app.ActionDetails.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pedestal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end