classdef Routine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        StartatSlider        matlab.ui.control.Slider
        RoutinePanel         matlab.ui.container.Panel
        RoutineElements      matlab.ui.container.Tree
        ElementsNode         matlab.ui.container.TreeNode
        Node                 matlab.ui.container.TreeNode
        AddButton            matlab.ui.control.Button
        RoutineName          matlab.ui.control.EditField
        CancelButton         matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        ElementPanel         matlab.ui.container.Panel
        ElementListBoxLabel  matlab.ui.control.Label
        ElementListBox       matlab.ui.control.ListBox
        DeleteButton         matlab.ui.control.Button
        StartSpinnerLabel    matlab.ui.control.Label
        StartSpinner         matlab.ui.control.Spinner
        FactorsButton        matlab.ui.control.Button
        DurationmsLabel      matlab.ui.control.Label
        DurationSpinner_2    matlab.ui.control.Spinner
        NoResponseLimit      matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        rootdir % Description
        Factors % Description
    end
    
    methods (Access = public)
        
        function OrderEmts(app)
            app.UIAxes.YLim(2) = length(app.ElementsNode.Children) + 1;
            for n = 1:length(app.ElementsNode.Children)
                app.ElementsNode.Children(n).NodeData.Layer = app.UIAxes.YLim(2) - n;
                app.ElementsNode.Children(n).NodeData.Line.YData = app.UIAxes.YLim(2) - [n, n];
            end
            
            app.RoutineElements.SelectedNodes = app.ElementsNode;
            RoutineElementsSelectionChanged(app, app.RoutineElements);
        end
    
        function saveobj(app)
            addpath('Routines')
            
            rt = struct(...
                'Name', app.RoutineName.Value, ...
                'Dur', app.DurationSpinner.Value, ...
                'Factors', {[app.FactorTable.ColumnName, app.FactorTable.Data']}, ...
                'Elements', [] ...
                );
            for e = 1:length(app.RoutineElements.Children)-1
                current_element = app.RoutineElements.Children(e);
                el{e} = current_element.NodeData;
                el{e}.Start = current_element.NodeData.Line.XData(1);
            end
            if length(app.RoutineElements.Children)-1 > 0
                rt.Elements = el;
            end
            
            save(['Routines\' app.RoutineName.Value], 'rt');
        end
    
        function loadobj(app, rt)
            app.RoutineName.Value = rt.Name;
            app.DurationSpinner.Value = rt.Dur;
            RoutineDurationSpinnerValueChanged(app, struct('Value', rt.Dur));
            if ~isempty([rt.Factors{:}])
                app.FactorTable.Data = rt.Factors(:,2)';
                app.FactorTable.ColumnName = rt.Factors(:,1);
                app.FactorTable.Visible = true;
            end
            
            for e = 1:length(rt.Elements)
                el = rt.Elements{e};
                node = uitreenode(app.RoutineElements,  app.ElementsNode, 'before', ...
                    'Text', el.ElementName, ...
                    'NodeData', el, ...
                    'Tag', 'E' ...
                    );
                if node.NodeData.Dur == Inf
                    dur = 1000000;
                else
                    dur = node.NodeData.Dur;
                end
                layer = app.UIAxes.YLim(2);
                app.UIAxes.YLim(2) = layer + 1;
                node.NodeData.Line = plot(app.UIAxes, ...
                    [el.Start,dur], [layer,layer], ...
                    '-d', 'Color', [0,33/255,71/255], ...
                    'LineWidth', 10); %draw element
                hold(app.UIAxes, 'on');
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center');
            app.UIFigure.Visible = 'on';
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            node = uitreenode(app.ElementsNode, ...
                'Text', '..', ...
                'NodeData', ...
                struct(...
                'ElementName', '..', ...
                'el', struct('Dur', 1), ...
                'Line', struct(...
                'XData', [], ...
                'YData', [], ...
                'Color', [[] [] []] ...
                ), ...
                'Start', 1, ...
                'Layer', app.UIAxes.YLim(2) ...
                ), ...
                'Tag', 'E' ...
                );

            expand(app.ElementsNode);
            OrderEmts(app)
            app.RoutineElements.SelectedNodes = node;
            RoutineElementsSelectionChanged(app, app.RoutineElements);
            
        end

        % Callback function: StartSpinner, StartSpinner, 
        % StartatSlider, StartatSlider
        function StartatSliderValueChanged(app, event)
            node = app.RoutineElements.SelectedNodes;
            node.NodeData.Line.XData = [event.Value,event.Value + node.NodeData.el.Dur];
            
            app.StartSpinner.Value = event.Value;
            app.StartatSlider.Value = event.Value;
            node.NodeData.Start = event.Value;
        end

        % Selection changed function: RoutineElements
        function RoutineElementsSelectionChanged(app, event)
            set(app.ElementPanel.Children, 'Enable', event.SelectedNodes.Parent == app.ElementsNode);
            app.StartatSlider.Visible = event.SelectedNodes.Parent == app.ElementsNode && ~strcmp(event.SelectedNodes.Text, '..');
            
            if event.SelectedNodes.Parent == app.ElementsNode
                app.StartatSlider.Value = event.SelectedNodes.NodeData.Start;
                app.StartSpinner.Value = event.SelectedNodes.NodeData.Start;
                event.SelectedNodes.NodeData.Line.Color = [0.90,0.00,0.49];
                
                el = dir([app.rootdir, 'Elements']);
                el = {el.name};
                el = el(~strcmp(el, '.'));
                app.ElementListBox.Items = el;
                app.ElementListBox.Value = event.SelectedNodes.NodeData.ElementName;
            else
                app.ElementListBox.Items = {};
                app.StartSpinner.Value = 0;
            end
            
            try
                if event.PreviousSelectedNodes.Parent == app.ElementsNode
                    event.PreviousSelectedNodes.NodeData.Line.Color = [0 0.1294 0.2784];
                end
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            try
                delete(app.RoutineElements.SelectedNodes.NodeData.Line)
            end
            delete(app.RoutineElements.SelectedNodes)
            OrderEmts(app)
            
            %             if ~isempty([app.RoutineElements.Children.NodeData])
            %                 OrderEmts(app)
            %             else
            %                 app.StartatSlider.Visible = 'off';
            %             end
        end

        % Callback function: DurationSpinner_2, DurationSpinner_2
        function RoutineDurationSpinnerValueChanged(app, event)
        app.UIAxes.XLim = [0, event.Value];
        app.StartatSlider.Limits = [0, event.Value];
        app.StartSpinner.Limits = [0, event.Value];
        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
    delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
    saveobj(app)
    delete(app)
        end

        % Value changed function: ElementListBox
        function ElementListBoxValueChanged(app, event)
            node = app.RoutineElements.SelectedNodes;
            try
                delete(node.NodeData.Line);
            end
            switch event.Value
                case '..'
                    node.Text = event.Value;
                    node.NodeData = struct(...
                        'ElementName', '..', ...
                        'el', struct('Dur', 1), ...
                        'Line', struct(...
                        'XData', [], ...
                        'YData', [], ...
                        'Color', [[] [] []] ...
                        ), ...
                        'Start', node.NodeData.Start, ...
                        'Layer', node.NodeData.Layer ...
                        );
                    app.StartatSlider.Visible = false;
                otherwise
                    load([app.rootdir, 'Elements\' event.Value]);
                    node.Text = event.Value;
                    node.NodeData.el = el;
                    app.UIAxes.YLim(2) = length(app.ElementsNode.Children) + 1;
                    app.RoutineElements.SelectedNodes.NodeData.Line = plot(app.UIAxes, ...
                        [0,node.NodeData.el.Dur], ...
                        [node.NodeData.Layer,node.NodeData.Layer], ...
                        '-d', 'Color', [0.90,0.00,0.49], ...
                        'LineWidth', 10); %draw element
                    StartatSliderValueChanged(app, struct('Value', node.NodeData.Start));
                    app.StartatSlider.Visible = true;
                    hold(app.UIAxes, 'on');
            end
            
        end

        % Button pushed function: FactorsButton
        function EditFactors(app, event)
            f = Factors;
            f.Parent = app;
            f.init();
            if ~isempty(app.Factors)
                f.loadobj(app.Factors);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, {'Time (ms)'; ''})
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YTick = 1;
            app.UIAxes.YTickLabel = '';
            app.StartatSlider = uislider(app.UIFigure);
            app.StartatSlider.MajorTicks = [];
            app.StartatSlider.ValueChangedFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartatSlider.ValueChangingFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartatSlider.MinorTicks = [];
            app.StartatSlider.Visible = 'off';
            app.RoutinePanel = uipanel(app.UIFigure);
            app.RoutineElements = uitree(app.RoutinePanel);
            app.RoutineElements.SelectionChangedFcn = createCallbackFcn(app, @RoutineElementsSelectionChanged, true);
            app.ElementsNode = uitreenode(app.RoutineElements);
            app.ElementsNode.Text = 'Elements';
            app.Node = uitreenode(app.RoutineElements);
            app.Node.Text = '';
            app.AddButton = uibutton(app.RoutinePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RoutineName = uieditfield(app.RoutinePanel, 'text');
            app.RoutineName.Value = 'Routine Name';
            app.CancelButton = uibutton(app.RoutinePanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.RoutinePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ElementPanel = uipanel(app.RoutinePanel);
            app.ElementListBoxLabel = uilabel(app.ElementPanel);
            app.ElementListBoxLabel.Enable = 'off';
            app.ElementListBoxLabel.Text = 'Element:';
            app.ElementListBox = uilistbox(app.ElementPanel);
            app.ElementListBox.Items = {};
            app.ElementListBox.ValueChangedFcn = createCallbackFcn(app, @ElementListBoxValueChanged, true);
            app.ElementListBox.Enable = 'off';
            app.ElementListBox.Value = {};
            app.DeleteButton = uibutton(app.ElementPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.StartSpinnerLabel = uilabel(app.ElementPanel);
            app.StartSpinnerLabel.Enable = 'off';
            app.StartSpinnerLabel.Text = 'Start:  ';
            app.StartSpinner = uispinner(app.ElementPanel);
            app.StartSpinner.Step = 100;
            app.StartSpinner.ValueChangingFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartSpinner.Limits = [0 100];
            app.StartSpinner.RoundFractionalValues = 'on';
            app.StartSpinner.ValueChangedFcn = createCallbackFcn(app, @StartatSliderValueChanged, true);
            app.StartSpinner.Editable = 'off';
            app.StartSpinner.Enable = 'off';
            app.FactorsButton = uibutton(app.RoutinePanel, 'push');
            app.FactorsButton.ButtonPushedFcn = createCallbackFcn(app, @EditFactors, true);
            app.FactorsButton.Text = 'Factors...';
            app.DurationmsLabel = uilabel(app.UIFigure);
            app.DurationmsLabel.Text = 'Duration (ms):';
            app.DurationSpinner_2 = uispinner(app.UIFigure);
            app.DurationSpinner_2.Step = 100;
            app.DurationSpinner_2.ValueChangingFcn = createCallbackFcn(app, @RoutineDurationSpinnerValueChanged, true);
            app.DurationSpinner_2.RoundFractionalValues = 'on';
            app.DurationSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RoutineDurationSpinnerValueChanged, true);
            app.DurationSpinner_2.Value = 100;
            app.NoResponseLimit = uicheckbox(app.UIFigure);
            app.NoResponseLimit.Text = '∞';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Routine

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end