classdef Screen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        WidthSpinnerLabel   matlab.ui.control.Label
        WidthSpinner        matlab.ui.control.Spinner
        HeightSpinner       matlab.ui.control.Spinner
        HeightSpinnerLabel  matlab.ui.control.Label
        UseCurrentScreenResoutionCheckBox  matlab.ui.control.CheckBox
        Instructions        matlab.ui.control.Label
        ConfirmButton       matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        Instructions_2      matlab.ui.control.Label
    end

    
    properties (Access = public)
        rootdir % Root directory
        parent % Parent object
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center'); % Move figure to centre
            try
                load([app.rootdir 'Design\Screen'], 'sDim'); % Attempt to load spec from a file
                sDim = [1 1 sDim]; % Append 1s so sDim looks like a ScreenSize call
            catch % If there is no file...
                sDim = get(groot, 'ScreenSize'); % Get screen dimensions
            end
            app.WidthSpinner.Value = sDim(3); % Set default width value
            app.HeightSpinner.Value = sDim(4); % Set default height value
        end

        % Value changed function: UseCurrentScreenResoutionCheckBox
        function UseCurrentScreenResoution(app, event)
            if event.Value
                sDim = get(groot, 'ScreenSize'); % Get screen dimensions
                app.WidthSpinner.Value = sDim(3); % Set default width value
                app.HeightSpinner.Value = sDim(4); % Set default height value
                set([app.WidthSpinner app.HeightSpinner], 'Enable', 'off'); % Disable spinners
            else
                set([app.WidthSpinner app.HeightSpinner], 'Enable', 'on'); % Enable spinners
            end
        end

        % Button pushed function: ConfirmButton
        function Confirm(app, event)
            sDim = [app.WidthSpinner.Value, app.HeightSpinner.Value]; % Record spec
            save([app.rootdir 'Design\Screen'], 'sDim'); % Save
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.WidthSpinnerLabel = uilabel(app.Panel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.Panel);
            app.WidthSpinner.Limits = [1 Inf];
            app.WidthSpinner.Value = 1;
            app.HeightSpinner = uispinner(app.Panel);
            app.HeightSpinner.Limits = [1 Inf];
            app.HeightSpinner.Value = 1;
            app.HeightSpinnerLabel = uilabel(app.Panel);
            app.HeightSpinnerLabel.Text = 'Height';
            app.UseCurrentScreenResoutionCheckBox = uicheckbox(app.Panel);
            app.UseCurrentScreenResoutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCurrentScreenResoution, true);
            app.UseCurrentScreenResoutionCheckBox.Text = 'Use Current Screen Resoution';
            app.Instructions = uilabel(app.UIFigure);
            app.Instructions.Text = {'What resolution is the screen which you will run this '; 'experiment on?'; ''; 'Your experiment will still run on different monitors'; ' but images will be scaled to fit.'};
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @Confirm, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Instructions_2 = uilabel(app.UIFigure);
            app.Instructions_2.Text = {'Screen resolution can be changed at any time via:'; 'Display -> Resolution...'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Screen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end